classdef ActiveDataset < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout  matlab.ui.container.GridLayout
        addSet          matlab.ui.control.Button
        TypeLabel       matlab.ui.control.Label
        datasetType     matlab.ui.control.DropDown
        buffer10        matlab.ui.control.Button
        buffer9         matlab.ui.control.Button
        buffer8         matlab.ui.control.Button
        buffer7         matlab.ui.control.Button
        buffer6         matlab.ui.control.Button
        buffer5         matlab.ui.control.Button
        buffer4         matlab.ui.control.Button
        buffer3         matlab.ui.control.Button
        buffer2         matlab.ui.control.Button
        buffer1         matlab.ui.control.Button
        dividerPanel    matlab.ui.container.Panel
        sets            matlab.ui.control.DropDown
        setsLabel       matlab.ui.control.Label
    end

    properties (Access = public)
        handles % handles to widgets
    end
    
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.buffer1 = comp.buffer1;
            comp.handles.buffer2 = comp.buffer2;
            comp.handles.buffer3 = comp.buffer3;
            comp.handles.buffer4 = comp.buffer4;
            comp.handles.buffer5 = comp.buffer5;
            comp.handles.buffer6 = comp.buffer6;
            comp.handles.buffer7 = comp.buffer7;
            comp.handles.buffer8 = comp.buffer8;
            comp.handles.buffer9 = comp.buffer9;
            comp.handles.buffer10 = comp.buffer10;
            comp.handles.sets = comp.sets;
            comp.handles.addSet = comp.addSet;
            comp.handles.datasetType = comp.datasetType;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 275 87];
            comp.mainGridLayout = uigridlayout(comp);
            comp.setsLabel = uilabel(comp.mainGridLayout);
            comp.setsLabel.Text = 'Set';
            comp.sets = uidropdown(comp.mainGridLayout);
            comp.sets.Items = {'Set 1'};
            comp.sets.Tag = 'sets';
            comp.sets.Tooltip = {'Select a set with datasets; use RMB for additional options'};
            comp.sets.Value = 'Set 1';
            comp.dividerPanel = uipanel(comp.mainGridLayout);
            comp.buffer1 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer1.Tag = 'buffer1';
            comp.buffer1.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer1.Text = '1';
            comp.buffer2 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer2.Tag = 'buffer2';
            comp.buffer2.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer2.Text = '2';
            comp.buffer3 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer3.Tag = 'buffer3';
            comp.buffer3.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer3.Text = '3';
            comp.buffer4 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer4.Tag = 'buffer4';
            comp.buffer4.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer4.Text = '4';
            comp.buffer5 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer5.Tag = 'buffer5';
            comp.buffer5.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer5.Text = '5';
            comp.buffer6 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer6.Tag = 'buffer6';
            comp.buffer6.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer6.Text = '6';
            comp.buffer7 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer7.Tag = 'buffer7';
            comp.buffer7.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer7.Text = '7';
            comp.buffer8 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer8.Tag = 'buffer8';
            comp.buffer8.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer8.Text = '8';
            comp.buffer9 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer9.Tag = 'buffer9';
            comp.buffer9.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer9.Text = '9';
            comp.buffer10 = uibutton(comp.mainGridLayout, 'push');
            comp.buffer10.Tag = 'buffer10';
            comp.buffer10.Tooltip = {'Dataset buffer; use RMB for a context menu with additional options'};
            comp.buffer10.Text = '10';
            comp.datasetType = uidropdown(comp.mainGridLayout);
            comp.datasetType.Items = {'Standard', 'Virtual', 'BigData'};
            comp.datasetType.Tooltip = {'Dataset type, standard, virtual, or bigdata'};
            comp.datasetType.Value = 'Standard';
            comp.TypeLabel = uilabel(comp.mainGridLayout);
            comp.TypeLabel.Text = 'Type';
            comp.addSet = uibutton(comp.mainGridLayout, 'push');
            comp.addSet.Icon = 'add_16px.png';
            comp.addSet.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end