classdef BigDataOutput < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        componentGridLayout        matlab.ui.container.GridLayout
        BigDataoutputPanel         matlab.ui.container.Panel
        panelGridLayout            matlab.ui.container.GridLayout
        sizeLabel                  matlab.ui.control.Label
        BigData_ResultLabel        matlab.ui.control.Label
        selectOutputBtn            matlab.ui.control.Button
        BigData_OutputPath         matlab.ui.control.EditField
        BigData_Result             matlab.ui.control.DropDown
        OutputEditFieldLabel       matlab.ui.control.Label
        BigData_OpenIn             matlab.ui.control.DropDown
        BigData_OpenInLabel        matlab.ui.control.Label
        BigData_PyramidLevel       matlab.ui.control.DropDown
        BigData_PyramidLevelLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        handles % component handles
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.componentGridLayout = comp.componentGridLayout;
            comp.handles.selectOutputBtn = comp.selectOutputBtn;
            comp.handles.BigData_OutputPath = comp.BigData_OutputPath;
            comp.handles.sizeLabel = comp.sizeLabel;
            comp.handles.BigData_OpenIn = comp.BigData_OpenIn;
            comp.handles.BigData_PyramidLevel = comp.BigData_PyramidLevel;
            comp.handles.BigData_Result = comp.BigData_Result;
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 612 84];
            comp.componentGridLayout = uigridlayout(comp);
            comp.BigDataoutputPanel = uipanel(comp.componentGridLayout);
            comp.BigDataoutputPanel.Title = ' BigData output';
            comp.panelGridLayout = uigridlayout(comp.BigDataoutputPanel);
            comp.BigData_PyramidLevelLabel = uilabel(comp.panelGridLayout);
            comp.BigData_PyramidLevelLabel.Text = 'Level';
            comp.BigData_PyramidLevel = uidropdown(comp.panelGridLayout);
            comp.BigData_PyramidLevel.Items = {'s0', 's1'};
            comp.BigData_PyramidLevel.Value = 's0';
            comp.BigData_OpenInLabel = uilabel(comp.panelGridLayout);
            comp.BigData_OpenInLabel.Text = 'Open in';
            comp.BigData_OpenIn = uidropdown(comp.panelGridLayout);
            comp.OutputEditFieldLabel = uilabel(comp.panelGridLayout);
            comp.OutputEditFieldLabel.Text = 'Output';
            comp.BigData_Result = uidropdown(comp.panelGridLayout);
            comp.BigData_OutputPath = uieditfield(comp.panelGridLayout, 'text');
            comp.selectOutputBtn = uibutton(comp.panelGridLayout, 'push');
            comp.selectOutputBtn.Icon = 'open_16px.png';
            comp.selectOutputBtn.Text = '';
            comp.BigData_ResultLabel = uilabel(comp.panelGridLayout);
            comp.BigData_ResultLabel.Text = 'Result';
            comp.sizeLabel = uilabel(comp.panelGridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end