classdef FijiConnect < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout          matlab.ui.container.GridLayout
        leftGridLayout          matlab.ui.container.GridLayout
        helpButton              matlab.ui.control.Button
        runButton               matlab.ui.control.Button
        selectFileButton        matlab.ui.control.Button
        macroText               matlab.ui.control.EditField
        RunmacroEditFieldLabel  matlab.ui.control.Label
        importButton            matlab.ui.control.Button
        exportButton            matlab.ui.control.Button
        imageType               matlab.ui.control.DropDown
        ImagetypeDropDownLabel  matlab.ui.control.Label
        centralGridLayout       matlab.ui.container.GridLayout
        stopFijiButton          matlab.ui.control.Button
        startFijiButton         matlab.ui.control.Button
        rightGridLayout         matlab.ui.container.GridLayout
        leftDividerPanel        matlab.ui.container.Panel
        rightDividerPanel       matlab.ui.container.Panel
    end

    properties (Access = public)
        handles % handles to the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            
            comp.handles.mainGridLayout = comp.mainGridLayout;
            
            comp.handles.helpButton = comp.helpButton;
            comp.handles.runButton = comp.runButton;
            comp.handles.selectFileButton = comp.selectFileButton;
            comp.handles.macroText = comp.macroText;
            comp.handles.importButton = comp.importButton;
            comp.handles.exportButton = comp.exportButton;
            comp.handles.imageType = comp.imageType;
            comp.handles.stopFijiButton = comp.stopFijiButton;
            comp.handles.startFijiButton = comp.startFijiButton;

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 910 147];
            comp.mainGridLayout = uigridlayout(comp);
            comp.mainGridLayout.Tag = 'mainGridLayout';
            comp.rightDividerPanel = uipanel(comp.mainGridLayout);
            comp.leftDividerPanel = uipanel(comp.mainGridLayout);
            comp.rightGridLayout = uigridlayout(comp.mainGridLayout);
            comp.centralGridLayout = uigridlayout(comp.mainGridLayout);
            comp.startFijiButton = uibutton(comp.centralGridLayout, 'push');
            comp.startFijiButton.Tag = 'startFijiButton';
            comp.startFijiButton.Icon = 'fiji_16px.png';
            comp.startFijiButton.Tooltip = {'Start instance of Fiji, use Preferences to define location of Fiji.app'};
            comp.startFijiButton.Text = 'Start Fiji';
            comp.stopFijiButton = uibutton(comp.centralGridLayout, 'push');
            comp.stopFijiButton.Tag = 'stopFijiButton';
            comp.stopFijiButton.Icon = 'remove_16px.png';
            comp.stopFijiButton.Tooltip = {'Stop Fiji, use this button to properly close it'};
            comp.stopFijiButton.Text = 'Stop Fiji';
            comp.leftGridLayout = uigridlayout(comp.mainGridLayout);
            comp.ImagetypeDropDownLabel = uilabel(comp.leftGridLayout);
            comp.ImagetypeDropDownLabel.Text = 'Image type';
            comp.imageType = uidropdown(comp.leftGridLayout);
            comp.imageType.Items = {'image', 'labels', 'mask', 'selection'};
            comp.imageType.Tag = 'imageType';
            comp.imageType.Tooltip = {'define type of dataset to exchange with Fiji'};
            comp.imageType.Value = 'image';
            comp.exportButton = uibutton(comp.leftGridLayout, 'push');
            comp.exportButton.Tag = 'exportButton';
            comp.exportButton.Icon = 'export_16px.png';
            comp.exportButton.Text = 'Export';
            comp.importButton = uibutton(comp.leftGridLayout, 'push');
            comp.importButton.Tag = 'importButton';
            comp.importButton.Icon = 'import_16px.png';
            comp.importButton.Text = 'Import';
            comp.RunmacroEditFieldLabel = uilabel(comp.leftGridLayout);
            comp.RunmacroEditFieldLabel.Text = 'Run macro';
            comp.macroText = uieditfield(comp.leftGridLayout, 'text');
            comp.macroText.Tag = 'macroText';
            comp.macroText.Tooltip = {'Enter a macro in format, for example: run(''Flip Z'')'};
            comp.macroText.Value = 'run(''Flip Z'')';
            comp.selectFileButton = uibutton(comp.leftGridLayout, 'push');
            comp.selectFileButton.Tag = 'selectFileButton';
            comp.selectFileButton.Icon = 'open_16px.png';
            comp.selectFileButton.Tooltip = {'select a text file with list of macro functions for Fiji'};
            comp.selectFileButton.Text = 'Select file...';
            comp.runButton = uibutton(comp.leftGridLayout, 'push');
            comp.runButton.Tag = 'runButton';
            comp.runButton.Icon = 'play_16px.png';
            comp.runButton.Tooltip = {'run macro on the selected in the Fiji dataset'};
            comp.runButton.Text = 'Run';
            comp.helpButton = uibutton(comp.leftGridLayout, 'push');
            comp.helpButton.Tag = 'helpButton';
            comp.helpButton.Icon = 'help_16px.png';
            comp.helpButton.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end