classdef ImageView < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout         matlab.ui.container.GridLayout
        frameGridLayout        matlab.ui.container.GridLayout
        frameNumber            matlab.ui.control.NumericEditField
        lastFrame              matlab.ui.control.Button
        frameNumberSlider      matlab.ui.control.Slider
        firstFrame             matlab.ui.control.Button
        sliceGridLayout        matlab.ui.container.GridLayout
        sliceSliderGridLayout  matlab.ui.container.GridLayout
        lastSlice              matlab.ui.control.Button
        sliceNumberSlider      matlab.ui.control.Slider
        firstSlice             matlab.ui.control.Button
        sliceNumber            matlab.ui.control.NumericEditField
        imViewAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        handles % handles to the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.imViewAxes.Title.Interpreter = 'none';

             % allocate widget handles to the handles structure
            comp.handles.imViewAxes = comp.imViewAxes;
            comp.handles.sliceNumber = comp.sliceNumber;
            comp.handles.firstSlice = comp.firstSlice;
            comp.handles.sliceNumberSlider = comp.sliceNumberSlider;
            comp.handles.lastSlice = comp.lastSlice;
            comp.handles.firstFrame = comp.firstFrame;
            comp.handles.frameNumberSlider = comp.frameNumberSlider;
            comp.handles.lastFrame = comp.lastFrame;
            comp.handles.frameNumber = comp.frameNumber;


            %% set background for last and first slice buttons of the image view panel
            % bg = comp.lastSlice.BackgroundColor;
            % btn1 = ones([6 5 3]);
            % btn2 = ones([6 5 3]);
            % for color=1:3
            %     btn1(:,:,color) = btn1(:,:,color).*bg(color);
            %     btn2(:,:,color) = btn2(:,:,color).*bg(color);
            % end
            % btn1(6,1,:) = [0 0 0]; btn1(3,1,:) = [0 0 0];
            % btn1(5,2,:) = [0 0 0]; btn1(2,2,:) = [0 0 0];
            % btn1(4,3,:) = [0 0 0]; btn1(1,3,:) = [0 0 0];
            % btn1(5,4,:) = [0 0 0]; btn1(2,4,:) = [0 0 0];
            % btn1(6,5,:) = [0 0 0]; btn1(3,5,:) = [0 0 0];
            % 
            % btn2 = flip(btn1, 1);
            % comp.firstSlice.Icon = btn2;
            % comp.lastSlice.Icon = btn1;
            % 
            % comp.firstFrame.Icon = permute(btn1, [2 1 3]);
            % comp.lastFrame.Icon = permute(btn2, [2 1 3]);

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 937 673];
            comp.mainGridLayout = uigridlayout(comp);
            comp.imViewAxes = uiaxes(comp.mainGridLayout);
            title(comp.imViewAxes, 'Image View            >>>>> loadded_filename.tif')
            comp.imViewAxes.XTick = [];
            comp.imViewAxes.YTick = [];
            comp.imViewAxes.ZTick = [];
            comp.imViewAxes.Tag = 'imViewAxes';
            comp.sliceGridLayout = uigridlayout(comp.mainGridLayout);
            comp.sliceNumber = uieditfield(comp.sliceGridLayout, 'numeric');
            comp.sliceNumber.Limits = [1 Inf];
            comp.sliceNumber.RoundFractionalValues = 'on';
            comp.sliceNumber.Tag = 'sliceNumber';
            comp.sliceNumber.Value = 200;
            comp.sliceSliderGridLayout = uigridlayout(comp.sliceGridLayout);
            comp.firstSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.firstSlice.Tag = 'firstSlice';
            comp.firstSlice.Icon = 'double-arrow-down_16px.png';
            comp.firstSlice.Text = '';
            comp.sliceNumberSlider = uislider(comp.sliceSliderGridLayout);
            comp.sliceNumberSlider.MajorTicks = [];
            comp.sliceNumberSlider.MajorTickLabels = {};
            comp.sliceNumberSlider.MinorTicks = [0 25 50 75 100];
            comp.sliceNumberSlider.Step = 0.1;
            comp.sliceNumberSlider.Tag = 'sliceNumberSlider';
            comp.sliceNumberSlider.Value = 50;
            comp.lastSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.lastSlice.Tag = 'lastSlice';
            comp.lastSlice.Icon = 'double-arrow-up_16px.png';
            comp.lastSlice.Text = '';
            comp.frameGridLayout = uigridlayout(comp.mainGridLayout);
            comp.firstFrame = uibutton(comp.frameGridLayout, 'push');
            comp.firstFrame.Tag = 'firstFrame';
            comp.firstFrame.Icon = 'double-arrow-left_16px.png';
            comp.firstFrame.Text = '';
            comp.frameNumberSlider = uislider(comp.frameGridLayout);
            comp.frameNumberSlider.MajorTicks = [];
            comp.frameNumberSlider.MajorTickLabels = {};
            comp.frameNumberSlider.MinorTicks = [0 25 50 75 100];
            comp.frameNumberSlider.Step = 0.1;
            comp.frameNumberSlider.Tag = 'frameNumberSlider';
            comp.frameNumberSlider.Value = 50;
            comp.lastFrame = uibutton(comp.frameGridLayout, 'push');
            comp.lastFrame.Tag = 'lastFrame';
            comp.lastFrame.Icon = 'double-arrow-right_16px.png';
            comp.lastFrame.Text = '';
            comp.frameNumber = uieditfield(comp.frameGridLayout, 'numeric');
            comp.frameNumber.Limits = [1 Inf];
            comp.frameNumber.RoundFractionalValues = 'on';
            comp.frameNumber.Tag = 'frameNumber';
            comp.frameNumber.Value = 1;
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end