classdef ImageViewDocument < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout         matlab.ui.container.GridLayout
        frameGridLayout        matlab.ui.container.GridLayout
        lastFrame              matlab.ui.control.Button
        nextFrame              matlab.ui.control.Button
        frameNumberSlider      matlab.ui.control.Slider
        prevFrame              matlab.ui.control.Button
        firstFrame             matlab.ui.control.Button
        frameNumber            matlab.ui.control.NumericEditField
        sliceGridLayout        matlab.ui.container.GridLayout
        sliceSliderGridLayout  matlab.ui.container.GridLayout
        lastSlice              matlab.ui.control.Button
        nextSlice              matlab.ui.control.Button
        sliceNumberSlider      matlab.ui.control.Slider
        prevSlice              matlab.ui.control.Button
        firstSlice             matlab.ui.control.Button
        sliceNumber            matlab.ui.control.NumericEditField
        imViewAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        brushCursor % handle to brush cursor
        centralMarker % handle for central marker of axes
        handles % handles to the component widgets
        imViewFigure % handle to the imageView figure window
        imageHandle = matlab.graphics.primitive.Image('CData', []); % handle to the rendered image 
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.imViewAxes.Title.Interpreter = 'none';

            % allocate widget handles to the handles structure
            comp.handles.mainGridLayout = comp.mainGridLayout;
            comp.handles.imViewAxes = comp.imViewAxes;
            comp.handles.sliceNumber = comp.sliceNumber;
            comp.handles.firstSlice = comp.firstSlice;
            comp.handles.prevSlice = comp.prevSlice;
            comp.handles.sliceNumberSlider = comp.sliceNumberSlider;
            comp.handles.nextSlice = comp.nextSlice;
            comp.handles.lastSlice = comp.lastSlice;

            comp.handles.firstFrame = comp.firstFrame;
            comp.handles.prevFrame = comp.prevFrame;
            comp.handles.frameNumberSlider = comp.frameNumberSlider;
            comp.handles.nextFrame = comp.nextFrame;
            comp.handles.lastFrame = comp.lastFrame;
            comp.handles.frameNumber = comp.frameNumber;

            % Disable default interactivity for performance
            disableDefaultInteractivity(comp.imViewAxes);
            comp.imViewAxes.Toolbar = [];

            % get ancestor of the axes to allow showing correct cursor and WindowButtonMotionFcn
            comp.imViewFigure = ancestor(comp.imViewAxes, 'figure');

            %% set background for last and first slice buttons of the image view panel
            % bg = comp.lastSlice.BackgroundColor;
            % btn1 = ones([6 5 3]);
            % btn2 = ones([6 5 3]);
            % for color=1:3
            %     btn1(:,:,color) = btn1(:,:,color).*bg(color);
            %     btn2(:,:,color) = btn2(:,:,color).*bg(color);
            % end
            % btn1(6,1,:) = [0 0 0]; btn1(3,1,:) = [0 0 0];
            % btn1(5,2,:) = [0 0 0]; btn1(2,2,:) = [0 0 0];
            % btn1(4,3,:) = [0 0 0]; btn1(1,3,:) = [0 0 0];
            % btn1(5,4,:) = [0 0 0]; btn1(2,4,:) = [0 0 0];
            % btn1(6,5,:) = [0 0 0]; btn1(3,5,:) = [0 0 0];
            % 
            % btn2 = flip(btn1, 1);
            % comp.firstSlice.Icon = btn2;
            % comp.lastSlice.Icon = btn1;
            % 
            % comp.firstFrame.Icon = permute(btn1, [2 1 3]);
            % comp.lastFrame.Icon = permute(btn2, [2 1 3]);

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 937 673];
            comp.mainGridLayout = uigridlayout(comp);
            comp.imViewAxes = uiaxes(comp.mainGridLayout);
            title(comp.imViewAxes, 'Image View            >>>>> loadded filename.tif')
            comp.imViewAxes.YDir = 'reverse';
            comp.imViewAxes.XTick = [];
            comp.imViewAxes.YTick = [];
            comp.imViewAxes.ZTick = [];
            comp.imViewAxes.Tag = 'imViewAxes';
            comp.sliceGridLayout = uigridlayout(comp.mainGridLayout);
            comp.sliceNumber = uieditfield(comp.sliceGridLayout, 'numeric');
            comp.sliceNumber.Limits = [1 100.001];
            comp.sliceNumber.RoundFractionalValues = 'on';
            comp.sliceNumber.Tag = 'sliceNumber';
            comp.sliceNumber.Tooltip = {'change Z slices'};
            comp.sliceNumber.Value = 1;
            comp.sliceSliderGridLayout = uigridlayout(comp.sliceGridLayout);
            comp.firstSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.firstSlice.Tag = 'firstSlice';
            comp.firstSlice.Icon = 'double_arrow_down_16px.png';
            comp.firstSlice.Tooltip = {'show the first slice'};
            comp.firstSlice.Text = '';
            comp.prevSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.prevSlice.Tag = 'prevSlice';
            comp.prevSlice.Icon = 'prev_slice_16px.png';
            comp.prevSlice.Tooltip = {'show the previous slice'};
            comp.prevSlice.Text = '';
            comp.sliceNumberSlider = uislider(comp.sliceSliderGridLayout);
            comp.sliceNumberSlider.Limits = [1 1.001];
            comp.sliceNumberSlider.MajorTicks = [];
            comp.sliceNumberSlider.MajorTickLabels = {};
            comp.sliceNumberSlider.MinorTicks = [0 25 50 75 100];
            comp.sliceNumberSlider.Step = 1;
            comp.sliceNumberSlider.Tag = 'sliceNumberSlider';
            comp.sliceNumberSlider.Tooltip = {'change Z slices, right click for options'};
            comp.sliceNumberSlider.Value = 1;
            comp.nextSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.nextSlice.Tag = 'nextSlice';
            comp.nextSlice.Icon = 'next_slice_16px.png';
            comp.nextSlice.Tooltip = {'show the next slice'};
            comp.nextSlice.Text = '';
            comp.lastSlice = uibutton(comp.sliceSliderGridLayout, 'push');
            comp.lastSlice.Tag = 'lastSlice';
            comp.lastSlice.Icon = 'double_arrow_up_16px.png';
            comp.lastSlice.Tooltip = {'show the last slice'};
            comp.lastSlice.Text = '';
            comp.frameGridLayout = uigridlayout(comp.mainGridLayout);
            comp.frameNumber = uieditfield(comp.frameGridLayout, 'numeric');
            comp.frameNumber.Limits = [1 100.001];
            comp.frameNumber.RoundFractionalValues = 'on';
            comp.frameNumber.Tag = 'frameNumber';
            comp.frameNumber.Tooltip = {'change time frames'};
            comp.frameNumber.Value = 1;
            comp.firstFrame = uibutton(comp.frameGridLayout, 'push');
            comp.firstFrame.Tag = 'firstFrame';
            comp.firstFrame.Icon = 'double_arrow_left_16px.png';
            comp.firstFrame.Tooltip = {'show the first frame'};
            comp.firstFrame.Text = '';
            comp.prevFrame = uibutton(comp.frameGridLayout, 'push');
            comp.prevFrame.Tag = 'prevFrame';
            comp.prevFrame.Icon = 'prev_frame_16px.png';
            comp.prevFrame.Tooltip = {'show the previous frame'};
            comp.prevFrame.Text = '';
            comp.frameNumberSlider = uislider(comp.frameGridLayout);
            comp.frameNumberSlider.Limits = [1 1.001];
            comp.frameNumberSlider.MajorTicks = [];
            comp.frameNumberSlider.MajorTickLabels = {};
            comp.frameNumberSlider.MinorTicks = [0 25 50 75 100];
            comp.frameNumberSlider.Step = 1;
            comp.frameNumberSlider.Tag = 'frameNumberSlider';
            comp.frameNumberSlider.Tooltip = {'change time frames, right click for options'};
            comp.frameNumberSlider.Value = 1;
            comp.nextFrame = uibutton(comp.frameGridLayout, 'push');
            comp.nextFrame.Tag = 'nextFrame';
            comp.nextFrame.Icon = 'next_frame_16px.png';
            comp.nextFrame.Tooltip = {'show the next frame'};
            comp.nextFrame.Text = '';
            comp.lastFrame = uibutton(comp.frameGridLayout, 'push');
            comp.lastFrame.Tag = 'lastFrame';
            comp.lastFrame.Icon = 'double_arrow_right_16px.png';
            comp.lastFrame.Tooltip = {'show the last frame'};
            comp.lastFrame.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end