classdef Roi < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout        matlab.ui.container.GridLayout
        rightDividerPanel     matlab.ui.container.Panel
        leftDividerPanel      matlab.ui.container.Panel
        rightGridLayout       matlab.ui.container.GridLayout
        centralGridLayout     matlab.ui.container.GridLayout
        roiFixAspect          matlab.ui.control.CheckBox
        roiShowLabel          matlab.ui.control.CheckBox
        help                  matlab.ui.control.Button
        roiToSelection        matlab.ui.control.Button
        roiHeight             matlab.ui.control.Spinner
        HeightSpinnerLabel    matlab.ui.control.Label
        roiWidth              matlab.ui.control.Spinner
        WidthSpinnerLabel     matlab.ui.control.Label
        roiY1                 matlab.ui.control.Spinner
        Y1SpinnerLabel        matlab.ui.control.Label
        roiX1                 matlab.ui.control.Spinner
        X1SpinnerLabel        matlab.ui.control.Label
        roiManually           matlab.ui.control.CheckBox
        leftGridLayout        matlab.ui.container.GridLayout
        roiModify             matlab.ui.control.Button
        roiShowROI            matlab.ui.control.CheckBox
        roiOptions            matlab.ui.control.Button
        roiType               matlab.ui.control.DropDown
        ROItypeDropDownLabel  matlab.ui.control.Label
        roiRemove             matlab.ui.control.Button
        roiAdd                matlab.ui.control.Button
        roiSave               matlab.ui.control.Button
        roiLoad               matlab.ui.control.Button
        roiList               matlab.ui.control.ListBox
        ROIlistListBoxLabel   matlab.ui.control.Label
    end

    properties (Access = public)
        handles % handles to the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.mainGridLayout = comp.mainGridLayout;
            comp.handles.roiOptions = comp.roiOptions;
            comp.handles.roiList = comp.roiList;
            comp.handles.roiLoad = comp.roiLoad;
            comp.handles.roiSave = comp.roiSave;
            comp.handles.roiAdd = comp.roiAdd;
            comp.handles.roiModify = comp.roiModify;
            comp.handles.roiRemove = comp.roiRemove;
            comp.handles.roiType = comp.roiType;
            comp.handles.roiFixAspect = comp.roiFixAspect;
            comp.handles.roiShowLabel = comp.roiShowLabel;
            comp.handles.roiShowROI = comp.roiShowROI;
            comp.handles.roiManually = comp.roiManually;
            comp.handles.roiX1 = comp.roiX1;
            comp.handles.roiY1 = comp.roiY1;
            comp.handles.roiWidth = comp.roiWidth;
            comp.handles.roiHeight = comp.roiHeight;
            comp.handles.roiToSelection = comp.roiToSelection;
            comp.handles.help = comp.help;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 910 147];
            comp.mainGridLayout = uigridlayout(comp);
            comp.mainGridLayout.Tag = 'mainGridLayout';
            comp.leftGridLayout = uigridlayout(comp.mainGridLayout);
            comp.ROIlistListBoxLabel = uilabel(comp.leftGridLayout);
            comp.ROIlistListBoxLabel.Text = 'ROI list';
            comp.roiList = uilistbox(comp.leftGridLayout);
            comp.roiList.Items = {};
            comp.roiList.Tag = 'roiList';
            comp.roiList.Value = {};
            comp.roiLoad = uibutton(comp.leftGridLayout, 'push');
            comp.roiLoad.Tag = 'roiLoad';
            comp.roiLoad.Icon = 'open_16px.png';
            comp.roiLoad.Tooltip = {'load ROI(s) from a file'};
            comp.roiLoad.Text = 'Load';
            comp.roiSave = uibutton(comp.leftGridLayout, 'push');
            comp.roiSave.Tag = 'roiSave';
            comp.roiSave.Icon = 'save_16px.png';
            comp.roiSave.Tooltip = {'save ROI(s) to a file'};
            comp.roiSave.Text = 'Save';
            comp.roiAdd = uibutton(comp.leftGridLayout, 'push');
            comp.roiAdd.Tag = 'roiAdd';
            comp.roiAdd.Icon = 'add_16px.png';
            comp.roiAdd.Tooltip = {'add new ROI'};
            comp.roiAdd.Text = 'Add';
            comp.roiRemove = uibutton(comp.leftGridLayout, 'push');
            comp.roiRemove.Tag = 'roiRemove';
            comp.roiRemove.Icon = 'remove_16px.png';
            comp.roiRemove.Tooltip = {'remove selected ROI'};
            comp.roiRemove.Text = 'Remove';
            comp.ROItypeDropDownLabel = uilabel(comp.leftGridLayout);
            comp.ROItypeDropDownLabel.Text = 'ROI type';
            comp.roiType = uidropdown(comp.leftGridLayout);
            comp.roiType.Items = {'Rectangle', 'Ellipse', 'Polyline', 'Lasso'};
            comp.roiType.Tag = 'roiType';
            comp.roiType.Tooltip = {'select ROI type'};
            comp.roiType.Value = 'Rectangle';
            comp.roiOptions = uibutton(comp.leftGridLayout, 'push');
            comp.roiOptions.Tag = 'roiOptions';
            comp.roiOptions.Icon = 'settings_16px.png';
            comp.roiOptions.Tooltip = {'adjust ROI visualization settings'};
            comp.roiOptions.Text = 'Options';
            comp.roiShowROI = uicheckbox(comp.leftGridLayout);
            comp.roiShowROI.Tag = 'roiShowROI';
            comp.roiShowROI.Tooltip = {'show ROI in the image view panel'};
            comp.roiShowROI.Text = 'show ROI';
            comp.roiModify = uibutton(comp.leftGridLayout, 'push');
            comp.roiModify.Tag = 'roiModify';
            comp.roiModify.Icon = 'edit_16px.png';
            comp.roiModify.Tooltip = {'modify the selected ROI'};
            comp.roiModify.Text = 'Modify';
            comp.centralGridLayout = uigridlayout(comp.mainGridLayout);
            comp.roiManually = uicheckbox(comp.centralGridLayout);
            comp.roiManually.Tag = 'roiManually';
            comp.roiManually.Tooltip = {'check to add ROI using the specified dimensions'};
            comp.roiManually.Text = 'manually';
            comp.X1SpinnerLabel = uilabel(comp.centralGridLayout);
            comp.X1SpinnerLabel.Enable = 'off';
            comp.X1SpinnerLabel.Text = 'X1';
            comp.roiX1 = uispinner(comp.centralGridLayout);
            comp.roiX1.Limits = [1 Inf];
            comp.roiX1.RoundFractionalValues = 'on';
            comp.roiX1.Tag = 'roiX1';
            comp.roiX1.Enable = 'off';
            comp.roiX1.Tooltip = {'define X of the top left corner for a new ROI'};
            comp.roiX1.Value = 100;
            comp.Y1SpinnerLabel = uilabel(comp.centralGridLayout);
            comp.Y1SpinnerLabel.Enable = 'off';
            comp.Y1SpinnerLabel.Text = 'Y1';
            comp.roiY1 = uispinner(comp.centralGridLayout);
            comp.roiY1.Limits = [1 Inf];
            comp.roiY1.RoundFractionalValues = 'on';
            comp.roiY1.Tag = 'roiY1';
            comp.roiY1.Enable = 'off';
            comp.roiY1.Tooltip = {'define Y of the top left corner for a new ROI'};
            comp.roiY1.Value = 100;
            comp.WidthSpinnerLabel = uilabel(comp.centralGridLayout);
            comp.WidthSpinnerLabel.Enable = 'off';
            comp.WidthSpinnerLabel.Text = 'Width';
            comp.roiWidth = uispinner(comp.centralGridLayout);
            comp.roiWidth.Limits = [1 Inf];
            comp.roiWidth.RoundFractionalValues = 'on';
            comp.roiWidth.Tag = 'roiWidth';
            comp.roiWidth.Enable = 'off';
            comp.roiWidth.Tooltip = {'define ROI width'};
            comp.roiWidth.Value = 200;
            comp.HeightSpinnerLabel = uilabel(comp.centralGridLayout);
            comp.HeightSpinnerLabel.Enable = 'off';
            comp.HeightSpinnerLabel.Text = 'Height';
            comp.roiHeight = uispinner(comp.centralGridLayout);
            comp.roiHeight.Limits = [1 Inf];
            comp.roiHeight.RoundFractionalValues = 'on';
            comp.roiHeight.Tag = 'roiHeight';
            comp.roiHeight.Enable = 'off';
            comp.roiHeight.Tooltip = {'define ROI height'};
            comp.roiHeight.Value = 200;
            comp.roiToSelection = uibutton(comp.centralGridLayout, 'push');
            comp.roiToSelection.Tag = 'roiToSelection';
            comp.roiToSelection.Tooltip = {'convert ROI to the Selection layer'};
            comp.roiToSelection.Text = 'ROI to Selection';
            comp.help = uibutton(comp.centralGridLayout, 'push');
            comp.help.Tag = 'roiHelp';
            comp.help.Icon = 'help_16px.png';
            comp.help.Text = '';
            comp.roiShowLabel = uicheckbox(comp.centralGridLayout);
            comp.roiShowLabel.Tag = 'roiShowLabel';
            comp.roiShowLabel.Tooltip = {'add label with the ROI index next to the ROI'};
            comp.roiShowLabel.Text = 'show label';
            comp.roiFixAspect = uicheckbox(comp.centralGridLayout);
            comp.roiFixAspect.Tag = 'roiFixAspect';
            comp.roiFixAspect.Tooltip = {'fix aspect ration during ROI addition'};
            comp.roiFixAspect.Text = 'fix aspect';
            comp.rightGridLayout = uigridlayout(comp.mainGridLayout);
            comp.leftDividerPanel = uipanel(comp.mainGridLayout);
            comp.rightDividerPanel = uipanel(comp.mainGridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end