classdef Segmentation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        panelSAM                  matlab.ui.container.Panel
        samVersion                matlab.ui.control.DropDown
        VersionDropDownLabel      matlab.ui.control.Label
        samClear                  matlab.ui.control.Button
        samList                   matlab.ui.control.Button
        samSettings               matlab.ui.control.Button
        DatasetDropDownLabel      matlab.ui.control.Label
        samSegment                matlab.ui.control.Button
        samMode                   matlab.ui.control.DropDown
        ModeDropDownLabel_2       matlab.ui.control.Label
        samDestination            matlab.ui.control.DropDown
        DestinationDropDownLabel  matlab.ui.control.Label
        samDataset                matlab.ui.control.DropDown
        samMethod                 matlab.ui.control.DropDown
        MethodDropDownLabel_2     matlab.ui.control.Label
        lassoObjectPicker         matlab.ui.container.Panel
        objectRecalculate         matlab.ui.control.Button
        panelMembrane             matlab.ui.container.Panel
        Label2_2                  matlab.ui.control.Label
        membraneWidth             matlab.ui.control.Spinner
        WidthSpinnerLabel         matlab.ui.control.Label
        membraneScale             matlab.ui.control.Spinner
        ScaleSpinnerLabel         matlab.ui.control.Label
        membraneRecenterView      matlab.ui.control.CheckBox
        membraneBlackSignal       matlab.ui.control.CheckBox
        membraneStraightLine      matlab.ui.control.CheckBox
        panelMagicwand            matlab.ui.container.Panel
        magicConnect              matlab.ui.container.ButtonGroup
        magicConnect4             matlab.ui.control.RadioButton
        magicConnect8             matlab.ui.control.RadioButton
        magicRadius               matlab.ui.control.NumericEditField
        RadiusEditFieldLabel      matlab.ui.control.Label
        magicLabel                matlab.ui.control.Label
        magicRange2               matlab.ui.control.NumericEditField
        magicRange1               matlab.ui.control.NumericEditField
        magicVariationLabel       matlab.ui.control.Label
        magicMethod               matlab.ui.control.DropDown
        MethodDropDownLabel       matlab.ui.control.Label
        panelLasso                matlab.ui.container.Panel
        Label2                    matlab.ui.control.Label
        lassoCustomParameters     matlab.ui.container.Panel
        lassoHeight               matlab.ui.control.NumericEditField
        Width2Label_2             matlab.ui.control.Label
        lassoWidth                matlab.ui.control.NumericEditField
        Width2Label               matlab.ui.control.Label
        lassoY1                   matlab.ui.control.NumericEditField
        Y1Label                   matlab.ui.control.Label
        lassoX1                   matlab.ui.control.NumericEditField
        X1EditFieldLabel_2        matlab.ui.control.Label
        lassoSelect               matlab.ui.control.Button
        lassoManually             matlab.ui.control.CheckBox
        lassoMode                 matlab.ui.control.DropDown
        ModeDropDownLabel         matlab.ui.control.Label
        lassoType                 matlab.ui.control.DropDown
        TypeLabel                 matlab.ui.control.Label
        panelDrag                 matlab.ui.container.Panel
        dragValue                 matlab.ui.control.NumericEditField
        dragDown                  matlab.ui.control.Button
        dragRight                 matlab.ui.control.Button
        dragLeft                  matlab.ui.control.Button
        dragUp                    matlab.ui.control.Button
        dragLayer                 matlab.ui.control.DropDown
        dragText                  matlab.ui.control.Label
        LayerDropDownLabel        matlab.ui.control.Label
        panelThresholding         matlab.ui.container.Panel
        threshold                 matlab.ui.control.Button
        HighSliderLabel           matlab.ui.control.Label
        thresholdHighValue        matlab.ui.control.NumericEditField
        thresholdHigh             matlab.ui.control.Slider
        thresholdLowValue         matlab.ui.control.NumericEditField
        thresholdLow              matlab.ui.control.Slider
        LowSliderLabel            matlab.ui.control.Label
        thresholdInvert           matlab.ui.control.CheckBox
        threshold4D               matlab.ui.control.CheckBox
        threshold3D               matlab.ui.control.CheckBox
        thresholdType             matlab.ui.control.DropDown
        thresholdAdaptive         matlab.ui.control.CheckBox
        panelAnnotations          matlab.ui.container.Panel
        annPrecision              matlab.ui.control.Spinner
        PrecisionLabel            matlab.ui.control.Label
        annDisplayAs              matlab.ui.control.DropDown
        DisplayasDropDownLabel    matlab.ui.control.Label
        annFocusOnValue           matlab.ui.control.CheckBox
        annShowPrompt             matlab.ui.control.CheckBox
        annDeleteAll              matlab.ui.control.Button
        annAnnotationList         matlab.ui.control.Button
        panelLines3D              matlab.ui.container.Panel
        linesShowLines            matlab.ui.control.CheckBox
        linesAltClick             matlab.ui.control.DropDown
        AltClickDropDownLabel     matlab.ui.control.Label
        linesCtrlClick            matlab.ui.control.DropDown
        CtrlClickDropDownLabel    matlab.ui.control.Label
        linesShiftClick           matlab.ui.control.DropDown
        ShiftClickDropDownLabel   matlab.ui.control.Label
        linesClick                matlab.ui.control.DropDown
        ClickDropDownLabel        matlab.ui.control.Label
        linesTableView            matlab.ui.control.Button
        mainGridLayout            matlab.ui.container.GridLayout
        topGridLayout             matlab.ui.container.GridLayout
        objects3D                 matlab.ui.control.CheckBox
        createModel               matlab.ui.control.Button
        loadModel                 matlab.ui.control.Button
        addMaterial               matlab.ui.control.Button
        removeMaterial            matlab.ui.control.Button
        viewSettings              matlab.ui.control.Button
        help                      matlab.ui.control.Button
        colorWheel                matlab.ui.control.Button
        restrictPanel             matlab.ui.container.Panel
        middleGridLayout          matlab.ui.container.GridLayout
        segmTool                  matlab.ui.control.DropDown
        restrictMaterial          matlab.ui.control.CheckBox
        restrictMask              matlab.ui.control.CheckBox
        favoriteTool              matlab.ui.control.CheckBox
        panelBrush                matlab.ui.container.Panel
        brushFixToScreen          matlab.ui.control.CheckBox
        brushUseClustering        matlab.ui.container.ButtonGroup
        clustersPar2              matlab.ui.control.NumericEditField
        CompactEditFieldLabel     matlab.ui.control.Label
        clustersPar1              matlab.ui.control.NumericEditField
        NEditFieldLabel           matlab.ui.control.Label
        slicClusters              matlab.ui.control.RadioButton
        watershedClusters         matlab.ui.control.RadioButton
        noClusters                matlab.ui.control.RadioButton
        interpolationLabel        matlab.ui.control.Label
        interpolationSettings     matlab.ui.control.Button
        eraserFactor              matlab.ui.control.Spinner
        EraserxSpinnerLabel       matlab.ui.control.Label
        brushRadius               matlab.ui.control.Spinner
        RadiusSpinnerLabel        matlab.ui.control.Label
        materialsTable            matlab.ui.control.Table
    end

    properties (Access = public)
        handles % structure with handles to the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.mainGridLayout = comp.mainGridLayout;
            comp.handles.topGridLayout = comp.topGridLayout;
            comp.handles.middleGridLayout = comp.middleGridLayout;
            
            comp.handles.createModel = comp.createModel;
            comp.handles.loadModel = comp.loadModel;
            comp.handles.addMaterial = comp.addMaterial;
            comp.handles.removeMaterial = comp.removeMaterial;
            comp.handles.objects3D = comp.objects3D;
            
            comp.handles.colorWheel = comp.colorWheel;
            comp.handles.viewSettings = comp.viewSettings;
            comp.handles.help = comp.help;
            comp.handles.materialsTable = comp.materialsTable;
            comp.handles.restrictMaterial = comp.restrictMaterial;
            comp.handles.restrictMask = comp.restrictMask;
            comp.handles.favoriteTool = comp.favoriteTool;
            comp.handles.segmTool = comp.segmTool;

            
            % ----------------- 3D lines panel -----------------
            comp.handles.panelLines3D = comp.panelLines3D;
            comp.handles.linesTableView = comp.linesTableView;
            comp.handles.linesShowLines = comp.linesShowLines;
            comp.handles.linesClick = comp.linesClick;
            comp.handles.linesShiftClick = comp.linesShiftClick;
            comp.handles.linesCtrlClick = comp.linesCtrlClick;
            comp.handles.linesAltClick = comp.linesAltClick;

            comp.panelLines3D.Visible = 'off';
            comp.panelLines3D.Parent = comp.panelBrush.Parent;
            comp.panelLines3D.Layout.Row = 4;
            comp.panelLines3D.Layout.Column = 1;

            % ----------------- Brush panel -----------------
            comp.handles.panelBrush = comp.panelBrush;
            comp.handles.brushRadius = comp.brushRadius;
            comp.handles.eraserFactor = comp.eraserFactor;
            comp.handles.interpolationSettings = comp.interpolationSettings;
            comp.handles.brushFixToScreen = comp.brushFixToScreen;
            
            % brushUseClustering radio group
            comp.handles.brushUseClustering = comp.brushUseClustering;
            comp.handles.noClusters = comp.noClusters;
            comp.handles.watershedClusters = comp.watershedClusters;
            comp.handles.slicClusters = comp.slicClusters;
            comp.handles.clustersPar1 = comp.clustersPar1;
            comp.handles.clustersPar2 = comp.clustersPar2;


            % ----------------- Annotations panel -----------------
            comp.handles.panelAnnotations = comp.panelAnnotations;
            comp.handles.annAnnotationList = comp.annAnnotationList;
            comp.handles.annDeleteAll = comp.annDeleteAll;
            comp.handles.annPrecision = comp.annPrecision;
            comp.handles.annShowPrompt = comp.annShowPrompt;
            comp.handles.annFocusOnValue = comp.annFocusOnValue;
            comp.handles.annDisplayAs = comp.annDisplayAs;

            comp.panelAnnotations.Parent = comp.panelBrush.Parent;
            comp.panelAnnotations.Layout.Row = 4;
            comp.panelAnnotations.Layout.Column = 1;

            % ----------------- Black-and-white thresholding panel -----------------
            comp.handles.panelThresholding = comp.panelThresholding;
            comp.handles.thresholdAdaptive = comp.thresholdAdaptive;
            comp.handles.thresholdType = comp.thresholdType;
            comp.handles.threshold3D = comp.threshold3D;
            comp.handles.threshold4D = comp.threshold4D;
            comp.handles.thresholdInvert = comp.thresholdInvert;
            comp.handles.thresholdLow = comp.thresholdLow;
            comp.handles.thresholdLowValue = comp.thresholdLowValue;
            comp.handles.thresholdHigh = comp.thresholdHigh;
            comp.handles.thresholdLowValue = comp.thresholdLowValue;
            comp.handles.thresholdHighValue = comp.thresholdHighValue;
            comp.handles.threshold = comp.threshold;
            
            comp.panelThresholding.Parent = comp.panelBrush.Parent;
            comp.panelThresholding.Layout.Row = 4;
            comp.panelThresholding.Layout.Column = 1;

            % ----------------- Drag and drop materials panel -----------------
            comp.handles.panelDrag = comp.panelDrag;
            comp.handles.dragLayer = comp.dragLayer;
            comp.handles.dragUp = comp.dragUp;
            comp.handles.dragRight = comp.dragLeft;
            comp.handles.dragLeft = comp.dragRight;
            comp.handles.dragDown = comp.dragDown;
            comp.handles.dragValue = comp.dragValue;
            comp.handles.dragText = comp.dragText;
            comp.handles.dragText.Text = sprintf('Control+Mouse -> move selected object\nShift+Mouse -> move all objects');

            comp.panelDrag.Parent = comp.panelBrush.Parent;
            comp.panelDrag.Layout.Row = 4;
            comp.panelDrag.Layout.Column = 1;

            % ----------------- Lasso panel -----------------
            comp.handles.panelLasso = comp.panelLasso;
            comp.handles.lassoType = comp.lassoType;
            comp.handles.lassoMode = comp.lassoMode;
            comp.handles.lassoManually = comp.lassoManually;
            comp.handles.lassoX1 = comp.lassoX1;
            comp.handles.lassoY1 = comp.lassoY1;
            comp.handles.lassoWidth = comp.lassoWidth;
            comp.handles.lassoHeight = comp.lassoHeight;
            comp.handles.lassoSelect = comp.lassoSelect;

            comp.panelLasso.Parent = comp.panelBrush.Parent;
            comp.panelLasso.Layout.Row = 4;
            comp.panelLasso.Layout.Column = 1;

            % ----------------- Magic wand panel -----------------
            comp.handles.panelMagicwand = comp.panelMagicwand;
            comp.handles.magicMethod = comp.magicMethod;
            comp.handles.magicRange1 = comp.magicRange1;
            comp.handles.magicRange2 = comp.magicRange2;
            comp.handles.magicRadius = comp.magicRadius;
            comp.handles.magicConnect = comp.magicConnect;
            comp.handles.magicConnect8 = comp.magicConnect8;
            comp.handles.magicConnect4 = comp.magicConnect4;

            comp.panelMagicwand.Parent = comp.panelBrush.Parent;
            comp.panelMagicwand.Layout.Row = 4;
            comp.panelMagicwand.Layout.Column = 1;

            % ----------------- Membrane click tracker panel -----------------
            comp.handles.panelMembrane = comp.panelMembrane;
            comp.handles.membraneScale = comp.membraneScale;
            comp.handles.membraneWidth = comp.membraneWidth;
            comp.handles.membraneStraightLine = comp.membraneStraightLine;
            comp.handles.membraneBlackSignal = comp.membraneBlackSignal;
            comp.handles.membraneRecenterView = comp.membraneRecenterView;
            
            comp.panelMembrane.Parent = comp.panelBrush.Parent;
            comp.panelMembrane.Layout.Row = 4;
            comp.panelMembrane.Layout.Column = 1;

            % ----------------- Object picker panel -----------------
            comp.handles.objectRecalculate = comp.objectRecalculate;
            comp.handles.lassoObjectPicker = comp.lassoObjectPicker;
            comp.lassoObjectPicker.Parent = comp.lassoCustomParameters.Parent;
            comp.lassoObjectPicker.Position = comp.lassoCustomParameters.Position;

            % ----------------- SAM panel -----------------
            comp.handles.panelSAM = comp.panelSAM;
            comp.handles.samMethod = comp.samMethod;
            comp.handles.samDataset = comp.samDataset;
            comp.handles.samDestination = comp.samDestination;
            comp.handles.samMode = comp.samMode;
            comp.handles.samVersion = comp.samVersion;
            comp.handles.samSettings = comp.samSettings;
            comp.handles.samList = comp.samList;
            comp.handles.samClear = comp.samClear;
            comp.handles.samSegment = comp.samSegment;

            comp.panelSAM.Parent = comp.panelBrush.Parent;
            comp.panelSAM.Layout.Row = 4;
            comp.panelSAM.Layout.Column = 1;

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 276 512];
            comp.mainGridLayout = uigridlayout(comp);
            comp.mainGridLayout.Tag = 'mainGridLayout';
            comp.materialsTable = uitable(comp.mainGridLayout);
            comp.materialsTable.ColumnName = {'C'; 'Material'; 'Add to'};
            comp.materialsTable.RowName = {};
            comp.panelBrush = uipanel(comp.mainGridLayout);
            comp.RadiusSpinnerLabel = uilabel(comp.panelBrush);
            comp.RadiusSpinnerLabel.Text = 'Radius';
            comp.brushRadius = uispinner(comp.panelBrush);
            comp.brushRadius.Limits = [1 Inf];
            comp.brushRadius.RoundFractionalValues = 'on';
            comp.brushRadius.Tag = 'brushRadius';
            comp.brushRadius.Tooltip = {'brush size in pixels; use the LMB to paint'};
            comp.brushRadius.Value = 51;
            comp.EraserxSpinnerLabel = uilabel(comp.panelBrush);
            comp.EraserxSpinnerLabel.Text = 'Eraser, x';
            comp.eraserFactor = uispinner(comp.panelBrush);
            comp.eraserFactor.Step = 0.25;
            comp.eraserFactor.Limits = [0.01 Inf];
            comp.eraserFactor.ValueDisplayFormat = '%11.3g';
            comp.eraserFactor.Tag = 'eraserFactor';
            comp.eraserFactor.Tooltip = {'use Ctrl+LMB to enable eraser, the size of the brush is magnified using this factor'};
            comp.eraserFactor.Value = 1.25;
            comp.interpolationSettings = uibutton(comp.panelBrush, 'push');
            comp.interpolationSettings.Tag = 'interpolationSettings';
            comp.interpolationSettings.Icon = 'settings_16px.png';
            comp.interpolationSettings.Tooltip = {'update interpolation settings'};
            comp.interpolationSettings.Text = '';
            comp.interpolationLabel = uilabel(comp.panelBrush);
            comp.interpolationLabel.Text = 'Interpolation';
            comp.brushUseClustering = uibuttongroup(comp.panelBrush);
            comp.brushUseClustering.Title = 'Use clustering';
            comp.brushUseClustering.Tag = 'brushUseClustering';
            comp.noClusters = uiradiobutton(comp.brushUseClustering);
            comp.noClusters.Tooltip = {'Use plain brush without clustering'};
            comp.noClusters.Text = 'No clusters';
            comp.noClusters.Value = true;
            comp.watershedClusters = uiradiobutton(comp.brushUseClustering);
            comp.watershedClusters.Tooltip = {'Watershed clustering of pixels, good for objects with ridges (membranes)'};
            comp.watershedClusters.Text = 'Watershed';
            comp.slicClusters = uiradiobutton(comp.brushUseClustering);
            comp.slicClusters.Tooltip = {'SLIC clustering of pixels, good for objects with distinct intensities'};
            comp.slicClusters.Text = 'SLIC';
            comp.NEditFieldLabel = uilabel(comp.brushUseClustering);
            comp.NEditFieldLabel.Text = 'N';
            comp.clustersPar1 = uieditfield(comp.brushUseClustering, 'numeric');
            comp.clustersPar1.Limits = [1 Inf];
            comp.clustersPar1.RoundFractionalValues = 'on';
            comp.clustersPar1.Tag = 'clustersPar1';
            comp.clustersPar1.Value = 220;
            comp.CompactEditFieldLabel = uilabel(comp.brushUseClustering);
            comp.CompactEditFieldLabel.Text = 'Compact';
            comp.clustersPar2 = uieditfield(comp.brushUseClustering, 'numeric');
            comp.clustersPar2.Limits = [1 Inf];
            comp.clustersPar2.RoundFractionalValues = 'on';
            comp.clustersPar2.Tag = 'clustersPar2';
            comp.clustersPar2.Value = 50;
            comp.brushFixToScreen = uicheckbox(comp.panelBrush);
            comp.brushFixToScreen.Tag = 'brushFixToScreen';
            comp.brushFixToScreen.Tooltip = {'the brush diameter fixed in screen pixels as result it keeps the diameter at all magnifications'};
            comp.brushFixToScreen.Text = 'fix to screen';
            comp.restrictPanel = uipanel(comp.mainGridLayout);
            comp.middleGridLayout = uigridlayout(comp.restrictPanel);
            comp.middleGridLayout.Tag = 'middleGridLayout';
            comp.favoriteTool = uicheckbox(comp.middleGridLayout);
            comp.favoriteTool.Tag = 'favoriteTool';
            comp.favoriteTool.Tooltip = {'check to define a favorite tool, toggle the favorite tools using ''D'' shortcut'};
            comp.favoriteTool.Text = 'Favorite tool (D)';
            comp.restrictMask = uicheckbox(comp.middleGridLayout);
            comp.restrictMask.Tag = 'restrictMask';
            comp.restrictMask.Tooltip = {'when checked only masked area will be modified during selection'};
            comp.restrictMask.Text = 'Restrict to mask';
            comp.restrictMaterial = uicheckbox(comp.middleGridLayout);
            comp.restrictMaterial.Tag = 'restrictMaterial';
            comp.restrictMaterial.Tooltip = {'when checked only the selected material will be modified during selection'};
            comp.restrictMaterial.Text = 'Restrict to material';
            comp.segmTool = uidropdown(comp.middleGridLayout);
            comp.segmTool.Items = {'3D ball', '3D lines', 'Annotations', 'Brush', 'BW thresholding', 'Drag&Drop materials', 'Lasso', 'MagicWand/RegionGrowing', 'Membrane ClickTracker', 'Object picker', 'Segment-anything model', 'Spot'};
            comp.segmTool.Tag = 'segmTool';
            comp.segmTool.Value = 'Brush';
            comp.topGridLayout = uigridlayout(comp.mainGridLayout);
            comp.topGridLayout.Tag = 'topGridLayout';
            comp.colorWheel = uibutton(comp.topGridLayout, 'push');
            comp.colorWheel.Tag = 'colorWheel';
            comp.colorWheel.Icon = 'color_wheel_16px.png';
            comp.colorWheel.Tooltip = {'adjust the color scheme for visualization of materials. Use RMB for additional options'};
            comp.colorWheel.Text = '';
            comp.help = uibutton(comp.topGridLayout, 'push');
            comp.help.Tag = 'segmentationHelp';
            comp.help.Icon = 'help_16px.png';
            comp.help.Text = '';
            comp.viewSettings = uibutton(comp.topGridLayout, 'push');
            comp.viewSettings.Tag = 'viewSettings';
            comp.viewSettings.Icon = 'view_settings_16px.png';
            comp.viewSettings.Text = '';
            comp.removeMaterial = uibutton(comp.topGridLayout, 'push');
            comp.removeMaterial.Tag = 'removeMaterial';
            comp.removeMaterial.Text = '';
            comp.addMaterial = uibutton(comp.topGridLayout, 'push');
            comp.addMaterial.Tag = 'addMaterial';
            comp.addMaterial.Text = '';
            comp.loadModel = uibutton(comp.topGridLayout, 'push');
            comp.loadModel.Tag = 'loadModel';
            comp.loadModel.Text = 'Load';
            comp.createModel = uibutton(comp.topGridLayout, 'push');
            comp.createModel.Tag = 'createModel';
            comp.createModel.Text = 'New';
            comp.objects3D = uicheckbox(comp.topGridLayout);
            comp.objects3D.Tag = 'objects3D';
            comp.objects3D.Enable = 'off';
            comp.objects3D.Text = '3D';
            comp.panelLines3D = uipanel(comp);
            comp.panelLines3D.Visible = 'off';
            comp.linesTableView = uibutton(comp.panelLines3D, 'push');
            comp.linesTableView.Tag = 'linesTableView';
            comp.linesTableView.Icon = 'table_16px.png';
            comp.linesTableView.Text = 'Table view';
            comp.ClickDropDownLabel = uilabel(comp.panelLines3D);
            comp.ClickDropDownLabel.Text = 'Click';
            comp.linesClick = uidropdown(comp.panelLines3D);
            comp.linesClick.Items = {'Add node', 'Assign active node', 'Connect to node', 'Delete node', 'Insert node after active', 'Modify active node', 'New tree', 'Split tree'};
            comp.linesClick.Tag = 'linesClick';
            comp.linesClick.Value = 'Add node';
            comp.ShiftClickDropDownLabel = uilabel(comp.panelLines3D);
            comp.ShiftClickDropDownLabel.Text = 'Shift+Click';
            comp.linesShiftClick = uidropdown(comp.panelLines3D);
            comp.linesShiftClick.Items = {'Add node', 'Assign active node', 'Connect to node', 'Delete node', 'Insert node after active', 'Modify active node', 'New tree', 'Split tree'};
            comp.linesShiftClick.Tag = 'linesShiftClick';
            comp.linesShiftClick.Value = 'New tree';
            comp.CtrlClickDropDownLabel = uilabel(comp.panelLines3D);
            comp.CtrlClickDropDownLabel.Text = 'Ctrl+Click';
            comp.linesCtrlClick = uidropdown(comp.panelLines3D);
            comp.linesCtrlClick.Items = {'Add node', 'Assign active node', 'Connect to node', 'Delete node', 'Insert node after active', 'Modify active node', 'New tree', 'Split tree'};
            comp.linesCtrlClick.Tag = 'linesCtrlClick';
            comp.linesCtrlClick.Value = 'Assign active node';
            comp.AltClickDropDownLabel = uilabel(comp.panelLines3D);
            comp.AltClickDropDownLabel.Text = 'Alt+Click';
            comp.linesAltClick = uidropdown(comp.panelLines3D);
            comp.linesAltClick.Items = {'Add node', 'Assign active node', 'Connect to node', 'Delete node', 'Insert node after active', 'Modify active node', 'New tree', 'Split tree'};
            comp.linesAltClick.Tag = 'linesAltClick';
            comp.linesAltClick.Value = 'Modify active node';
            comp.linesShowLines = uicheckbox(comp.panelLines3D);
            comp.linesShowLines.Tag = 'linesShowLines';
            comp.linesShowLines.Text = 'show lines';
            comp.linesShowLines.Value = true;
            comp.panelAnnotations = uipanel(comp);
            comp.panelAnnotations.Visible = 'off';
            comp.annAnnotationList = uibutton(comp.panelAnnotations, 'push');
            comp.annAnnotationList.Tag = 'annAnnotationList';
            comp.annAnnotationList.Icon = 'list_16px.png';
            comp.annAnnotationList.Text = 'Annotation list';
            comp.annDeleteAll = uibutton(comp.panelAnnotations, 'push');
            comp.annDeleteAll.Tag = 'annDeleteAll';
            comp.annDeleteAll.Text = 'Delete all';
            comp.annShowPrompt = uicheckbox(comp.panelAnnotations);
            comp.annShowPrompt.Tag = 'annShowPrompt';
            comp.annShowPrompt.Text = 'show prompt';
            comp.annShowPrompt.Value = true;
            comp.annFocusOnValue = uicheckbox(comp.panelAnnotations);
            comp.annFocusOnValue.Tag = 'annFocusOnValue';
            comp.annFocusOnValue.Text = 'focus on value';
            comp.DisplayasDropDownLabel = uilabel(comp.panelAnnotations);
            comp.DisplayasDropDownLabel.Text = 'Display as';
            comp.annDisplayAs = uidropdown(comp.panelAnnotations);
            comp.annDisplayAs.Items = {'Marker', 'Label', 'Value', 'Label + Value'};
            comp.annDisplayAs.Tag = 'annDisplayAs';
            comp.annDisplayAs.Value = 'Label + Value';
            comp.PrecisionLabel = uilabel(comp.panelAnnotations);
            comp.PrecisionLabel.Text = 'Precision';
            comp.annPrecision = uispinner(comp.panelAnnotations);
            comp.annPrecision.Limits = [0 Inf];
            comp.annPrecision.RoundFractionalValues = 'on';
            comp.annPrecision.ValueDisplayFormat = '%11d';
            comp.annPrecision.Tag = 'annPrecision';
            comp.annPrecision.Value = 3;
            comp.panelThresholding = uipanel(comp);
            comp.panelThresholding.Visible = 'off';
            comp.thresholdAdaptive = uicheckbox(comp.panelThresholding);
            comp.thresholdAdaptive.Tag = 'thresholdAdaptive';
            comp.thresholdAdaptive.Tooltip = {'Enable adaptive thresholding'};
            comp.thresholdAdaptive.Text = 'adaptive';
            comp.thresholdType = uidropdown(comp.panelThresholding);
            comp.thresholdType.Items = {'black-on-white', 'white-on-black'};
            comp.thresholdType.Tag = 'thresholdType';
            comp.thresholdType.Enable = 'off';
            comp.thresholdType.Value = 'black-on-white';
            comp.threshold3D = uicheckbox(comp.panelThresholding);
            comp.threshold3D.Tag = 'threshold3D';
            comp.threshold3D.Tooltip = {'Enable black and white thresholding in for 3D stack'};
            comp.threshold3D.Text = '3D';
            comp.threshold4D = uicheckbox(comp.panelThresholding);
            comp.threshold4D.Tag = 'threshold4D';
            comp.threshold4D.Tooltip = {'Enable black and white thresholding in for 4D dataset'};
            comp.threshold4D.Enable = 'off';
            comp.threshold4D.Text = '4D';
            comp.thresholdInvert = uicheckbox(comp.panelThresholding);
            comp.thresholdInvert.Tag = 'thresholdInvert';
            comp.thresholdInvert.Enable = 'off';
            comp.thresholdInvert.Text = 'invert';
            comp.LowSliderLabel = uilabel(comp.panelThresholding);
            comp.LowSliderLabel.Tag = 'lowSliderLabel';
            comp.LowSliderLabel.Text = 'Low';
            comp.thresholdLow = uislider(comp.panelThresholding);
            comp.thresholdLow.Limits = [0 255];
            comp.thresholdLow.MajorTicks = [0 64 128 192 255];
            comp.thresholdLow.MajorTickLabels = {'0', '1/4', '1/2', '3/4', '1'};
            comp.thresholdLow.MinorTicks = [0 16 32 48 64 80 96 112 128 144 160 176 192 208 224 240 255];
            comp.thresholdLow.Tag = 'thresholdLow';
            comp.thresholdLowValue = uieditfield(comp.panelThresholding, 'numeric');
            comp.thresholdLowValue.Limits = [0 255];
            comp.thresholdLowValue.ValueDisplayFormat = '%.0f';
            comp.thresholdLowValue.Tag = 'thresholdLowValue';
            comp.thresholdLowValue.Tooltip = {'Minimum intensity value for black and white thresholding'};
            comp.thresholdHigh = uislider(comp.panelThresholding);
            comp.thresholdHigh.Limits = [0 255];
            comp.thresholdHigh.MajorTicks = [0 64 128 192 255];
            comp.thresholdHigh.MajorTickLabels = {'0', '1/4', '1/2', '3/4', '1'};
            comp.thresholdHigh.MinorTicks = [0 16 32 48 64 80 96 112 128 144 160 176 192 208 224 240 255];
            comp.thresholdHigh.Tag = 'thresholdHigh';
            comp.thresholdHigh.Value = 255;
            comp.thresholdHighValue = uieditfield(comp.panelThresholding, 'numeric');
            comp.thresholdHighValue.Limits = [0 65535];
            comp.thresholdHighValue.ValueDisplayFormat = '%.0f';
            comp.thresholdHighValue.Tag = 'thresholdHighValue';
            comp.thresholdHighValue.Tooltip = {'Maximum intensity value for black and white thresholding'};
            comp.thresholdHighValue.Value = 255;
            comp.HighSliderLabel = uilabel(comp.panelThresholding);
            comp.HighSliderLabel.Tag = 'HighSliderLabel';
            comp.HighSliderLabel.Text = 'High';
            comp.threshold = uibutton(comp.panelThresholding, 'push');
            comp.threshold.Tag = 'threshold';
            comp.threshold.Tooltip = {'Threshold the dataset using the defined values'};
            comp.threshold.Text = 'Threshold';
            comp.panelDrag = uipanel(comp);
            comp.panelDrag.Visible = 'off';
            comp.LayerDropDownLabel = uilabel(comp.panelDrag);
            comp.LayerDropDownLabel.Text = 'Layer';
            comp.dragText = uilabel(comp.panelDrag);
            comp.dragLayer = uidropdown(comp.panelDrag);
            comp.dragLayer.Items = {'selection', 'mask', 'labels'};
            comp.dragLayer.Tag = 'dragLayer';
            comp.dragLayer.Tooltip = {'Select the layer type to use for drag-and-drop'};
            comp.dragLayer.Value = 'selection';
            comp.dragUp = uibutton(comp.panelDrag, 'push');
            comp.dragUp.Tag = 'dragUp';
            comp.dragUp.Tooltip = {'Move up'};
            comp.dragUp.Text = '^';
            comp.dragLeft = uibutton(comp.panelDrag, 'push');
            comp.dragLeft.Tag = 'dragLeft';
            comp.dragLeft.Tooltip = {'Move to left'};
            comp.dragLeft.Text = '<';
            comp.dragRight = uibutton(comp.panelDrag, 'push');
            comp.dragRight.Tag = 'dragRight';
            comp.dragRight.Tooltip = {'Move to right'};
            comp.dragRight.Text = '>';
            comp.dragDown = uibutton(comp.panelDrag, 'push');
            comp.dragDown.Tag = 'dragDown';
            comp.dragDown.Tooltip = {'Move down'};
            comp.dragDown.Text = 'v';
            comp.dragValue = uieditfield(comp.panelDrag, 'numeric');
            comp.dragValue.Tag = 'dragValue';
            comp.dragValue.Tooltip = {'Step for the shift'};
            comp.dragValue.Value = 1;
            comp.panelLasso = uipanel(comp);
            comp.panelLasso.Visible = 'off';
            comp.TypeLabel = uilabel(comp.panelLasso);
            comp.TypeLabel.Text = 'Type';
            comp.lassoType = uidropdown(comp.panelLasso);
            comp.lassoType.Items = {'Lasso', 'Rectangle', 'Ellipse', 'Polyline'};
            comp.lassoType.Tag = 'lassoType';
            comp.lassoType.Tooltip = {'Select the type of lasso tool to use for ROI drawing'};
            comp.lassoType.Value = 'Rectangle';
            comp.ModeDropDownLabel = uilabel(comp.panelLasso);
            comp.ModeDropDownLabel.Text = 'Mode';
            comp.lassoMode = uidropdown(comp.panelLasso);
            comp.lassoMode.Items = {'Add', 'Subtract'};
            comp.lassoMode.Tag = 'lassoMode';
            comp.lassoMode.Tooltip = {'This mode defines whether the lasso area will be added to or subtracted from the current selection'};
            comp.lassoMode.Value = 'Add';
            comp.lassoCustomParameters = uipanel(comp.panelLasso);
            comp.lassoCustomParameters.Title = 'Custom parameters';
            comp.lassoManually = uicheckbox(comp.lassoCustomParameters);
            comp.lassoManually.Tag = 'lassoManually';
            comp.lassoManually.Tooltip = {'When checked, the ROI position and size are defined manually; available for rectangle and ellipse types only.'};
            comp.lassoManually.Text = 'manually';
            comp.lassoSelect = uibutton(comp.lassoCustomParameters, 'push');
            comp.lassoSelect.Tag = 'lassoSelect';
            comp.lassoSelect.Icon = 'select_16px.png';
            comp.lassoSelect.Enable = 'off';
            comp.lassoSelect.Text = 'Select';
            comp.X1EditFieldLabel_2 = uilabel(comp.lassoCustomParameters);
            comp.X1EditFieldLabel_2.Enable = 'off';
            comp.X1EditFieldLabel_2.Text = 'X1';
            comp.lassoX1 = uieditfield(comp.lassoCustomParameters, 'numeric');
            comp.lassoX1.Limits = [1 Inf];
            comp.lassoX1.RoundFractionalValues = 'on';
            comp.lassoX1.ValueDisplayFormat = '%11d';
            comp.lassoX1.Tag = 'lassoX1';
            comp.lassoX1.Enable = 'off';
            comp.lassoX1.Tooltip = {'Specify the top-left corner coordinate for rectangle, or the center coordinate for ellipse'};
            comp.lassoX1.Value = 100;
            comp.Y1Label = uilabel(comp.lassoCustomParameters);
            comp.Y1Label.Enable = 'off';
            comp.Y1Label.Text = 'Y1';
            comp.lassoY1 = uieditfield(comp.lassoCustomParameters, 'numeric');
            comp.lassoY1.Limits = [1 Inf];
            comp.lassoY1.RoundFractionalValues = 'on';
            comp.lassoY1.ValueDisplayFormat = '%11d';
            comp.lassoY1.Tag = 'lassoY1';
            comp.lassoY1.Enable = 'off';
            comp.lassoY1.Tooltip = {'Specify the top-left corner coordinate for rectangle, or the center coordinate for ellipse'};
            comp.lassoY1.Value = 100;
            comp.Width2Label = uilabel(comp.lassoCustomParameters);
            comp.Width2Label.Enable = 'off';
            comp.Width2Label.Text = 'Width/2';
            comp.lassoWidth = uieditfield(comp.lassoCustomParameters, 'numeric');
            comp.lassoWidth.Limits = [1 Inf];
            comp.lassoWidth.RoundFractionalValues = 'on';
            comp.lassoWidth.ValueDisplayFormat = '%11d';
            comp.lassoWidth.Tag = 'lassoWidth';
            comp.lassoWidth.Enable = 'off';
            comp.lassoWidth.Tooltip = {'Specify the width and height of the rectangle, or the radius of the ellipse'};
            comp.lassoWidth.Value = 50;
            comp.Width2Label_2 = uilabel(comp.lassoCustomParameters);
            comp.Width2Label_2.Enable = 'off';
            comp.Width2Label_2.Text = 'Height/2';
            comp.lassoHeight = uieditfield(comp.lassoCustomParameters, 'numeric');
            comp.lassoHeight.Limits = [1 Inf];
            comp.lassoHeight.ValueDisplayFormat = '%11d';
            comp.lassoHeight.Tag = 'lassoHeight';
            comp.lassoHeight.Enable = 'off';
            comp.lassoHeight.Tooltip = {'Specify the width and height of the rectangle, or the radius of the ellipse'};
            comp.lassoHeight.Value = 50;
            comp.Label2 = uilabel(comp.panelLasso);
            comp.Label2.Tooltip = {'Click LMB to activate the lasso tool, then press and hold LMB to drag and draw the ROI boundary. Double-click to accept and finalize the selection'};
            comp.Label2.Text = 'Click LMB to activate, then hold LMB to draw the ROI';
            comp.panelMagicwand = uipanel(comp);
            comp.panelMagicwand.Visible = 'off';
            comp.MethodDropDownLabel = uilabel(comp.panelMagicwand);
            comp.MethodDropDownLabel.Text = 'Method';
            comp.magicMethod = uidropdown(comp.panelMagicwand);
            comp.magicMethod.Items = {'Magic Wand', 'Region Grow'};
            comp.magicMethod.Tag = 'magicMethod';
            comp.magicMethod.Value = 'Magic Wand';
            comp.magicVariationLabel = uilabel(comp.panelMagicwand);
            comp.magicVariationLabel.Text = 'Variation';
            comp.magicRange1 = uieditfield(comp.panelMagicwand, 'numeric');
            comp.magicRange1.Limits = [0 Inf];
            comp.magicRange1.RoundFractionalValues = 'on';
            comp.magicRange1.ValueDisplayFormat = '%11d';
            comp.magicRange1.Tag = 'magicRange1';
            comp.magicRange1.Value = 6;
            comp.magicRange2 = uieditfield(comp.panelMagicwand, 'numeric');
            comp.magicRange2.Limits = [0 Inf];
            comp.magicRange2.RoundFractionalValues = 'on';
            comp.magicRange2.ValueDisplayFormat = '%11d';
            comp.magicRange2.Tag = 'magicRange2';
            comp.magicRange2.Value = 6;
            comp.magicLabel = uilabel(comp.panelMagicwand);
            comp.magicLabel.Tag = 'magicLabel';
            comp.magicLabel.Text = '-';
            comp.RadiusEditFieldLabel = uilabel(comp.panelMagicwand);
            comp.RadiusEditFieldLabel.Text = 'Radius';
            comp.magicRadius = uieditfield(comp.panelMagicwand, 'numeric');
            comp.magicRadius.Limits = [0 Inf];
            comp.magicRadius.RoundFractionalValues = 'on';
            comp.magicRadius.ValueDisplayFormat = '%11d';
            comp.magicRadius.Tag = 'magicRadius';
            comp.magicConnect = uibuttongroup(comp.panelMagicwand);
            comp.magicConnect.Title = 'Connect';
            comp.magicConnect.Tag = 'magicConnect';
            comp.magicConnect8 = uiradiobutton(comp.magicConnect);
            comp.magicConnect8.Text = '8';
            comp.magicConnect8.Value = true;
            comp.magicConnect4 = uiradiobutton(comp.magicConnect);
            comp.magicConnect4.Text = '4';
            comp.panelMembrane = uipanel(comp);
            comp.panelMembrane.Visible = 'off';
            comp.membraneStraightLine = uicheckbox(comp.panelMembrane);
            comp.membraneStraightLine.Tag = 'membraneStraightLine';
            comp.membraneStraightLine.Text = 'straight line';
            comp.membraneBlackSignal = uicheckbox(comp.panelMembrane);
            comp.membraneBlackSignal.Tag = 'membraneBlackSignal';
            comp.membraneBlackSignal.Text = 'black signal';
            comp.membraneBlackSignal.Value = true;
            comp.membraneRecenterView = uicheckbox(comp.panelMembrane);
            comp.membraneRecenterView.Tag = 'membraneRecenterView';
            comp.membraneRecenterView.Text = 'recenter view';
            comp.ScaleSpinnerLabel = uilabel(comp.panelMembrane);
            comp.ScaleSpinnerLabel.Text = 'Scale';
            comp.membraneScale = uispinner(comp.panelMembrane);
            comp.membraneScale.Step = 0.1;
            comp.membraneScale.Limits = [0 Inf];
            comp.membraneScale.ValueDisplayFormat = '%11.3g';
            comp.membraneScale.Tag = 'membraneScale';
            comp.membraneScale.Value = 0.2;
            comp.WidthSpinnerLabel = uilabel(comp.panelMembrane);
            comp.WidthSpinnerLabel.Text = 'Width';
            comp.membraneWidth = uispinner(comp.panelMembrane);
            comp.membraneWidth.Limits = [1 Inf];
            comp.membraneWidth.RoundFractionalValues = 'on';
            comp.membraneWidth.ValueDisplayFormat = '%11d';
            comp.membraneWidth.Tag = 'membraneWidth';
            comp.membraneWidth.Value = 4;
            comp.Label2_2 = uilabel(comp.panelMembrane);
            comp.Label2_2.Text = 'Use Shift+Click to define the starting point; each subsequent click adds a new segment. Use "Apply in 3D" to draw straight lines in 3D';
            comp.lassoObjectPicker = uipanel(comp);
            comp.lassoObjectPicker.Title = 'Recalculate stats';
            comp.lassoObjectPicker.Visible = 'off';
            comp.objectRecalculate = uibutton(comp.lassoObjectPicker, 'push');
            comp.objectRecalculate.Tag = 'objectRecalculate';
            comp.objectRecalculate.Text = 'Recalculate stats for 3D objects';
            comp.panelSAM = uipanel(comp);
            comp.panelSAM.Visible = 'off';
            comp.MethodDropDownLabel_2 = uilabel(comp.panelSAM);
            comp.MethodDropDownLabel_2.Text = 'Method';
            comp.samMethod = uidropdown(comp.panelSAM);
            comp.samMethod.Items = {'Interactive', 'Interactive 3D', 'Landmarks', 'Automatic everything'};
            comp.samMethod.Tag = 'samMethod';
            comp.samMethod.Value = 'Interactive 3D';
            comp.samDataset = uidropdown(comp.panelSAM);
            comp.samDataset.Items = {'2D, Slice', '3D, Stack', '4D, Dataset'};
            comp.samDataset.Tag = 'samDataset';
            comp.samDataset.Value = '2D, Slice';
            comp.DestinationDropDownLabel = uilabel(comp.panelSAM);
            comp.DestinationDropDownLabel.Text = 'Destination';
            comp.samDestination = uidropdown(comp.panelSAM);
            comp.samDestination.Items = {'selection', 'mask', 'labels'};
            comp.samDestination.Tag = 'samDestination';
            comp.samDestination.Value = 'selection';
            comp.ModeDropDownLabel_2 = uilabel(comp.panelSAM);
            comp.ModeDropDownLabel_2.Text = 'Mode';
            comp.samMode = uidropdown(comp.panelSAM);
            comp.samMode.Items = {'replace', 'add', 'subtract', 'add, +next material'};
            comp.samMode.Tag = 'samMode';
            comp.samMode.Value = 'replace';
            comp.samSegment = uibutton(comp.panelSAM, 'push');
            comp.samSegment.Tag = 'samSegment';
            comp.samSegment.Enable = 'off';
            comp.samSegment.Text = 'Segment';
            comp.DatasetDropDownLabel = uilabel(comp.panelSAM);
            comp.DatasetDropDownLabel.Text = 'Dataset';
            comp.samSettings = uibutton(comp.panelSAM, 'push');
            comp.samSettings.Tag = 'samSettings';
            comp.samSettings.Icon = 'settings_16px.png';
            comp.samSettings.Text = '';
            comp.samList = uibutton(comp.panelSAM, 'push');
            comp.samList.Tag = 'samList';
            comp.samList.Icon = 'list_16px.png';
            comp.samList.Text = '';
            comp.samClear = uibutton(comp.panelSAM, 'push');
            comp.samClear.Tag = 'samClear';
            comp.samClear.Icon = 'wipe_16px.png';
            comp.samClear.Text = '';
            comp.VersionDropDownLabel = uilabel(comp.panelSAM);
            comp.VersionDropDownLabel.Text = 'Version';
            comp.samVersion = uidropdown(comp.panelSAM);
            comp.samVersion.Items = {'SAM1', 'SAM2'};
            comp.samVersion.Tag = 'samVersion';
            comp.samVersion.Value = 'SAM2';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end