classdef AmiraImportGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        mainGridLayout               matlab.ui.container.GridLayout
        continueBtn                  matlab.ui.control.Button
        cancelBtn                    matlab.ui.control.Button
        importParametersPanel        matlab.ui.container.Panel
        importGridLayout             matlab.ui.container.GridLayout
        EndsliceLabel                matlab.ui.control.Label
        endSliceSpinner              matlab.ui.control.Spinner
        ZstepLabel                   matlab.ui.control.Label
        zStepSpinner                 matlab.ui.control.Spinner
        StartsliceSpinner_2Label     matlab.ui.control.Label
        binXYSpinner                 matlab.ui.control.Spinner
        resizeDropdown               matlab.ui.control.DropDown
        ResizeXYmethodDropDownLabel  matlab.ui.control.Label
        StartsliceSpinner_2Label_2   matlab.ui.control.Label
        binZSpinner                  matlab.ui.control.Spinner
        startSliceSlice              matlab.ui.control.Spinner
        StartsliceSpinnerLabel       matlab.ui.control.Label
        datasetDimensionsLabel       matlab.ui.control.Label
        secondLabel                  matlab.ui.control.Label
        firstLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        handles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Figure.Visible = false;

            app.handles.firstLabel = app.firstLabel;
            app.handles.datasetDimensionsLabel = app.datasetDimensionsLabel;
            app.handles.startSliceSlice = app.startSliceSlice;
            app.handles.zStepSpinner = app.zStepSpinner;
            app.handles.endSliceSpinner = app.endSliceSpinner;
            app.handles.binXYSpinner = app.binXYSpinner;
            app.handles.binZSpinner = app.binZSpinner;
            app.handles.resizeDropdown = app.resizeDropdown;
            app.handles.cancelBtn = app.cancelBtn;
            app.handles.continueBtn = app.continueBtn;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Amira import';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.importParametersPanel = uipanel(app.mainGridLayout);
            app.importParametersPanel.Title = 'Import parameters';
            app.importGridLayout = uigridlayout(app.importParametersPanel);
            app.firstLabel = uilabel(app.importGridLayout);
            app.firstLabel.Text = 'Define paramters for importing Amira Mesh file';
            app.secondLabel = uilabel(app.importGridLayout);
            app.secondLabel.Text = 'The selected dataset has the following dimensions (w:h:z):';
            app.datasetDimensionsLabel = uilabel(app.importGridLayout);
            app.datasetDimensionsLabel.Text = 'width x height x thickness';
            app.StartsliceSpinnerLabel = uilabel(app.importGridLayout);
            app.StartsliceSpinnerLabel.Text = 'Start slice';
            app.startSliceSlice = uispinner(app.importGridLayout);
            app.startSliceSlice.Limits = [1 Inf];
            app.startSliceSlice.RoundFractionalValues = 'on';
            app.startSliceSlice.ValueDisplayFormat = '%d';
            app.startSliceSlice.Value = 1;
            app.binZSpinner = uispinner(app.importGridLayout);
            app.binZSpinner.Limits = [1 Inf];
            app.binZSpinner.RoundFractionalValues = 'on';
            app.binZSpinner.ValueDisplayFormat = '%d';
            app.binZSpinner.Value = 1;
            app.StartsliceSpinner_2Label_2 = uilabel(app.importGridLayout);
            app.StartsliceSpinner_2Label_2.Text = 'Bin Z dimension, times';
            app.ResizeXYmethodDropDownLabel = uilabel(app.importGridLayout);
            app.ResizeXYmethodDropDownLabel.Text = 'Resize XY method';
            app.resizeDropdown = uidropdown(app.importGridLayout);
            app.resizeDropdown.Items = {'nearest', 'bilinear', 'bicubic'};
            app.resizeDropdown.Value = 'bicubic';
            app.binXYSpinner = uispinner(app.importGridLayout);
            app.binXYSpinner.Limits = [1 Inf];
            app.binXYSpinner.RoundFractionalValues = 'on';
            app.binXYSpinner.ValueDisplayFormat = '%d';
            app.binXYSpinner.Value = 2;
            app.StartsliceSpinner_2Label = uilabel(app.importGridLayout);
            app.StartsliceSpinner_2Label.Text = 'Bin XY dimension, times';
            app.zStepSpinner = uispinner(app.importGridLayout);
            app.zStepSpinner.Limits = [1 Inf];
            app.zStepSpinner.RoundFractionalValues = 'on';
            app.zStepSpinner.ValueDisplayFormat = '%d';
            app.zStepSpinner.Value = 10;
            app.ZstepLabel = uilabel(app.importGridLayout);
            app.ZstepLabel.Text = 'Z step';
            app.endSliceSpinner = uispinner(app.importGridLayout);
            app.endSliceSpinner.Limits = [1 Inf];
            app.endSliceSpinner.RoundFractionalValues = 'on';
            app.endSliceSpinner.ValueDisplayFormat = '%d';
            app.endSliceSpinner.Value = 1;
            app.EndsliceLabel = uilabel(app.importGridLayout);
            app.EndsliceLabel.Text = 'End slice';
            app.cancelBtn = uibutton(app.mainGridLayout, 'push');
            app.cancelBtn.Text = 'Cancel';
            app.continueBtn = uibutton(app.mainGridLayout, 'push');
            app.continueBtn.Text = 'Continue';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmiraImportGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end