classdef AnnotationsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                  matlab.ui.Figure
        mainGridLayout          matlab.ui.container.GridLayout
        closeBtn                matlab.ui.control.Button
        helpBtn                 matlab.ui.control.Button
        annotationTable         matlab.ui.control.Table
        ToolsPanel              matlab.ui.container.Panel
        toolsGridLayout         matlab.ui.container.GridLayout
        loadBtn                 matlab.ui.control.Button
        saveBtn                 matlab.ui.control.Button
        PrecisionSpinnerLabel   matlab.ui.control.Label
        precisionEdit           matlab.ui.control.Spinner
        SorttableDropDownLabel  matlab.ui.control.Label
        resortTablePopup        matlab.ui.control.DropDown
        refreshBtn              matlab.ui.control.Button
        deleteBtn               matlab.ui.control.Button
        jumpCheck               matlab.ui.control.CheckBox
        settingsButton          matlab.ui.control.Button
        tableContextMenu        matlab.ui.container.ContextMenu
        cmJump                  matlab.ui.container.Menu
        cmAdd                   matlab.ui.container.Menu
        cmRename                matlab.ui.container.Menu
        cmModify                matlab.ui.container.Menu
        cmCount                 matlab.ui.container.Menu
        cmClipboard             matlab.ui.container.Menu
        cmClipboardPaste        matlab.ui.container.Menu
        cmMask                  matlab.ui.container.Menu
        cmCropPatches           matlab.ui.container.Menu
        cmInterpolate           matlab.ui.container.Menu
        cmExport                matlab.ui.container.Menu
        cmImaris                matlab.ui.container.Menu
        cmOrder                 matlab.ui.container.Menu
        cmOrderTop              matlab.ui.container.Menu
        cmOrderUp               matlab.ui.container.Menu
        cmOrderDown             matlab.ui.container.Menu
        cmOrderBottom           matlab.ui.container.Menu
        cmDelete                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        winController % controller of the view
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Name = 'List of annotations';
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.mainGridLayout.Tag = 'mainGridLayout';
            app.settingsButton = uibutton(app.mainGridLayout, 'push');
            app.settingsButton.Tag = 'settingsButton';
            app.settingsButton.Icon = 'settings_16px.png';
            app.settingsButton.Tooltip = {'Define additional settings'};
            app.settingsButton.Text = 'Settings';
            app.ToolsPanel = uipanel(app.mainGridLayout);
            app.ToolsPanel.Title = 'Tools';
            app.toolsGridLayout = uigridlayout(app.ToolsPanel);
            app.jumpCheck = uicheckbox(app.toolsGridLayout);
            app.jumpCheck.Tag = 'jumpCheck';
            app.jumpCheck.Tooltip = {'automatically jump to the selected annotation'};
            app.jumpCheck.Text = 'auto jump';
            app.deleteBtn = uibutton(app.toolsGridLayout, 'push');
            app.deleteBtn.Tag = 'deleteBtn';
            app.deleteBtn.Icon = 'annotation_remove_16px.png';
            app.deleteBtn.Tooltip = {'Delete all annotations'};
            app.deleteBtn.Text = 'Delete all';
            app.refreshBtn = uibutton(app.toolsGridLayout, 'push');
            app.refreshBtn.Tag = 'refreshBtn';
            app.refreshBtn.Icon = 'replace_16px.png';
            app.refreshBtn.Tooltip = {'refresh table'};
            app.refreshBtn.Text = 'Refresh';
            app.resortTablePopup = uidropdown(app.toolsGridLayout);
            app.resortTablePopup.Items = {'Name', 'Value', 'Z', 'X', 'Y', 'T'};
            app.resortTablePopup.Tag = 'resortTablePopup';
            app.resortTablePopup.Tooltip = {'Sort list of annotations'};
            app.resortTablePopup.Value = 'Name';
            app.SorttableDropDownLabel = uilabel(app.toolsGridLayout);
            app.SorttableDropDownLabel.Text = 'Sort table';
            app.precisionEdit = uispinner(app.toolsGridLayout);
            app.precisionEdit.RoundFractionalValues = 'on';
            app.precisionEdit.ValueDisplayFormat = '%11d';
            app.precisionEdit.Tag = 'precisionEdit';
            app.precisionEdit.Tooltip = {'number with precision for the column of the Values'};
            app.precisionEdit.Value = 3;
            app.PrecisionSpinnerLabel = uilabel(app.toolsGridLayout);
            app.PrecisionSpinnerLabel.Text = 'Precision';
            app.saveBtn = uibutton(app.toolsGridLayout, 'push');
            app.saveBtn.Tag = 'saveBtn';
            app.saveBtn.Icon = 'save_16px.png';
            app.saveBtn.Tooltip = {'export annotations to a file or to the main Matlab workspace'};
            app.saveBtn.Text = 'Save';
            app.loadBtn = uibutton(app.toolsGridLayout, 'push');
            app.loadBtn.Tag = 'loadBtn';
            app.loadBtn.Icon = 'open_16px.png';
            app.loadBtn.Tooltip = {'import annotations from a file or from the main Matlab workspace'};
            app.loadBtn.Text = 'Load';
            app.annotationTable = uitable(app.mainGridLayout);
            app.annotationTable.ColumnName = {'Name'; 'Value'; 'Z'; 'X'; 'Y'; 'T'};
            app.annotationTable.RowName = {};
            app.annotationTable.Tag = 'annotationTable';
            app.helpBtn = uibutton(app.mainGridLayout, 'push');
            app.helpBtn.Tag = 'helpBtn';
            app.helpBtn.Icon = 'help_16px.png';
            app.helpBtn.Text = '';
            app.closeBtn = uibutton(app.mainGridLayout, 'push');
            app.closeBtn.Tag = 'closeBtn';
            app.closeBtn.Text = 'Close';
            app.tableContextMenu = uicontextmenu(app.Figure);
            app.cmJump = uimenu(app.tableContextMenu);
            app.cmJump.Text = 'Jump to annotation';
            app.cmJump.Tag = 'cmJump';
            app.cmAdd = uimenu(app.tableContextMenu);
            app.cmAdd.Text = 'Add annotation...';
            app.cmAdd.Tag = 'cmAdd';
            app.cmRename = uimenu(app.tableContextMenu);
            app.cmRename.Text = 'Rename selected annotations...';
            app.cmRename.Tag = 'cmRename';
            app.cmModify = uimenu(app.tableContextMenu);
            app.cmModify.Text = 'Batch modify selected annotations...';
            app.cmModify.Tag = 'cmModify';
            app.cmCount = uimenu(app.tableContextMenu);
            app.cmCount.Text = 'Count selected annotations';
            app.cmCount.Tag = 'cmCount';
            app.cmClipboard = uimenu(app.tableContextMenu);
            app.cmClipboard.Separator = 'on';
            app.cmClipboard.Text = 'Copy selected annotations to clipboard';
            app.cmClipboard.Tag = 'cmClipboard';
            app.cmClipboardPaste = uimenu(app.tableContextMenu);
            app.cmClipboardPaste.Text = 'Paste from clipboard to column';
            app.cmClipboardPaste.Tag = 'cmClipboardPaste';
            app.cmMask = uimenu(app.tableContextMenu);
            app.cmMask.Text = 'Convert selected annotations to Mask...';
            app.cmMask.Tag = 'cmMask';
            app.cmCropPatches = uimenu(app.tableContextMenu);
            app.cmCropPatches.Text = 'Crop out patches around selected annotation...';
            app.cmCropPatches.Tag = 'cmCropPatches';
            app.cmInterpolate = uimenu(app.tableContextMenu);
            app.cmInterpolate.Text = 'Interpolate between selected annotations...';
            app.cmInterpolate.Tag = 'cmInterpolate';
            app.cmExport = uimenu(app.tableContextMenu);
            app.cmExport.Separator = 'on';
            app.cmExport.Text = 'Export selected annotations...';
            app.cmExport.Tag = 'cmExport';
            app.cmImaris = uimenu(app.tableContextMenu);
            app.cmImaris.Text = 'Export selected annotations to Imaris';
            app.cmImaris.Tag = 'cmImaris';
            app.cmOrder = uimenu(app.tableContextMenu);
            app.cmOrder.Separator = 'on';
            app.cmOrder.Text = 'Order';
            app.cmOrderTop = uimenu(app.cmOrder);
            app.cmOrderTop.Text = 'Move to top  (Ctrl+Shift+Up)';
            app.cmOrderTop.Tag = 'cmOrderTop';
            app.cmOrderUp = uimenu(app.cmOrder);
            app.cmOrderUp.Text = 'Move up (Ctrl+Up)';
            app.cmOrderUp.Tag = 'cmOrderUp';
            app.cmOrderDown = uimenu(app.cmOrder);
            app.cmOrderDown.Text = 'Move down  (Ctrl+Down)';
            app.cmOrderDown.Tag = 'cmOrderDown';
            app.cmOrderBottom = uimenu(app.cmOrder);
            app.cmOrderBottom.Text = 'Move to bottom (Ctrl+Shift+Down)';
            app.cmOrderBottom.Tag = 'cmOrderBottom';
            app.cmDelete = uimenu(app.tableContextMenu);
            app.cmDelete.Separator = 'on';
            app.cmDelete.Text = 'Delete selected annotation(s)...';
            app.cmDelete.Tag = 'cmDelete';
            % Assign app.tableContextMenu
            app.annotationTable.ContextMenu = app.tableContextMenu;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnnotationsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end