classdef BoundingBoxGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                  matlab.ui.Figure
        mainGridLayout          matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        BoundingBoxTitleLabel   matlab.ui.control.Label
        PixelSizeTitleLabel     matlab.ui.control.Label
        splitPanel              matlab.ui.container.Panel
        BoundingBoxLabel        matlab.ui.control.Label
        pixSizeLabel            matlab.ui.control.Label
        CoordinatesPanel        matlab.ui.container.Panel
        CoordinatesGridLayout   matlab.ui.container.GridLayout
        Label3                  matlab.ui.control.Label
        XminEditFieldLabel      matlab.ui.control.Label
        Xmin                    matlab.ui.control.EditField
        YminEditFieldLabel      matlab.ui.control.Label
        Ymin                    matlab.ui.control.EditField
        ZminEditFieldLabel      matlab.ui.control.Label
        Zmin                    matlab.ui.control.EditField
        XcenterEditFieldLabel   matlab.ui.control.Label
        Xcenter                 matlab.ui.control.EditField
        YcenterEditFieldLabel   matlab.ui.control.Label
        Ycenter                 matlab.ui.control.EditField
        XmaxLabel               matlab.ui.control.Label
        Xmax                    matlab.ui.control.EditField
        YmaxLabel               matlab.ui.control.Label
        Ymax                    matlab.ui.control.EditField
        ZmaxLabel               matlab.ui.control.Label
        Zmax                    matlab.ui.control.EditField
        StageRotationBiasLabel  matlab.ui.control.Label
        StageRotationBias       matlab.ui.control.EditField
        importBtn               matlab.ui.control.Button
        helpButton              matlab.ui.control.Button
        applyButton             matlab.ui.control.Button
        closeButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        winController % controller of the view 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Bounding box';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.closeButton = uibutton(app.mainGridLayout, 'push');
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Text = 'Close';
            app.applyButton = uibutton(app.mainGridLayout, 'push');
            app.applyButton.Tag = 'applyButton';
            app.applyButton.Text = 'Apply';
            app.helpButton = uibutton(app.mainGridLayout, 'push');
            app.helpButton.Tag = 'helpButton';
            app.helpButton.Icon = 'help_16px.png';
            app.helpButton.Text = '';
            app.importBtn = uibutton(app.mainGridLayout, 'push');
            app.importBtn.Tag = 'importBtn';
            app.importBtn.Icon = 'import_16px.png';
            app.importBtn.Tooltip = {'automatically update pixel size and bounding box from a text in the clipboard, see Menu->Dataset Help section for details'};
            app.importBtn.Text = 'Import from Clipboard';
            app.CoordinatesPanel = uipanel(app.mainGridLayout);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.CoordinatesGridLayout = uigridlayout(app.CoordinatesPanel);
            app.StageRotationBias = uieditfield(app.CoordinatesGridLayout, 'text');
            app.StageRotationBias.Tooltip = {'bias of the stage rotation in degrees'};
            app.StageRotationBiasLabel = uilabel(app.CoordinatesGridLayout);
            app.StageRotationBiasLabel.Text = 'Stage rotation bias (only for central point)';
            app.Zmax = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Zmax.Tag = 'Zmax';
            app.Zmax.Tooltip = {'bounding box Z-max coordinate'};
            app.ZmaxLabel = uilabel(app.CoordinatesGridLayout);
            app.ZmaxLabel.Text = 'Z, max';
            app.Ymax = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Ymax.Tag = 'Ymax';
            app.Ymax.Tooltip = {'bounding box Y-max coordinate'};
            app.YmaxLabel = uilabel(app.CoordinatesGridLayout);
            app.YmaxLabel.Text = 'Y max';
            app.Xmax = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Xmax.Tag = 'Xmax';
            app.Xmax.Tooltip = {'bounding box X-max coordinate'};
            app.XmaxLabel = uilabel(app.CoordinatesGridLayout);
            app.XmaxLabel.Text = 'X max';
            app.Ycenter = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Ycenter.Tag = 'Ycenter';
            app.Ycenter.Tooltip = {'bounding box Y-center coordinate'};
            app.YcenterEditFieldLabel = uilabel(app.CoordinatesGridLayout);
            app.YcenterEditFieldLabel.Text = 'Y, center';
            app.Xcenter = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Xcenter.Tag = 'Xcenter';
            app.Xcenter.Tooltip = {'bounding box X-center coordinate'};
            app.XcenterEditFieldLabel = uilabel(app.CoordinatesGridLayout);
            app.XcenterEditFieldLabel.Text = 'X, center';
            app.Zmin = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Zmin.Tag = 'Zmin';
            app.Zmin.Tooltip = {'bounding box Z-min coordinate'};
            app.ZminEditFieldLabel = uilabel(app.CoordinatesGridLayout);
            app.ZminEditFieldLabel.Text = 'Z, min';
            app.Ymin = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Ymin.Tag = 'Ymin';
            app.Ymin.Tooltip = {'bounding box Y-min coordinate'};
            app.YminEditFieldLabel = uilabel(app.CoordinatesGridLayout);
            app.YminEditFieldLabel.Text = 'Y, min';
            app.Xmin = uieditfield(app.CoordinatesGridLayout, 'text');
            app.Xmin.Tag = 'Xmin';
            app.Xmin.Tooltip = {'bounding box X-min coordinate'};
            app.XminEditFieldLabel = uilabel(app.CoordinatesGridLayout);
            app.XminEditFieldLabel.Text = 'X, min';
            app.Label3 = uilabel(app.CoordinatesGridLayout);
            app.Label3.Text = 'To shift the bounding box it is enough to provide one set of numbers: minimal or central';
            app.pixSizeLabel = uilabel(app.mainGridLayout);
            app.pixSizeLabel.Tag = 'pixSizeText';
            app.pixSizeLabel.Text = 'Label2';
            app.BoundingBoxLabel = uilabel(app.mainGridLayout);
            app.BoundingBoxLabel.Tag = 'currentBBLabel';
            app.splitPanel = uipanel(app.mainGridLayout);
            app.PixelSizeTitleLabel = uilabel(app.mainGridLayout);
            app.PixelSizeTitleLabel.Text = 'Pixel size:';
            app.BoundingBoxTitleLabel = uilabel(app.mainGridLayout);
            app.BoundingBoxTitleLabel.Text = 'Bounding box:';
            app.Image = uiimage(app.mainGridLayout);
            app.Image.ImageSource = 'puffin_measure_5_96px.png';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundingBoxGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end