classdef CropObjectsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                      matlab.ui.Figure
        mainGridLayout              matlab.ui.container.GridLayout
        dirEdit                     matlab.ui.control.EditField
        selectDirBtn                matlab.ui.control.Button
        outputDirectoryLabel        matlab.ui.control.Label
        maskFormatPopup             matlab.ui.control.DropDown
        modelFormatPopup            matlab.ui.control.DropDown
        SingleMaskObjectPerDataset  matlab.ui.control.CheckBox
        cropMaskCheck               matlab.ui.control.CheckBox
        cropModelCheck              matlab.ui.control.CheckBox
        JittercoordinatesPanel      matlab.ui.container.Panel
        jitterSeedEditbox           matlab.ui.control.NumericEditField
        SeedEditFieldLabel          matlab.ui.control.Label
        jitterVariationEditbox      matlab.ui.control.NumericEditField
        VariationEditFieldLabel     matlab.ui.control.Label
        jitterEnableCheckbox        matlab.ui.control.CheckBox
        CropObjectsDepth            matlab.ui.control.Spinner
        DepthpxLabel                matlab.ui.control.Label
        marginZEdit                 matlab.ui.control.Spinner
        MarginZLabel                matlab.ui.control.Label
        marginXYEdit                matlab.ui.control.Spinner
        MarginXYLabel               matlab.ui.control.Label
        formatPopup                 matlab.ui.control.DropDown
        formatPopupLabel            matlab.ui.control.Label
        DcropsPanel                 matlab.ui.container.Panel
        Generate3DPatches           matlab.ui.control.CheckBox
        targetPanel                 matlab.ui.container.ButtonGroup
        matlabRadio                 matlab.ui.control.RadioButton
        fileRadio                   matlab.ui.control.RadioButton
        cropBtn                     matlab.ui.control.Button
        cancelBtn                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            app.Figure.Name = 'Crop objects';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.cancelBtn = uibutton(app.mainGridLayout, 'push');
            app.cancelBtn.Tag = 'cancelBtn';
            app.cancelBtn.Text = 'Cancel';
            app.cropBtn = uibutton(app.mainGridLayout, 'push');
            app.cropBtn.Tag = 'cropBtn';
            app.cropBtn.Text = 'Crop';
            app.targetPanel = uibuttongroup(app.mainGridLayout);
            app.targetPanel.Title = 'Target';
            app.targetPanel.Tag = 'targetPanel';
            app.fileRadio = uiradiobutton(app.targetPanel);
            app.fileRadio.Tag = 'fileRadio';
            app.fileRadio.Text = 'File';
            app.fileRadio.Value = true;
            app.matlabRadio = uiradiobutton(app.targetPanel);
            app.matlabRadio.Tag = 'matlabRadio';
            app.matlabRadio.Text = 'MATLAB';
            app.DcropsPanel = uipanel(app.mainGridLayout);
            app.DcropsPanel.Title = '3D crops';
            app.Generate3DPatches = uicheckbox(app.DcropsPanel);
            app.Generate3DPatches.Tag = 'Generate3DPatches';
            app.Generate3DPatches.Enable = 'off';
            app.Generate3DPatches.Text = 'crop 3D objects';
            app.formatPopupLabel = uilabel(app.mainGridLayout);
            app.formatPopupLabel.Text = 'Output format for images:';
            app.formatPopup = uidropdown(app.mainGridLayout);
            app.formatPopup.Items = {'Amira Mesh binary (*.am)', 'MRC format for IMOD (*.mrc)', 'NRRD Data Format (*.nrrd)', 'TIF format LZW compression (*.tif)', 'TIF format uncompressed (*.tif)'};
            app.formatPopup.Tag = 'formatPopup';
            app.formatPopup.Value = 'Amira Mesh binary (*.am)';
            app.MarginXYLabel = uilabel(app.mainGridLayout);
            app.MarginXYLabel.Text = 'Margin XY';
            app.marginXYEdit = uispinner(app.mainGridLayout);
            app.marginXYEdit.Limits = [0 Inf];
            app.marginXYEdit.RoundFractionalValues = 'on';
            app.marginXYEdit.ValueDisplayFormat = '%11d';
            app.marginXYEdit.Tag = 'marginXYEdit';
            app.MarginZLabel = uilabel(app.mainGridLayout);
            app.MarginZLabel.Text = 'Margin Z';
            app.marginZEdit = uispinner(app.mainGridLayout);
            app.marginZEdit.Limits = [0 Inf];
            app.marginZEdit.RoundFractionalValues = 'on';
            app.marginZEdit.ValueDisplayFormat = '%11d';
            app.marginZEdit.Tag = 'marginZEdit';
            app.marginZEdit.Enable = 'off';
            app.DepthpxLabel = uilabel(app.mainGridLayout);
            app.DepthpxLabel.Text = 'Depth, px';
            app.CropObjectsDepth = uispinner(app.mainGridLayout);
            app.CropObjectsDepth.Limits = [0 Inf];
            app.CropObjectsDepth.RoundFractionalValues = 'on';
            app.CropObjectsDepth.ValueDisplayFormat = '%11d';
            app.CropObjectsDepth.Tag = 'CropObjectsDepth';
            app.CropObjectsDepth.Enable = 'off';
            app.JittercoordinatesPanel = uipanel(app.mainGridLayout);
            app.JittercoordinatesPanel.Title = 'Jitter coordinates';
            app.jitterEnableCheckbox = uicheckbox(app.JittercoordinatesPanel);
            app.jitterEnableCheckbox.Tag = 'jitterEnableCheckbox';
            app.jitterEnableCheckbox.Text = 'jitter XY centroids';
            app.VariationEditFieldLabel = uilabel(app.JittercoordinatesPanel);
            app.VariationEditFieldLabel.Text = 'Variation';
            app.jitterVariationEditbox = uieditfield(app.JittercoordinatesPanel, 'numeric');
            app.jitterVariationEditbox.Limits = [0 Inf];
            app.jitterVariationEditbox.Tag = 'jitterVariationEditbox';
            app.jitterVariationEditbox.Enable = 'off';
            app.SeedEditFieldLabel = uilabel(app.JittercoordinatesPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.jitterSeedEditbox = uieditfield(app.JittercoordinatesPanel, 'numeric');
            app.jitterSeedEditbox.Tag = 'jitterSeedEditbox';
            app.jitterSeedEditbox.Enable = 'off';
            app.cropModelCheck = uicheckbox(app.mainGridLayout);
            app.cropModelCheck.Tag = 'cropModelCheck';
            app.cropModelCheck.Text = 'Crop Model';
            app.cropMaskCheck = uicheckbox(app.mainGridLayout);
            app.cropMaskCheck.Tag = 'cropMaskCheck';
            app.cropMaskCheck.Text = 'Crop Mask';
            app.SingleMaskObjectPerDataset = uicheckbox(app.mainGridLayout);
            app.SingleMaskObjectPerDataset.Tag = 'SingleMaskObjectPerDataset';
            app.SingleMaskObjectPerDataset.Text = 'Single Mask object per dataset';
            app.modelFormatPopup = uidropdown(app.mainGridLayout);
            app.modelFormatPopup.Items = {'MATLAB format (*.model)', 'Amira Mesh binary (*.am)', 'MRC format for IMOD (*.mrc)', 'NRRD Data Format (*.nrrd)', 'TIF format LZW compression (*.tif)', 'TIF format uncompressed (*.tif)'};
            app.modelFormatPopup.Tag = 'modelFormatPopup';
            app.modelFormatPopup.Enable = 'off';
            app.modelFormatPopup.Value = 'MATLAB format (*.model)';
            app.maskFormatPopup = uidropdown(app.mainGridLayout);
            app.maskFormatPopup.Items = {'MATLAB format (*.mask)', 'Amira Mesh binary (*.am)', 'MRC format for IMOD (*.mrc)', 'NRRD Data Format (*.nrrd)', 'TIF format LZW compression (*.tif)', 'TIF format uncompressed (*.tif)'};
            app.maskFormatPopup.Tag = 'maskFormatPopup';
            app.maskFormatPopup.Enable = 'off';
            app.maskFormatPopup.Value = 'MATLAB format (*.mask)';
            app.outputDirectoryLabel = uilabel(app.mainGridLayout);
            app.outputDirectoryLabel.Text = 'Output directory:';
            app.selectDirBtn = uibutton(app.mainGridLayout, 'push');
            app.selectDirBtn.Tag = 'selectDirBtn';
            app.selectDirBtn.Tooltip = {'select output directory'};
            app.selectDirBtn.Text = '...';
            app.dirEdit = uieditfield(app.mainGridLayout, 'text');
            app.dirEdit.Tag = 'dirEdit';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CropObjectsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end