classdef Lines3dDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        mainGridLayout               matlab.ui.container.GridLayout
        edgesViewAdditionalField     matlab.ui.control.DropDown
        closeBtn                     matlab.ui.control.Button
        treesViewTable               matlab.ui.control.Table
        Treesof3DlinesLabel          matlab.ui.control.Label
        activeTreeText               matlab.ui.control.Label
        activeNodeText               matlab.ui.control.Label
        TableDropDownLabel           matlab.ui.control.Label
        tableSelectionPopup          matlab.ui.control.DropDown
        FieldDropDownLabel           matlab.ui.control.Label
        nodesViewAdditionalField     matlab.ui.control.DropDown
        jumpCheck                    matlab.ui.control.CheckBox
        autoRefreshCheck             matlab.ui.control.CheckBox
        nodesViewTable               matlab.ui.control.Table
        loadBtn                      matlab.ui.control.Button
        saveBtn                      matlab.ui.control.Button
        helpBtn                      matlab.ui.control.Button
        refreshBtn                   matlab.ui.control.Button
        deleteBtn                    matlab.ui.control.Button
        visualizeBtn                 matlab.ui.control.Button
        settingsBtn                  matlab.ui.control.Button
        edgesViewTable               matlab.ui.control.Table
        treesViewTable_cm            matlab.ui.container.ContextMenu
        treesViewTable_cm_Rename     matlab.ui.container.Menu
        treesViewTable_cm_Find       matlab.ui.container.Menu
        treesViewTable_cm_Visualize  matlab.ui.container.Menu
        treesViewTable_cm_Save       matlab.ui.container.Menu
        treesViewTable_cm_Delete     matlab.ui.container.Menu
        nodesViewTable_cm            matlab.ui.container.ContextMenu
        nodesViewTable_cm_Jump       matlab.ui.container.Menu
        nodesViewTable_cm_Active     matlab.ui.container.Menu
        nodesViewTable_cm_Rename     matlab.ui.container.Menu
        nodesViewTable_cm_Pixels     matlab.ui.container.Menu
        nodesViewTable_cm_AnnotationsNew  matlab.ui.container.Menu
        nodesViewTable_cm_AnnotationsAdd  matlab.ui.container.Menu
        nodesViewTable_cm_AnnotationsDelete  matlab.ui.container.Menu
        nodesViewTable_cm_Delete     matlab.ui.container.Menu
        edgesViewTable_cm            matlab.ui.container.ContextMenu
        edgesViewTable_cm_Jump       matlab.ui.container.Menu
        edgesViewTable_cm_Active     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            app.Figure.Name = '3D lines list';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.edgesViewTable = uitable(app.mainGridLayout);
            app.edgesViewTable.ColumnName = {'Node1'; 'Node2'; 'Weight'; 'Length'};
            app.edgesViewTable.RowName = {};
            app.edgesViewTable.Tag = 'edgesViewTable';
            app.settingsBtn = uibutton(app.mainGridLayout, 'push');
            app.settingsBtn.Tag = 'settingsBtn';
            app.settingsBtn.Icon = 'settings_16px.png';
            app.settingsBtn.Tooltip = {'update visualization settings'};
            app.settingsBtn.Text = '';
            app.visualizeBtn = uibutton(app.mainGridLayout, 'push');
            app.visualizeBtn.Tag = 'visualizeBtn';
            app.visualizeBtn.Icon = 'volume_rendering_16px.png';
            app.visualizeBtn.Tooltip = {'show the graph in 3D on a separate figure'};
            app.visualizeBtn.Text = 'Visualize in 3D';
            app.deleteBtn = uibutton(app.mainGridLayout, 'push');
            app.deleteBtn.Tag = 'deleteBtn';
            app.deleteBtn.Icon = 'remove_16px.png';
            app.deleteBtn.Text = 'Delete all';
            app.refreshBtn = uibutton(app.mainGridLayout, 'push');
            app.refreshBtn.Tag = 'refreshBtn';
            app.refreshBtn.Icon = 'refresh_16px.png';
            app.refreshBtn.Tooltip = {'refresh table'};
            app.refreshBtn.Text = 'Refresh';
            app.helpBtn = uibutton(app.mainGridLayout, 'push');
            app.helpBtn.Tag = 'helpBtn';
            app.helpBtn.Icon = 'help_16px.png';
            app.helpBtn.Text = '';
            app.saveBtn = uibutton(app.mainGridLayout, 'push');
            app.saveBtn.Tag = 'saveBtn';
            app.saveBtn.Icon = 'save_16px.png';
            app.saveBtn.Tooltip = {'export annotations to a file or to the main Matlab workspace'};
            app.saveBtn.Text = 'Save';
            app.loadBtn = uibutton(app.mainGridLayout, 'push');
            app.loadBtn.Tag = 'loadBtn';
            app.loadBtn.Icon = 'open_16px.png';
            app.loadBtn.Tooltip = {'import annotations from a file or from the main Matlab workspace'};
            app.loadBtn.Text = 'Load';
            app.nodesViewTable = uitable(app.mainGridLayout);
            app.nodesViewTable.ColumnName = {'NodeName'; 'Radius'; 'Z'; 'X'; 'Y'};
            app.nodesViewTable.RowName = {};
            app.nodesViewTable.Tag = 'nodesViewTable';
            app.autoRefreshCheck = uicheckbox(app.mainGridLayout);
            app.autoRefreshCheck.Tag = 'autoRefreshCheck';
            app.autoRefreshCheck.Tooltip = {'auto update the tables, can be quite slow with many nodes'};
            app.autoRefreshCheck.Text = 'Auto refresh';
            app.autoRefreshCheck.Value = true;
            app.jumpCheck = uicheckbox(app.mainGridLayout);
            app.jumpCheck.Tag = 'jumpCheck';
            app.jumpCheck.Tooltip = {'automatically jump to the selected annotation'};
            app.jumpCheck.Text = 'Auto jump';
            app.nodesViewAdditionalField = uidropdown(app.mainGridLayout);
            app.nodesViewAdditionalField.Tag = 'nodesViewAdditionalField';
            app.FieldDropDownLabel = uilabel(app.mainGridLayout);
            app.FieldDropDownLabel.Text = 'Field';
            app.tableSelectionPopup = uidropdown(app.mainGridLayout);
            app.tableSelectionPopup.Items = {'Nodes', 'Edges'};
            app.tableSelectionPopup.Tag = 'tableSelectionPopup';
            app.tableSelectionPopup.Tooltip = {'select table to show'};
            app.tableSelectionPopup.Value = 'Nodes';
            app.TableDropDownLabel = uilabel(app.mainGridLayout);
            app.TableDropDownLabel.Text = 'Table';
            app.activeNodeText = uilabel(app.mainGridLayout);
            app.activeNodeText.Tag = 'activeNodeText';
            app.activeNodeText.Tooltip = {'index of the active node'};
            app.activeNodeText.Text = 'Active node:';
            app.activeTreeText = uilabel(app.mainGridLayout);
            app.activeTreeText.Tag = 'activeTreeText';
            app.activeTreeText.Tooltip = {'index of the currently active tree'};
            app.activeTreeText.Text = 'Active tree:';
            app.Treesof3DlinesLabel = uilabel(app.mainGridLayout);
            app.Treesof3DlinesLabel.Text = 'Trees of 3D lines';
            app.treesViewTable = uitable(app.mainGridLayout);
            app.treesViewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.treesViewTable.RowName = {};
            app.treesViewTable.Tag = 'treesViewTable';
            app.closeBtn = uibutton(app.mainGridLayout, 'push');
            app.closeBtn.Text = 'Close';
            app.edgesViewAdditionalField = uidropdown(app.mainGridLayout);
            app.edgesViewAdditionalField.Tag = 'edgesViewAdditionalField';
            app.treesViewTable_cm = uicontextmenu(app.Figure);
            app.treesViewTable_cm_Rename = uimenu(app.treesViewTable_cm);
            app.treesViewTable_cm_Rename.Text = 'Rename selected tree...';
            app.treesViewTable_cm_Find = uimenu(app.treesViewTable_cm);
            app.treesViewTable_cm_Find.Text = 'Find tree by node...';
            app.treesViewTable_cm_Visualize = uimenu(app.treesViewTable_cm);
            app.treesViewTable_cm_Visualize.Text = 'Visualize in 3D selected tree(s)';
            app.treesViewTable_cm_Save = uimenu(app.treesViewTable_cm);
            app.treesViewTable_cm_Save.Text = 'Save/export selected tree(s)';
            app.treesViewTable_cm_Delete = uimenu(app.treesViewTable_cm);
            app.treesViewTable_cm_Delete.Separator = 'on';
            app.treesViewTable_cm_Delete.Text = 'Delete selected tree(s)...';
            % Assign app.treesViewTable_cm
            app.treesViewTable.ContextMenu = app.treesViewTable_cm;
            app.nodesViewTable_cm = uicontextmenu(app.Figure);
            app.nodesViewTable_cm_Jump = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_Jump.Text = 'Jump to the node';
            app.nodesViewTable_cm_Active = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_Active.Text = 'Set as active node';
            app.nodesViewTable_cm_Rename = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_Rename.Text = 'Rename selected nodes...';
            app.nodesViewTable_cm_Pixels = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_Pixels.Text = 'Show coordinates in pixels...';
            app.nodesViewTable_cm_AnnotationsNew = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_AnnotationsNew.Separator = 'on';
            app.nodesViewTable_cm_AnnotationsNew.Text = 'New annotations from nodes';
            app.nodesViewTable_cm_AnnotationsAdd = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_AnnotationsAdd.Text = 'Add nodes to annotations';
            app.nodesViewTable_cm_AnnotationsDelete = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_AnnotationsDelete.Text = 'Delete nodes from annotations';
            app.nodesViewTable_cm_Delete = uimenu(app.nodesViewTable_cm);
            app.nodesViewTable_cm_Delete.Separator = 'on';
            app.nodesViewTable_cm_Delete.Text = 'Delete nodes...';
            % Assign app.nodesViewTable_cm
            app.nodesViewTable.ContextMenu = app.nodesViewTable_cm;
            app.edgesViewTable_cm = uicontextmenu(app.Figure);
            app.edgesViewTable_cm_Jump = uimenu(app.edgesViewTable_cm);
            app.edgesViewTable_cm_Jump.Text = 'Jump to the node';
            app.edgesViewTable_cm_Active = uimenu(app.edgesViewTable_cm);
            app.edgesViewTable_cm_Active.Text = 'Set as active node';
            % Assign app.edgesViewTable_cm
            app.edgesViewTable.ContextMenu = app.edgesViewTable_cm;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lines3dDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end