classdef MibDeepActivationsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        GridLayoutMain               matlab.ui.container.GridLayout
        OptionsPanel                 matlab.ui.container.Panel
        NetworkLayerNumber           matlab.ui.control.NumericEditField
        NextImageButton              matlab.ui.control.Button
        PreviousImageButton          matlab.ui.control.Button
        PatchSizeLabel               matlab.ui.control.Label
        NumberFilersLabel            matlab.ui.control.Label
        ImageFilename                matlab.ui.control.DropDown
        ImageLabel                   matlab.ui.control.Label
        NetworkLayerName             matlab.ui.control.DropDown
        LayerLabel                   matlab.ui.control.Label
        GridLayoutMiddle             matlab.ui.container.GridLayout
        OriginalimageLabel           matlab.ui.control.Label
        ActivationimageLabel         matlab.ui.control.Label
        ImageActivation              matlab.ui.control.Image
        ImageOriginal                matlab.ui.control.Image
        originalGridLayout           matlab.ui.container.GridLayout
        X1Label                      matlab.ui.control.Label
        x1                           matlab.ui.control.Spinner
        Y1Label                      matlab.ui.control.Label
        y1                           matlab.ui.control.Spinner
        Z1Label                      matlab.ui.control.Label
        z1                           matlab.ui.control.Spinner
        activationGridLayout         matlab.ui.container.GridLayout
        PatchZLabel                  matlab.ui.control.Label
        patchZ                       matlab.ui.control.Spinner
        FilterIdLabel                matlab.ui.control.Label
        filterId                     matlab.ui.control.Spinner
        UpdatePatch                  matlab.ui.control.Button
        CollageButton                matlab.ui.control.Button
        MinLabel                     matlab.ui.control.Label
        MinLabelValue                matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        MaxLabelValue                matlab.ui.control.Label
        WeightsLabel                 matlab.ui.control.Label
        WeightsPreviewPanel          matlab.ui.container.Panel
        weightsTrainedVarianceValue  matlab.ui.control.Label
        weightsTrainedMeanValue      matlab.ui.control.Label
        weightsScaleValue            matlab.ui.control.Label
        weightsOffsetValue           matlab.ui.control.Label
        weightsBiasValue             matlab.ui.control.Label
        weightsMaxValue              matlab.ui.control.Label
        weightsMinValue              matlab.ui.control.Label
        WeightsImage                 matlab.ui.control.Image
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Callback function
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Value changed function: ImageFilename
        function ImageFilenameValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.getNewImage();
        end

        % Value changed function: x1, y1, z1
        function ShiftImage(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.ShiftImage(event);
        end

        % Value changed function: NetworkLayerName
        function NetworkLayerNameValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            % update index of the selected layer
            layersList = app.NetworkLayerName.Items;
            selectedLayer = app.NetworkLayerName.Value;
            selectedLayerIndex = find(ismember(layersList, selectedLayer));
            app.NetworkLayerNumber.Value = selectedLayerIndex;

            app.winController.getActivations();

        end

        % Value changed function: filterId, patchZ
        function updatePatchView(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.showActivations();
        end

        % Button pushed function: UpdatePatch
        function UpdatePatchButtonPushed(app, event)
            app.winController.getActivations();
        end

        % Button pushed function: NextImageButton, PreviousImageButton
        function changeImage(app, event)
            app.winController.changeImage(event);
        end

        % Button pushed function: CollageButton
        function CollageButtonPushed(app, event)
            app.winController.makeCollage();
        end

        % Value changed function: NetworkLayerNumber
        function NetworkLayerNumberValueChanged(app, event)
%             selectedLayerIndex = app.NetworkLayerNumber.Value;
%             layersList = app.NetworkLayerName.Items;
%             app.NetworkLayerName.Value = layersList{selectedLayerIndex};
%             event.Source = app.NetworkLayerName;
%             app.NetworkLayerNameValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Activations explorer';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayoutMain = uigridlayout(app.Figure);
            app.GridLayoutMiddle = uigridlayout(app.GridLayoutMain);
            app.WeightsImage = uiimage(app.GridLayoutMiddle);
            app.WeightsPreviewPanel = uipanel(app.GridLayoutMiddle);
            app.weightsMinValue = uilabel(app.WeightsPreviewPanel);
            app.weightsMinValue.Tooltip = {'minimum weight value '};
            app.weightsMinValue.Text = 'Min: NaN';
            app.weightsMaxValue = uilabel(app.WeightsPreviewPanel);
            app.weightsMaxValue.Tooltip = {'maximum weight value '};
            app.weightsMaxValue.Text = 'Max: NaN';
            app.weightsBiasValue = uilabel(app.WeightsPreviewPanel);
            app.weightsBiasValue.Tooltip = {'weight bias value'};
            app.weightsBiasValue.Text = 'Bias: NaN';
            app.weightsOffsetValue = uilabel(app.WeightsPreviewPanel);
            app.weightsOffsetValue.Tooltip = {'weight offset value'};
            app.weightsOffsetValue.Text = 'Offset: NaN';
            app.weightsScaleValue = uilabel(app.WeightsPreviewPanel);
            app.weightsScaleValue.Tooltip = {'weight scale value'};
            app.weightsScaleValue.Text = 'Scale: NaN';
            app.weightsTrainedMeanValue = uilabel(app.WeightsPreviewPanel);
            app.weightsTrainedMeanValue.Tooltip = {'weight trained mean value'};
            app.weightsTrainedMeanValue.Text = 'Mean: NaN';
            app.weightsTrainedVarianceValue = uilabel(app.WeightsPreviewPanel);
            app.weightsTrainedVarianceValue.Tooltip = {'weight trained variance value'};
            app.weightsTrainedVarianceValue.Text = 'Var: NaN';
            app.WeightsLabel = uilabel(app.GridLayoutMiddle);
            app.WeightsLabel.Text = 'Weights';
            app.activationGridLayout = uigridlayout(app.GridLayoutMiddle);
            app.MaxLabelValue = uilabel(app.activationGridLayout);
            app.MaxLabelValue.Text = '1';
            app.MaxLabel = uilabel(app.activationGridLayout);
            app.MaxLabel.Text = 'Max:';
            app.MinLabelValue = uilabel(app.activationGridLayout);
            app.MinLabelValue.Text = '0';
            app.MinLabel = uilabel(app.activationGridLayout);
            app.MinLabel.Text = 'Min:';
            app.CollageButton = uibutton(app.activationGridLayout, 'push');
            app.CollageButton.ButtonPushedFcn = createCallbackFcn(app, @CollageButtonPushed, true);
            app.CollageButton.Icon = 'gallery_16px.png';
            app.CollageButton.Tooltip = {'Make a collage image with activations'};
            app.CollageButton.Text = 'Collage';
            app.UpdatePatch = uibutton(app.activationGridLayout, 'push');
            app.UpdatePatch.ButtonPushedFcn = createCallbackFcn(app, @UpdatePatchButtonPushed, true);
            app.UpdatePatch.Icon = 'refresh_16px.png';
            app.UpdatePatch.Text = 'Update';
            app.filterId = uispinner(app.activationGridLayout);
            app.filterId.ValueChangedFcn = createCallbackFcn(app, @updatePatchView, true);
            app.filterId.Value = 1;
            app.FilterIdLabel = uilabel(app.activationGridLayout);
            app.FilterIdLabel.Text = 'Filter Id';
            app.patchZ = uispinner(app.activationGridLayout);
            app.patchZ.ValueChangedFcn = createCallbackFcn(app, @updatePatchView, true);
            app.patchZ.Value = 1;
            app.PatchZLabel = uilabel(app.activationGridLayout);
            app.PatchZLabel.Text = 'Patch Z';
            app.originalGridLayout = uigridlayout(app.GridLayoutMiddle);
            app.z1 = uispinner(app.originalGridLayout);
            app.z1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.z1.Value = 1;
            app.Z1Label = uilabel(app.originalGridLayout);
            app.Z1Label.Text = 'Z1';
            app.y1 = uispinner(app.originalGridLayout);
            app.y1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.y1.Value = 1;
            app.Y1Label = uilabel(app.originalGridLayout);
            app.Y1Label.Text = 'Y1';
            app.x1 = uispinner(app.originalGridLayout);
            app.x1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.x1.Value = 1;
            app.X1Label = uilabel(app.originalGridLayout);
            app.X1Label.Text = 'X1';
            app.ImageOriginal = uiimage(app.GridLayoutMiddle);
            app.ImageActivation = uiimage(app.GridLayoutMiddle);
            app.ActivationimageLabel = uilabel(app.GridLayoutMiddle);
            app.ActivationimageLabel.Text = 'Activation image';
            app.OriginalimageLabel = uilabel(app.GridLayoutMiddle);
            app.OriginalimageLabel.Text = 'Original image';
            app.OptionsPanel = uipanel(app.GridLayoutMain);
            app.OptionsPanel.Title = 'Options';
            app.LayerLabel = uilabel(app.OptionsPanel);
            app.LayerLabel.Text = 'Layer:';
            app.NetworkLayerName = uidropdown(app.OptionsPanel);
            app.NetworkLayerName.ValueChangedFcn = createCallbackFcn(app, @NetworkLayerNameValueChanged, true);
            app.NetworkLayerName.Value = 'Option 4';
            app.ImageLabel = uilabel(app.OptionsPanel);
            app.ImageLabel.Text = 'Image:';
            app.ImageFilename = uidropdown(app.OptionsPanel);
            app.ImageFilename.ValueChangedFcn = createCallbackFcn(app, @ImageFilenameValueChanged, true);
            app.ImageFilename.Value = 'Option 4';
            app.NumberFilersLabel = uilabel(app.OptionsPanel);
            app.NumberFilersLabel.Text = 'Number of filers: ';
            app.PatchSizeLabel = uilabel(app.OptionsPanel);
            app.PatchSizeLabel.Text = 'Patch size: ';
            app.PreviousImageButton = uibutton(app.OptionsPanel, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @changeImage, true);
            app.PreviousImageButton.Icon = 'next_slice_16px.png';
            app.PreviousImageButton.Tooltip = {'Load previous image'};
            app.PreviousImageButton.Text = '';
            app.NextImageButton = uibutton(app.OptionsPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @changeImage, true);
            app.NextImageButton.Icon = 'prev_slice_16px.png';
            app.NextImageButton.Tooltip = {'Load next image'};
            app.NextImageButton.Text = '';
            app.NetworkLayerNumber = uieditfield(app.OptionsPanel, 'numeric');
            app.NetworkLayerNumber.Limits = [1 Inf];
            app.NetworkLayerNumber.RoundFractionalValues = 'on';
            app.NetworkLayerNumber.ValueChangedFcn = createCallbackFcn(app, @NetworkLayerNumberValueChanged, true);
            app.NetworkLayerNumber.Editable = 'off';
            app.NetworkLayerNumber.Tooltip = {'Layer number'};
            app.NetworkLayerNumber.Value = 1;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MibDeepActivationsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end