classdef MibDeepGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        BottomButtonsPanel              matlab.ui.container.Panel
        PredictButton                   matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        Mode                            matlab.ui.container.TabGroup
        Preprocess                      matlab.ui.container.Tab
        PreprocessingMode               matlab.ui.control.DropDown
        PreprocessforDropDownLabel      matlab.ui.control.Label
        DirSettingsPanel                matlab.ui.container.Panel
        PreprocessingParForWorkers      matlab.ui.control.NumericEditField
        WorkersEditFieldLabel           matlab.ui.control.Label
        UseParallelComputing            matlab.ui.control.CheckBox
        RandomGeneratorSeed             matlab.ui.control.NumericEditField
        RandomgeneratorseedLabel        matlab.ui.control.Label
        CompressProcessedModels         matlab.ui.control.CheckBox
        ValidationFraction              matlab.ui.control.NumericEditField
        FractionofimagesforvalidationEditFieldLabel  matlab.ui.control.Label
        CompressProcessedImages         matlab.ui.control.CheckBox
        LabelfilesdetailsPanel          matlab.ui.container.Panel
        MaskFilenameExtension           matlab.ui.control.DropDown
        ModelextensionDropDownLabel_2   matlab.ui.control.Label
        MaskAway                        matlab.ui.control.CheckBox
        NumberOfClassesPreprocessing    matlab.ui.control.NumericEditField
        NumberofclassesEditFieldLabel_2  matlab.ui.control.Label
        ModelFilenameExtension          matlab.ui.control.DropDown
        Labelsextension                 matlab.ui.control.Label
        SingleModelTrainingFile         matlab.ui.control.CheckBox
        DirectoriesPanel                matlab.ui.container.Panel
        ResultingImagesDir              matlab.ui.control.EditField
        SelectResultingImagesDir        matlab.ui.control.Button
        DirectorywithresultingimagesLabel  matlab.ui.control.Label
        OriginalPredictionImagesDir     matlab.ui.control.EditField
        SelectOriginalPredictionImagesDir  matlab.ui.control.Button
        BioformatsIndex                 matlab.ui.control.NumericEditField
        IndexEditField_2Label           matlab.ui.control.Label
        Bioformats                      matlab.ui.control.CheckBox
        ImageFilenameExtension          matlab.ui.control.DropDown
        extensionLabel_2                matlab.ui.control.Label
        DirectorywithimagesforpredictionLabel  matlab.ui.control.Label
        OriginalTrainingImagesDir       matlab.ui.control.EditField
        SelectOriginalTrainingImagesDir  matlab.ui.control.Button
        BioformatsTrainingIndex         matlab.ui.control.NumericEditField
        IndexEditFieldLabel             matlab.ui.control.Label
        BioformatsTraining              matlab.ui.control.CheckBox
        ImageFilenameExtensionTraining  matlab.ui.control.DropDown
        extensionLabel                  matlab.ui.control.Label
        DirectorywithimagesandlabelsfortrainingLabel  matlab.ui.control.Label
        Train                           matlab.ui.container.Tab
        TransferLearning                matlab.ui.control.Button
        BetaVersionPanel                matlab.ui.container.Panel
        T_DownsamplingFactor            matlab.ui.control.NumericEditField
        T_DownsamplingLabel             matlab.ui.control.Label
        TrainingProcessDesignPanel      matlab.ui.container.Panel
        T_findBestMinibatchSize         matlab.ui.control.Button
        T_previewValidationPatched      matlab.ui.control.Button
        T_RandomgeneratorseedLabel      matlab.ui.control.Label
        T_SendReports                   matlab.ui.control.CheckBox
        T_ExportTrainingPlots           matlab.ui.control.CheckBox
        MiniBatchSizeLabel              matlab.ui.control.Label
        T_SaveProgress                  matlab.ui.control.CheckBox
        TrainSettings                   matlab.ui.control.Button
        T_RandomGeneratorValSeed        matlab.ui.control.NumericEditField
        T_RandomGeneratorSeed           matlab.ui.control.NumericEditField
        T_MiniBatchSize                 matlab.ui.control.NumericEditField
        T_PatchesPerImage               matlab.ui.control.NumericEditField
        PatchesperimageLabel            matlab.ui.control.Label
        AugmentationDesignPanel         matlab.ui.container.Panel
        Augmentation3DSettings          matlab.ui.control.Button
        Augmentation2DSettings          matlab.ui.control.Button
        T_augmentation                  matlab.ui.control.CheckBox
        NetworkDesignPanel              matlab.ui.container.Panel
        CheckNetwork                    matlab.ui.control.Button
        EncoderdepthLabel_2             matlab.ui.control.Label
        T_StartingWeightsSettings       matlab.ui.control.Button
        T_StartingWeightsLabel          matlab.ui.control.Label
        EncoderdepthLabel               matlab.ui.control.Label
        T_SegmentationLayerSettings     matlab.ui.control.Button
        SegmentationlayerDropDownLabel  matlab.ui.control.Label
        NumberofclassesEditFieldLabel   matlab.ui.control.Label
        T_ActivationLayerSettings       matlab.ui.control.Button
        ActivationlayerLabel            matlab.ui.control.Label
        InputLayerSettings              matlab.ui.control.Button
        T_StartingWeights               matlab.ui.control.DropDown
        T_SegmentationLayer             matlab.ui.control.DropDown
        T_ActivationLayer               matlab.ui.control.DropDown
        T_FilterSize                    matlab.ui.control.NumericEditField
        PaddingLabel                    matlab.ui.control.Label
        FiltersizeLabel                 matlab.ui.control.Label
        T_NumFirstEncoderFilters        matlab.ui.control.NumericEditField
        T_NumAnisotropicBlocks          matlab.ui.control.NumericEditField
        T_EncoderDepth                  matlab.ui.control.NumericEditField
        PaddingLabel_3                  matlab.ui.control.Label
        T_NumberOfClasses               matlab.ui.control.NumericEditField
        T_ConvolutionPadding            matlab.ui.control.DropDown
        T_EncoderNetwork                matlab.ui.control.DropDown
        FiltersLabel                    matlab.ui.control.Label
        T_InputPatchSize                matlab.ui.control.EditField
        InputpatchsizeLabel             matlab.ui.control.Label
        Predict                         matlab.ui.container.Tab
        PreviewresultsPanel             matlab.ui.container.Panel
        PreviewResultsText2             matlab.ui.control.Label
        EvaluateSegmentation            matlab.ui.control.Button
        LoadPredictions                 matlab.ui.control.Button
        loadModels                      matlab.ui.control.Button
        PreviewResultsText              matlab.ui.control.Label
        loadImagesAndModels             matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        ExploreActivations              matlab.ui.control.Button
        P_ScoreExportSettings           matlab.ui.control.Button
        P_ScoreFiles                    matlab.ui.control.DropDown
        ScorefilesDropDownLabel         matlab.ui.control.Label
        P_ModelFiles                    matlab.ui.control.DropDown
        ModelfilesLabel                 matlab.ui.control.Label
        P_MiniBatchSize                 matlab.ui.control.NumericEditField
        BatchsizeforpredictionLabel     matlab.ui.control.Label
        P_ImageDownsamplingFactor       matlab.ui.control.NumericEditField
        DownsamplefactorforimagesEditFieldLabel  matlab.ui.control.Label
        P_ExtraPaddingPercentage        matlab.ui.control.NumericEditField
        PaddingLabel_2                  matlab.ui.control.Label
        InstanceSegmentationPanel       matlab.ui.container.Panel
        P_mergeInstancesTo3D            matlab.ui.control.Button
        P_OverlapInstancesSettings      matlab.ui.control.Button
        P_OverlapInstancesMode          matlab.ui.control.DropDown
        P_OverlapInstancesModeLabel     matlab.ui.control.Label
        previewDynamicMask              matlab.ui.control.Button
        P_DynamicMaskSettings           matlab.ui.control.Button
        P_DynamicMasking                matlab.ui.control.CheckBox
        P_OverlappingTilesPercentage    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        P_OverlappingTiles              matlab.ui.control.CheckBox
        PatchwisesettingsPanel          matlab.ui.container.Panel
        P_PatchWiseUpsample             matlab.ui.control.CheckBox
        P_PredictionMode                matlab.ui.control.DropDown
        PredictionengineLabel           matlab.ui.control.Label
        Options                         matlab.ui.container.Tab
        OptionsToolsPanel               matlab.ui.container.Panel
        O_balanceClassesButton          matlab.ui.control.Button
        O_countLabelsButton             matlab.ui.control.Button
        O_exportNetworkButton           matlab.ui.control.Button
        O_importNetworkButton           matlab.ui.control.Button
        ConfigfilesPanel                matlab.ui.container.Panel
        duplicateConfigButton           matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ConfigFilesLabel                matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        CustomtrainingplotPanel         matlab.ui.container.Panel
        O_CalculateAccuracyInstances    matlab.ui.control.CheckBox
        O_FractionOfPreviewPatches      matlab.ui.control.NumericEditField
        FractionofimagesforpreviewLabel  matlab.ui.control.Label
        O_PreviewImagePatches           matlab.ui.control.CheckBox
        O_NumberOfPoints                matlab.ui.control.NumericEditField
        NumberofpointsLabel             matlab.ui.control.Label
        O_RefreshRateIter               matlab.ui.control.NumericEditField
        RefreshrateiterLabel            matlab.ui.control.Label
        O_CustomTrainingProgressWindow  matlab.ui.control.CheckBox
        NetworkPanel                    matlab.ui.container.Panel
        NetworkFilename                 matlab.ui.control.EditField
        SelectNetwork                   matlab.ui.control.Button
        NetworkfilenameEditFieldLabel   matlab.ui.control.Label
        LoadAndPreviewNetwork           matlab.ui.control.Button
        Architecture                    matlab.ui.control.DropDown
        ArchitectureLabel_2             matlab.ui.control.Label
        gpuInfoBtn                      matlab.ui.control.Button
        GPUDropDown                     matlab.ui.control.DropDown
        GPULabel_2                      matlab.ui.control.Label
        Workflow                        matlab.ui.control.DropDown
        WorkflowLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        winController % controller of the view % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            app.ConfigFilesLabel.Text = sprintf('Config files are automatically\ncreated before training and\ninclude all DeepMIB settings\nbut without the trained network');
            app.PreviewResultsText.Text = sprintf('Use these buttons to preview predictions \nusing the currently active container of MIB.\nFor 3D networks only the first dataset will be shown');
            app.PreviewResultsText2.Text = sprintf('Press to evaluate results of the segmentation; requires\nground truth *.model files next to datasets for prediction');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            app.winController.helpButton_callback();
        end

        % Callback function: NetworkFilename, SelectNetwork
        function SelectNetworkButtonPushed(app, event)
            if strcmp(event.Source.Tag, 'NetworkFilename')
                app.winController.selectNetwork(event.Source.Value);   % callback from editbox change 
            else
                app.winController.selectNetwork();    
            end

        end

        % Value changed function: BioformatsIndex, 
        % ...and 36 other components
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: SelectOriginalPredictionImagesDir, 
        % ...and 2 other components
        function SelectDirerctories(app, event)
            app.winController.selectDirerctories(event);
        end

        % Value changed function: OriginalPredictionImagesDir, 
        % ...and 2 other components
        function ImageDirectoriesValueChanged(app, event)
            app.winController.updateImageDirectoryPath(event);
            
        end

        % Button pushed function: PredictButton, PreprocessButton, 
        % ...and 1 other component
        function StartButtonPushed(app, event)
            app.winController.start(event);
        end

        % Button pushed function: CheckNetwork
        function CheckNetworkButtonPushed(app, event)
            app.winController.checkNetwork();
        end

        % Button pushed function: TrainSettings
        function TrainSettingsButtonPushed(app, event)
            app.winController.setTrainingSettings();
        end

        % Selection change function: Mode
        function ModeSelectionChanged(app, event)
            selectedTab = app.Mode.SelectedTab;
            app.SelectNetwork.Enable = 'off';
            app.TrainButton.Enable = 'off';
            app.PredictButton.Enable = 'off';
            app.PreprocessButton.Enable = 'off';
            app.PreprocessButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.TrainButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.PredictButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.SelectNetwork.BackgroundColor = app.NetworkPanel.BackgroundColor;
            switch selectedTab.Tag
                case 'Preprocess'
                    app.PreprocessButton.Enable = 'on';
                    app.PreprocessButton.BackgroundColor = app.Preprocess.BackgroundColor;
                    app.SelectNetwork.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Train'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Predict'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: Augmentation2DSettings, 
        % ...and 1 other component
        function AugmentationSettingsButtonPushed(app, event)
            switch event.Source.Tag
                case 'Augmentation2DSettings'
                    app.winController.setAugmentationSettings('2D');
                case 'Augmentation3DSettings'
                    app.winController.setAugmentationSettings('3D');
            end
        end

        % Value changed function: T_augmentation
        function T_augmentationValueChanged(app, event)
            app.winController.toggleAugmentations();
        end

        % Value changed function: T_InputPatchSize
        function T_InputPatchSizeValueChanged(app, event)
            value = app.T_InputPatchSize.Value;
            value = str2num(value); %#ok<ST2NM> 
            if numel(value) ~= 4
                errordlg(sprintf('!!! Error !!!\n\nPlease provide the input patch size as 4 numbers that define\nheight, width, depth, colors\n\nFor example:\n"32, 32, 1, 3" for 2D U-net of 3 color channel images\n"64, 64, 64, 1" for 3D U-net of 1 color channel images'), ...
                    'Wrong patch size');
                return;
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: InputLayerSettings
        function InputLayerSettingsButtonPushed(app, event)
            app.winController.setInputLayerSettings();    
        end

        % Button pushed function: LoadPredictions
        function LoadPredictionsButtonPushed(app, event)
            app.winController.previewPredictions(); 
        end

        % Button pushed function: loadImagesAndModels, loadModels
        function loadImagesAndModelsButtonPushed(app, event)
            switch event.Source.Tag
                case 'loadImagesAndModels'
                    loadImagesSwitch = true;
                    %loadModelsSwitch = true;
                case 'loadModels'
                    loadImagesSwitch = false;
                    %loadModelsSwitch = true;
            end    
            app.winController.previewModels(loadImagesSwitch);
        end

        % Button pushed function: EvaluateSegmentation
        function EvaluateSegmentationButtonPushed(app, event)
            app.winController.evaluateSegmentation(); 
        end

        % Button pushed function: ExploreActivations
        function ExploreActivationsButtonPushed(app, event)
            app.winController.exploreActivations();
        end

        % Value changed function: Bioformats, BioformatsTraining
        function BioformatsValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.bioformatsCallback(event);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.winController.loadConfig();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.winController.saveConfig();
        end

        % Value changed function: GPUDropDown
        function GPUDropDownValueChanged(app, event)
            app.winController.selectGPUDevice();
        end

        % Button pushed function: gpuInfoBtn
        function gpuInfoBtnButtonPushed(app, event)
            app.winController.gpuInfo();
        end

        % Button pushed function: TransferLearning
        function TransferLearningButtonPushed(app, event)
            app.winController.transferLearning();
        end

        % Value changed function: UseParallelComputing
        function UseParallelComputingValueChanged(app, event)
            if app.UseParallelComputing.Value
                app.PreprocessingParForWorkers.Enable = 'on';
            else
                app.PreprocessingParForWorkers.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: O_exportNetworkButton
        function O_exportNetworkButtonPushed(app, event)
            app.winController.exportNetwork();
        end

        % Value changed function: SingleModelTrainingFile
        function SingleModelTrainingFileValueChanged(app, event)
            app.winController.singleModelTrainingFileValueChanged(event);
        end

        % Value changed function: Workflow
        function WorkflowValueChanged(app, event)
            app.winController.selectWorkflow(event);
        end

        % Value changed function: NumberOfClassesPreprocessing
        function NumberOfClassesPreprocessingValueChanged(app, event)
            app.T_NumberOfClasses.Value = app.NumberOfClassesPreprocessing.Value;
            event2.Source = app.T_NumberOfClasses;
            app.T_NumberOfClassesValueChanged(event2);
        end

        % Value changed function: T_NumberOfClasses
        function T_NumberOfClassesValueChanged(app, event)
            app.NumberOfClassesPreprocessing.Value = app.T_NumberOfClasses.Value;
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: T_ActivationLayer
        function T_ActivationLayerValueChanged(app, event)
            app.winController.activationLayerChangeCallback();
        end

        % Value changed function: O_CustomTrainingProgressWindow
        function O_CustomTrainingProgressWindowValueChanged(app, event)
            app.winController.customTrainingProgressWindow_Callback(event);
%             if app.O_CustomTrainingProgressWindow.Value
%                 app.O_RefreshRateIter.Enable = 'on';
%                 app.O_NumberOfPoints.Enable = 'on';
%                 app.O_PreviewImagePatches.Enable = 'on';
%                 app.O_FractionOfPreviewPatches.Enable = 'on';
%             else
%                 app.O_RefreshRateIter.Enable = 'off';
%                 app.O_NumberOfPoints.Enable = 'off';
%                 app.O_PreviewImagePatches.Enable = 'off';
%                 app.O_FractionOfPreviewPatches.Enable = 'off';
%             end
%             app.winController.updateBatchOptFromGUI(event);
%             event2.Source = app.O_PreviewImagePatches;
%             app.O_PreviewImagePatchesValueChanged(event2);
        end

        % Value changed function: O_PreviewImagePatches
        function O_PreviewImagePatchesValueChanged(app, event)
            app.winController.previewImagePatches_Callback(event);
%             if app.O_PreviewImagePatches.Value && strcmp(app.O_PreviewImagePatches.Enable, 'on')
%                 app.O_FractionOfPreviewPatches.Enable = 'on';
%             else
%                 app.O_FractionOfPreviewPatches.Enable = 'off';
%             end
%             app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: PreprocessingMode
        function PreprocessingModeValueChanged(app, event)
            app.winController.updatePreprocessingMode();
        end

        % Value changed function: T_SegmentationLayer
        function T_SegmentationLayerValueChanged(app, event)
            app.winController.setSegmentationLayer();
        end

        % Button pushed function: T_ActivationLayerSettings
        function T_ActivationLayerSettingsButtonPushed(app, event)
            app.winController.setActivationLayerOptions();
        end

        % Button pushed function: T_SegmentationLayerSettings
        function T_SegmentationLayerSettingsButtonPushed(app, event)
            app.winController.setSegmentationLayerOptions();
        end

        % Button pushed function: O_countLabelsButton
        function O_countLabelsButtonPushed(app, event)
            app.winController.countLabels()
        end

        % Button pushed function: O_importNetworkButton
        function O_importNetworkButtonPushed(app, event)
            app.winController.importNetwork()
        end

        % Button pushed function: P_DynamicMaskSettings
        function P_DynamicMaskSettingsButtonPushed(app, event)
            app.winController.updateDynamicMaskSettings()
        end

        % Value changed function: Architecture
        function ArchitectureValueChanged(app, event)
            app.winController.selectArchitecture(event);
        end

        % Button pushed function: previewDynamicMask
        function previewDynamicMaskButtonPushed(app, event)
            app.winController.previewDynamicMask();
        end

        % Value changed function: T_SaveProgress
        function T_SaveProgressValueChanged(app, event)
            app.winController.saveCheckpointNetworkCheck();
        end

        % Button pushed function: O_balanceClassesButton
        function O_balanceClassesButtonPushed(app, event)
            app.winController.balanceClasses();
        end

        % Button pushed function: duplicateConfigButton
        function duplicateConfigButtonPressed(app, event)
            app.winController.duplicateConfigAndNetwork();
        end

        % Value changed function: P_PredictionMode
        function P_PredictionModeValueChanged(app, event)
            value = app.P_PredictionMode.Value;
            switch value
                case 'Blocked-image'
                    app.P_ExtraPaddingPercentage.Enable = 'on';
                    app.P_ImageDownsamplingFactor.Enable = 'on';
                case 'Legacy'
                    app.P_ExtraPaddingPercentage.Enable = 'off';
                    app.P_ImageDownsamplingFactor.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: T_SendReports
        function T_SendReportsValueChanged(app, event)
            app.winController.sendReportsCallback();
        end

        % Button pushed function: LoadAndPreviewNetwork
        function LoadAndPreviewNetworkButtonPushed(app, event)
            fn = app.NetworkFilename.Value;
            app.winController.checkNetwork(fn);
        end

        % Callback function
        function updateEncoderNetwork(app, event)
            app.winController.updateEncoderNetwork();
        end

        % Button pushed function: P_ScoreExportSettings
        function P_ScoreExportSettingsButtonPushed(app, event)
            app.winController.updateScoreExportSettings();
        end

        % Button pushed function: P_OverlapInstancesSettings
        function P_OverlapInstancesSettingsButtonPushed(app, event)
            app.winController.updateOverlapInstancesSettings();
        end

        % Button pushed function: P_mergeInstancesTo3D
        function P_mergeInstancesTo3DPushed(app, event)
            app.winController.mergeInstancesTo3D();
        end

        % Button pushed function: T_StartingWeightsSettings
        function T_StartingWeightsSettingsButtonPushed(app, event)
            app.winController.setStartingWeightsSettings();
        end

        % Button pushed function: T_previewValidationPatched
        function T_previewValidationPatchedButtonPushed(app, event)
            app.winController.previewValidationPatches();    
        end

        % Button pushed function: T_findBestMinibatchSize
        function T_findBestMinibatchSizeButtonPushed(app, event)
            app.winController.findBestMinibatchSize();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'DeepMIB';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Figure);
            app.NetworkPanel = uipanel(app.GridLayout);
            app.NetworkPanel.Title = 'Network';
            app.WorkflowLabel = uilabel(app.NetworkPanel);
            app.WorkflowLabel.Text = 'Workflow';
            app.Workflow = uidropdown(app.NetworkPanel);
            app.Workflow.Items = {'2D Semantic', '3D Semantic', '2D Patch-wise', ''};
            app.Workflow.ValueChangedFcn = createCallbackFcn(app, @WorkflowValueChanged, true);
            app.Workflow.Value = '2D Semantic';
            app.GPULabel_2 = uilabel(app.NetworkPanel);
            app.GPULabel_2.Text = 'GPU:';
            app.GPUDropDown = uidropdown(app.NetworkPanel);
            app.GPUDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUDropDownValueChanged, true);
            app.GPUDropDown.Tooltip = {'select a GPU device to be used for training or prediction'};
            app.gpuInfoBtn = uibutton(app.NetworkPanel, 'push');
            app.gpuInfoBtn.ButtonPushedFcn = createCallbackFcn(app, @gpuInfoBtnButtonPushed, true);
            app.gpuInfoBtn.Icon = 'help_16px.png';
            app.gpuInfoBtn.Tooltip = {'GPU info: display information about the selected GPU device'};
            app.gpuInfoBtn.Text = '';
            app.ArchitectureLabel_2 = uilabel(app.NetworkPanel);
            app.ArchitectureLabel_2.Text = 'Architecture:';
            app.Architecture = uidropdown(app.NetworkPanel);
            app.Architecture.Items = {'U-net', 'SegNet', '', ''};
            app.Architecture.ValueChangedFcn = createCallbackFcn(app, @ArchitectureValueChanged, true);
            app.Architecture.Value = 'U-net';
            app.LoadAndPreviewNetwork = uibutton(app.NetworkPanel, 'push');
            app.LoadAndPreviewNetwork.ButtonPushedFcn = createCallbackFcn(app, @LoadAndPreviewNetworkButtonPushed, true);
            app.LoadAndPreviewNetwork.Icon = 'eye_16px.png';
            app.LoadAndPreviewNetwork.Tooltip = {'press to load and preview network specified in Network filename'};
            app.LoadAndPreviewNetwork.Text = '';
            app.NetworkfilenameEditFieldLabel = uilabel(app.NetworkPanel);
            app.NetworkfilenameEditFieldLabel.Text = 'Network filename:';
            app.SelectNetwork = uibutton(app.NetworkPanel, 'push');
            app.SelectNetwork.ButtonPushedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.SelectNetwork.Text = '...';
            app.NetworkFilename = uieditfield(app.NetworkPanel, 'text');
            app.NetworkFilename.ValueChangedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.NetworkFilename.Enable = 'off';
            app.Mode = uitabgroup(app.GridLayout);
            app.Mode.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectionChanged, true);
            app.Preprocess = uitab(app.Mode);
            app.Preprocess.Title = 'Directories and Preprocessing';
            app.DirectoriesPanel = uipanel(app.Preprocess);
            app.DirectoriesPanel.Title = 'Directories';
            app.DirectorywithimagesandlabelsfortrainingLabel = uilabel(app.DirectoriesPanel);
            app.DirectorywithimagesandlabelsfortrainingLabel.Text = 'Directory with images and labels for training';
            app.extensionLabel = uilabel(app.DirectoriesPanel);
            app.extensionLabel.Text = 'extension';
            app.ImageFilenameExtensionTraining = uidropdown(app.DirectoriesPanel);
            app.ImageFilenameExtensionTraining.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtensionTraining.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtensionTraining.Value = 'TIF';
            app.BioformatsTraining = uicheckbox(app.DirectoriesPanel);
            app.BioformatsTraining.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.BioformatsTraining.Text = 'Bio';
            app.IndexEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.IndexEditFieldLabel.Text = 'Index';
            app.BioformatsTrainingIndex = uieditfield(app.DirectoriesPanel, 'numeric');
            app.BioformatsTrainingIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.SelectOriginalTrainingImagesDir = uibutton(app.DirectoriesPanel, 'push');
            app.SelectOriginalTrainingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalTrainingImagesDir.Tooltip = {'Select directory with original images and models. The images and models should be placed under "Images" and "Labels" subfolders correspondingly'};
            app.SelectOriginalTrainingImagesDir.Text = '...';
            app.OriginalTrainingImagesDir = uieditfield(app.DirectoriesPanel, 'text');
            app.OriginalTrainingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.DirectorywithimagesforpredictionLabel = uilabel(app.DirectoriesPanel);
            app.DirectorywithimagesforpredictionLabel.Text = 'Directory with images for prediction';
            app.extensionLabel_2 = uilabel(app.DirectoriesPanel);
            app.extensionLabel_2.Text = 'extension';
            app.ImageFilenameExtension = uidropdown(app.DirectoriesPanel);
            app.ImageFilenameExtension.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtension.Value = 'TIF';
            app.Bioformats = uicheckbox(app.DirectoriesPanel);
            app.Bioformats.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.Bioformats.Text = 'Bio';
            app.IndexEditField_2Label = uilabel(app.DirectoriesPanel);
            app.IndexEditField_2Label.Text = 'Index';
            app.BioformatsIndex = uieditfield(app.DirectoriesPanel, 'numeric');
            app.BioformatsIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.SelectOriginalPredictionImagesDir = uibutton(app.DirectoriesPanel, 'push');
            app.SelectOriginalPredictionImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalPredictionImagesDir.Tooltip = {'Select directory with original images to be used for prediction'};
            app.SelectOriginalPredictionImagesDir.Text = '...';
            app.OriginalPredictionImagesDir = uieditfield(app.DirectoriesPanel, 'text');
            app.OriginalPredictionImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.DirectorywithresultingimagesLabel = uilabel(app.DirectoriesPanel);
            app.DirectorywithresultingimagesLabel.Text = 'Directory with resulting images';
            app.SelectResultingImagesDir = uibutton(app.DirectoriesPanel, 'push');
            app.SelectResultingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectResultingImagesDir.Tooltip = {'Select the output directory for results'};
            app.SelectResultingImagesDir.Text = '...';
            app.ResultingImagesDir = uieditfield(app.DirectoriesPanel, 'text');
            app.ResultingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.LabelfilesdetailsPanel = uipanel(app.Preprocess);
            app.LabelfilesdetailsPanel.Title = 'Label files details';
            app.SingleModelTrainingFile = uicheckbox(app.LabelfilesdetailsPanel);
            app.SingleModelTrainingFile.ValueChangedFcn = createCallbackFcn(app, @SingleModelTrainingFileValueChanged, true);
            app.SingleModelTrainingFile.Text = 'Single MIB model file';
            app.Labelsextension = uilabel(app.LabelfilesdetailsPanel);
            app.Labelsextension.Text = 'Labels extension';
            app.ModelFilenameExtension = uidropdown(app.LabelfilesdetailsPanel);
            app.ModelFilenameExtension.Items = {'MODEL', 'AM', 'PNG', 'TIF'};
            app.ModelFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ModelFilenameExtension.Value = 'MODEL';
            app.NumberofclassesEditFieldLabel_2 = uilabel(app.LabelfilesdetailsPanel);
            app.NumberofclassesEditFieldLabel_2.Text = 'Number of classes';
            app.NumberOfClassesPreprocessing = uieditfield(app.LabelfilesdetailsPanel, 'numeric');
            app.NumberOfClassesPreprocessing.Limits = [1 255];
            app.NumberOfClassesPreprocessing.RoundFractionalValues = 'on';
            app.NumberOfClassesPreprocessing.ValueChangedFcn = createCallbackFcn(app, @NumberOfClassesPreprocessingValueChanged, true);
            app.NumberOfClassesPreprocessing.Enable = 'off';
            app.NumberOfClassesPreprocessing.Tooltip = {'Number of classes including exterior'};
            app.NumberOfClassesPreprocessing.Value = 1;
            app.MaskAway = uicheckbox(app.LabelfilesdetailsPanel);
            app.MaskAway.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskAway.Text = 'Use masking';
            app.ModelextensionDropDownLabel_2 = uilabel(app.LabelfilesdetailsPanel);
            app.ModelextensionDropDownLabel_2.Text = 'Mask extension';
            app.MaskFilenameExtension = uidropdown(app.LabelfilesdetailsPanel);
            app.MaskFilenameExtension.Items = {'MASK', 'AM', 'PNG', 'TIF'};
            app.MaskFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskFilenameExtension.Value = 'MASK';
            app.DirSettingsPanel = uipanel(app.Preprocess);
            app.DirSettingsPanel.Title = 'Settings';
            app.CompressProcessedImages = uicheckbox(app.DirSettingsPanel);
            app.CompressProcessedImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedImages.Text = 'Compress processed images';
            app.FractionofimagesforvalidationEditFieldLabel = uilabel(app.DirSettingsPanel);
            app.FractionofimagesforvalidationEditFieldLabel.Text = 'Fraction of images for validation';
            app.ValidationFraction = uieditfield(app.DirSettingsPanel, 'numeric');
            app.ValidationFraction.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ValidationFraction.Value = 0.25;
            app.CompressProcessedModels = uicheckbox(app.DirSettingsPanel);
            app.CompressProcessedModels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedModels.Text = 'Compress processed labels';
            app.RandomgeneratorseedLabel = uilabel(app.DirSettingsPanel);
            app.RandomgeneratorseedLabel.Text = 'Random generator seed';
            app.RandomGeneratorSeed = uieditfield(app.DirSettingsPanel, 'numeric');
            app.RandomGeneratorSeed.Limits = [0 Inf];
            app.RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.RandomGeneratorSeed.Value = 2;
            app.UseParallelComputing = uicheckbox(app.DirSettingsPanel);
            app.UseParallelComputing.ValueChangedFcn = createCallbackFcn(app, @UseParallelComputingValueChanged, true);
            app.UseParallelComputing.Tooltip = {'enable parallel computing during image preprocessing'};
            app.UseParallelComputing.Text = 'Use parallel processing';
            app.WorkersEditFieldLabel = uilabel(app.DirSettingsPanel);
            app.WorkersEditFieldLabel.Text = 'Workers';
            app.PreprocessingParForWorkers = uieditfield(app.DirSettingsPanel, 'numeric');
            app.PreprocessingParForWorkers.Limits = [1 Inf];
            app.PreprocessingParForWorkers.Tooltip = {'define number of workers for parallel processsing'};
            app.PreprocessingParForWorkers.Value = 1;
            app.PreprocessforDropDownLabel = uilabel(app.Preprocess);
            app.PreprocessforDropDownLabel.Text = 'Preprocess for';
            app.PreprocessingMode = uidropdown(app.Preprocess);
            app.PreprocessingMode.ValueChangedFcn = createCallbackFcn(app, @PreprocessingModeValueChanged, true);
            app.Train = uitab(app.Mode);
            app.Train.Title = 'Train';
            app.NetworkDesignPanel = uipanel(app.Train);
            app.NetworkDesignPanel.Title = 'Network design';
            app.InputpatchsizeLabel = uilabel(app.NetworkDesignPanel);
            app.InputpatchsizeLabel.Text = 'Input patch size';
            app.T_InputPatchSize = uieditfield(app.NetworkDesignPanel, 'text');
            app.T_InputPatchSize.ValueChangedFcn = createCallbackFcn(app, @T_InputPatchSizeValueChanged, true);
            app.T_InputPatchSize.Value = '64 64 32';
            app.FiltersLabel = uilabel(app.NetworkDesignPanel);
            app.FiltersLabel.Text = 'Filters';
            app.T_EncoderNetwork = uidropdown(app.NetworkDesignPanel);
            app.T_EncoderNetwork.Items = {'resnet18', 'resnet50'};
            app.T_EncoderNetwork.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_EncoderNetwork.Value = 'resnet18';
            app.T_ConvolutionPadding = uidropdown(app.NetworkDesignPanel);
            app.T_ConvolutionPadding.Items = {'same', 'valid'};
            app.T_ConvolutionPadding.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ConvolutionPadding.Value = 'same';
            app.T_NumberOfClasses = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_NumberOfClasses.ValueChangedFcn = createCallbackFcn(app, @T_NumberOfClassesValueChanged, true);
            app.T_NumberOfClasses.Value = 3;
            app.PaddingLabel_3 = uilabel(app.NetworkDesignPanel);
            app.PaddingLabel_3.Text = 'Encoder network';
            app.T_EncoderDepth = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_EncoderDepth.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_EncoderDepth.Value = 3;
            app.T_NumAnisotropicBlocks = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_NumAnisotropicBlocks.Limits = [1 Inf];
            app.T_NumAnisotropicBlocks.RoundFractionalValues = 'on';
            app.T_NumAnisotropicBlocks.ValueDisplayFormat = '%11d';
            app.T_NumAnisotropicBlocks.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumAnisotropicBlocks.Enable = 'off';
            app.T_NumAnisotropicBlocks.Tooltip = {'Number of 2D convolution blocks for 3D Anisotropic architecture'};
            app.T_NumAnisotropicBlocks.Value = 1;
            app.T_NumFirstEncoderFilters = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_NumFirstEncoderFilters.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumFirstEncoderFilters.Value = 32;
            app.FiltersizeLabel = uilabel(app.NetworkDesignPanel);
            app.FiltersizeLabel.Text = 'Filter size';
            app.PaddingLabel = uilabel(app.NetworkDesignPanel);
            app.PaddingLabel.Text = 'Padding';
            app.T_FilterSize = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_FilterSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_FilterSize.Value = 3;
            app.T_ActivationLayer = uidropdown(app.NetworkDesignPanel);
            app.T_ActivationLayer.ValueChangedFcn = createCallbackFcn(app, @T_ActivationLayerValueChanged, true);
            app.T_SegmentationLayer = uidropdown(app.NetworkDesignPanel);
            app.T_SegmentationLayer.ValueChangedFcn = createCallbackFcn(app, @T_SegmentationLayerValueChanged, true);
            app.T_StartingWeights = uidropdown(app.NetworkDesignPanel);
            app.T_StartingWeights.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.InputLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.InputLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @InputLayerSettingsButtonPushed, true);
            app.InputLayerSettings.Icon = 'settings_16px.png';
            app.InputLayerSettings.Tooltip = {'Define training parameters'; ''};
            app.InputLayerSettings.Text = 'Input layer';
            app.ActivationlayerLabel = uilabel(app.NetworkDesignPanel);
            app.ActivationlayerLabel.Text = 'Activation layer';
            app.T_ActivationLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.T_ActivationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_ActivationLayerSettingsButtonPushed, true);
            app.T_ActivationLayerSettings.Icon = 'settings_16px.png';
            app.T_ActivationLayerSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_ActivationLayerSettings.Text = '';
            app.NumberofclassesEditFieldLabel = uilabel(app.NetworkDesignPanel);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.SegmentationlayerDropDownLabel = uilabel(app.NetworkDesignPanel);
            app.SegmentationlayerDropDownLabel.Text = 'Segmentation layer';
            app.T_SegmentationLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.T_SegmentationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_SegmentationLayerSettingsButtonPushed, true);
            app.T_SegmentationLayerSettings.Icon = 'settings_16px.png';
            app.T_SegmentationLayerSettings.Tooltip = {'define settings for the segmentation layer'; ''};
            app.T_SegmentationLayerSettings.Text = '';
            app.EncoderdepthLabel = uilabel(app.NetworkDesignPanel);
            app.EncoderdepthLabel.Text = 'Encoder depth';
            app.T_StartingWeightsLabel = uilabel(app.NetworkDesignPanel);
            app.T_StartingWeightsLabel.Text = 'Starting weights';
            app.T_StartingWeightsSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.T_StartingWeightsSettings.ButtonPushedFcn = createCallbackFcn(app, @T_StartingWeightsSettingsButtonPushed, true);
            app.T_StartingWeightsSettings.Icon = 'settings_16px.png';
            app.T_StartingWeightsSettings.Tooltip = {'define settings for initialization of the starting weights'};
            app.T_StartingWeightsSettings.Text = '';
            app.EncoderdepthLabel_2 = uilabel(app.NetworkDesignPanel);
            app.EncoderdepthLabel_2.Text = '2D convolution steps';
            app.CheckNetwork = uibutton(app.NetworkDesignPanel, 'push');
            app.CheckNetwork.ButtonPushedFcn = createCallbackFcn(app, @CheckNetworkButtonPushed, true);
            app.CheckNetwork.Text = 'Check network';
            app.AugmentationDesignPanel = uipanel(app.Train);
            app.AugmentationDesignPanel.Title = 'Augmentation design';
            app.T_augmentation = uicheckbox(app.AugmentationDesignPanel);
            app.T_augmentation.ValueChangedFcn = createCallbackFcn(app, @T_augmentationValueChanged, true);
            app.T_augmentation.Tooltip = {'enable data augmentation'};
            app.T_augmentation.Text = 'augmentation';
            app.Augmentation2DSettings = uibutton(app.AugmentationDesignPanel, 'push');
            app.Augmentation2DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation2DSettings.Icon = 'settings_16px.png';
            app.Augmentation2DSettings.Tooltip = {'set settings for 2D augmentation'};
            app.Augmentation2DSettings.Text = '2D / 2D Instance';
            app.Augmentation3DSettings = uibutton(app.AugmentationDesignPanel, 'push');
            app.Augmentation3DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation3DSettings.Icon = 'settings_16px.png';
            app.Augmentation3DSettings.Tooltip = {'set settings for 2.5D and 3D augmentations'; ''};
            app.Augmentation3DSettings.Text = '2.5D / 3D';
            app.TrainingProcessDesignPanel = uipanel(app.Train);
            app.TrainingProcessDesignPanel.Title = 'Training process design';
            app.PatchesperimageLabel = uilabel(app.TrainingProcessDesignPanel);
            app.PatchesperimageLabel.Text = 'Patches per image';
            app.T_PatchesPerImage = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_PatchesPerImage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_PatchesPerImage.Value = 32;
            app.T_MiniBatchSize = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_MiniBatchSize.Tooltip = {'Random seed generator for consistent training, use 0 for random initialization'};
            app.T_MiniBatchSize.Value = 4;
            app.T_RandomGeneratorSeed = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_RandomGeneratorSeed.Limits = [0 Inf];
            app.T_RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.T_RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_RandomGeneratorSeed.Value = 2;
            app.T_RandomGeneratorValSeed = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_RandomGeneratorValSeed.Limits = [0 Inf];
            app.T_RandomGeneratorValSeed.RoundFractionalValues = 'on';
            app.T_RandomGeneratorValSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_RandomGeneratorValSeed.Value = 1;
            app.TrainSettings = uibutton(app.TrainingProcessDesignPanel, 'push');
            app.TrainSettings.ButtonPushedFcn = createCallbackFcn(app, @TrainSettingsButtonPushed, true);
            app.TrainSettings.Icon = 'settings_16px.png';
            app.TrainSettings.Tooltip = {'Define training parameters'; ''};
            app.TrainSettings.Text = 'Training';
            app.T_SaveProgress = uicheckbox(app.TrainingProcessDesignPanel);
            app.T_SaveProgress.ValueChangedFcn = createCallbackFcn(app, @T_SaveProgressValueChanged, true);
            app.T_SaveProgress.Text = 'Save checkpoint networks';
            app.MiniBatchSizeLabel = uilabel(app.TrainingProcessDesignPanel);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.T_ExportTrainingPlots = uicheckbox(app.TrainingProcessDesignPanel);
            app.T_ExportTrainingPlots.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ExportTrainingPlots.Text = 'Export training plots';
            app.T_SendReports = uicheckbox(app.TrainingProcessDesignPanel);
            app.T_SendReports.ValueChangedFcn = createCallbackFcn(app, @T_SendReportsValueChanged, true);
            app.T_SendReports.Tooltip = {'Send reports about progress of the training process to email'};
            app.T_SendReports.Text = 'Send reports to email';
            app.T_RandomgeneratorseedLabel = uilabel(app.TrainingProcessDesignPanel);
            app.T_RandomgeneratorseedLabel.Text = 'Random seed';
            app.T_previewValidationPatched = uibutton(app.TrainingProcessDesignPanel, 'push');
            app.T_previewValidationPatched.ButtonPushedFcn = createCallbackFcn(app, @T_previewValidationPatchedButtonPushed, true);
            app.T_previewValidationPatched.Icon = 'eye_16px.png';
            app.T_previewValidationPatched.Tooltip = {'preview patches used for validation'};
            app.T_previewValidationPatched.Text = '';
            app.T_findBestMinibatchSize = uibutton(app.TrainingProcessDesignPanel, 'push');
            app.T_findBestMinibatchSize.ButtonPushedFcn = createCallbackFcn(app, @T_findBestMinibatchSizeButtonPushed, true);
            app.T_findBestMinibatchSize.Icon = 'stopwatch_16px.png';
            app.T_findBestMinibatchSize.Tooltip = {'Identify mini batch size and time its performance using a small test run'};
            app.T_findBestMinibatchSize.Text = '';
            app.BetaVersionPanel = uipanel(app.Train);
            app.BetaVersionPanel.Title = 'Beta version';
            app.T_DownsamplingLabel = uilabel(app.BetaVersionPanel);
            app.T_DownsamplingLabel.Text = 'Downsampling factor';
            app.T_DownsamplingFactor = uieditfield(app.BetaVersionPanel, 'numeric');
            app.T_DownsamplingFactor.Limits = [2 Inf];
            app.T_DownsamplingFactor.RoundFractionalValues = 'on';
            app.T_DownsamplingFactor.Tooltip = {'Downsampling factor for 2D/3D U-net and SegNet'};
            app.T_DownsamplingFactor.Value = 2;
            app.TransferLearning = uibutton(app.Train, 'push');
            app.TransferLearning.ButtonPushedFcn = createCallbackFcn(app, @TransferLearningButtonPushed, true);
            app.TransferLearning.Tooltip = {'Modify the loaded network for a different number of classes'};
            app.TransferLearning.Text = 'Transfer learning';
            app.Predict = uitab(app.Mode);
            app.Predict.Title = 'Predict';
            app.SettingsPanel = uipanel(app.Predict);
            app.SettingsPanel.Title = 'Settings';
            app.PredictionengineLabel = uilabel(app.SettingsPanel);
            app.PredictionengineLabel.Text = 'Prediction engine';
            app.P_PredictionMode = uidropdown(app.SettingsPanel);
            app.P_PredictionMode.Items = {'Blocked-image', 'Legacy'};
            app.P_PredictionMode.ValueChangedFcn = createCallbackFcn(app, @P_PredictionModeValueChanged, true);
            app.P_PredictionMode.Value = 'Blocked-image';
            app.PatchwisesettingsPanel = uipanel(app.SettingsPanel);
            app.PatchwisesettingsPanel.Title = 'Patch-wise settings';
            app.P_PatchWiseUpsample = uicheckbox(app.PatchwisesettingsPanel);
            app.P_PatchWiseUpsample.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_PatchWiseUpsample.Text = ' upsample prediction';
            app.P_OverlappingTiles = uicheckbox(app.SettingsPanel);
            app.P_OverlappingTiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTiles.Text = 'Overlapping tiles';
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '%%';
            app.P_OverlappingTilesPercentage = uieditfield(app.SettingsPanel, 'numeric');
            app.P_OverlappingTilesPercentage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTilesPercentage.Value = 5;
            app.P_DynamicMasking = uicheckbox(app.SettingsPanel);
            app.P_DynamicMasking.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_DynamicMasking.Text = 'Dynamic masking';
            app.P_DynamicMaskSettings = uibutton(app.SettingsPanel, 'push');
            app.P_DynamicMaskSettings.ButtonPushedFcn = createCallbackFcn(app, @P_DynamicMaskSettingsButtonPushed, true);
            app.P_DynamicMaskSettings.Icon = 'settings_16px.png';
            app.P_DynamicMaskSettings.Tooltip = {'press to set settings for dynamic masking'};
            app.P_DynamicMaskSettings.Text = '';
            app.previewDynamicMask = uibutton(app.SettingsPanel, 'push');
            app.previewDynamicMask.ButtonPushedFcn = createCallbackFcn(app, @previewDynamicMaskButtonPushed, true);
            app.previewDynamicMask.Icon = 'eye_16px.png';
            app.previewDynamicMask.Tooltip = {'Press to preview blocks generated using dynamic masking. The precessed image is taken from the MIB main window'};
            app.previewDynamicMask.Text = '';
            app.InstanceSegmentationPanel = uipanel(app.SettingsPanel);
            app.InstanceSegmentationPanel.Title = 'Instance segmentation';
            app.P_OverlapInstancesModeLabel = uilabel(app.InstanceSegmentationPanel);
            app.P_OverlapInstancesModeLabel.Text = 'Overlap mode';
            app.P_OverlapInstancesMode = uidropdown(app.InstanceSegmentationPanel);
            app.P_OverlapInstancesMode.Items = {'IoU merge', 'Centroid in core'};
            app.P_OverlapInstancesMode.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlapInstancesMode.Value = 'IoU merge';
            app.P_OverlapInstancesSettings = uibutton(app.InstanceSegmentationPanel, 'push');
            app.P_OverlapInstancesSettings.ButtonPushedFcn = createCallbackFcn(app, @P_OverlapInstancesSettingsButtonPushed, true);
            app.P_OverlapInstancesSettings.Icon = 'settings_16px.png';
            app.P_OverlapInstancesSettings.Tooltip = {'Instance stitchng settings'};
            app.P_OverlapInstancesSettings.Text = '';
            app.P_mergeInstancesTo3D = uibutton(app.InstanceSegmentationPanel, 'push');
            app.P_mergeInstancesTo3D.ButtonPushedFcn = createCallbackFcn(app, @P_mergeInstancesTo3DPushed, true);
            app.P_mergeInstancesTo3D.Tooltip = {'Combine 2D instances into 3D objects'};
            app.P_mergeInstancesTo3D.Text = 'Merge 2D to 3D';
            app.PaddingLabel_2 = uilabel(app.SettingsPanel);
            app.PaddingLabel_2.Text = 'Padding, %%';
            app.P_ExtraPaddingPercentage = uieditfield(app.SettingsPanel, 'numeric');
            app.P_ExtraPaddingPercentage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DownsamplefactorforimagesEditFieldLabel = uilabel(app.SettingsPanel);
            app.DownsamplefactorforimagesEditFieldLabel.Text = 'Downsample factor for images';
            app.P_ImageDownsamplingFactor = uieditfield(app.SettingsPanel, 'numeric');
            app.P_ImageDownsamplingFactor.Limits = [1 Inf];
            app.P_ImageDownsamplingFactor.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_ImageDownsamplingFactor.Value = 1;
            app.BatchsizeforpredictionLabel = uilabel(app.SettingsPanel);
            app.BatchsizeforpredictionLabel.Text = 'Batch size for prediction';
            app.P_MiniBatchSize = uieditfield(app.SettingsPanel, 'numeric');
            app.P_MiniBatchSize.Limits = [1 Inf];
            app.P_MiniBatchSize.RoundFractionalValues = 'on';
            app.P_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_MiniBatchSize.Value = 1;
            app.ModelfilesLabel = uilabel(app.SettingsPanel);
            app.ModelfilesLabel.Text = 'Model files';
            app.P_ModelFiles = uidropdown(app.SettingsPanel);
            app.P_ModelFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ScorefilesDropDownLabel = uilabel(app.SettingsPanel);
            app.ScorefilesDropDownLabel.Text = 'Score files';
            app.P_ScoreFiles = uidropdown(app.SettingsPanel);
            app.P_ScoreFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_ScoreExportSettings = uibutton(app.SettingsPanel, 'push');
            app.P_ScoreExportSettings.ButtonPushedFcn = createCallbackFcn(app, @P_ScoreExportSettingsButtonPushed, true);
            app.P_ScoreExportSettings.Icon = 'settings_16px.png';
            app.P_ScoreExportSettings.Tooltip = {'press to set settings for dynamic masking'};
            app.P_ScoreExportSettings.Text = '';
            app.ExploreActivations = uibutton(app.SettingsPanel, 'push');
            app.ExploreActivations.ButtonPushedFcn = createCallbackFcn(app, @ExploreActivationsButtonPushed, true);
            app.ExploreActivations.Text = 'Explore activations';
            app.PreviewresultsPanel = uipanel(app.Predict);
            app.PreviewresultsPanel.Title = 'Preview results';
            app.loadImagesAndModels = uibutton(app.PreviewresultsPanel, 'push');
            app.loadImagesAndModels.ButtonPushedFcn = createCallbackFcn(app, @loadImagesAndModelsButtonPushed, true);
            app.loadImagesAndModels.Text = 'Load images and models';
            app.PreviewResultsText = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText.Text = 'Use buttons below to preview predictions.';
            app.loadModels = uibutton(app.PreviewresultsPanel, 'push');
            app.loadModels.ButtonPushedFcn = createCallbackFcn(app, @loadImagesAndModelsButtonPushed, true);
            app.loadModels.Tooltip = {'If images are already loaded, load only the generated models'};
            app.loadModels.Text = 'Load models';
            app.LoadPredictions = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadPredictions.ButtonPushedFcn = createCallbackFcn(app, @LoadPredictionsButtonPushed, true);
            app.LoadPredictions.Text = 'Load prediction scores';
            app.EvaluateSegmentation = uibutton(app.PreviewresultsPanel, 'push');
            app.EvaluateSegmentation.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSegmentationButtonPushed, true);
            app.EvaluateSegmentation.Text = 'Evaluate segmentation';
            app.PreviewResultsText2 = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText2.Text = 'Evaluate segmentation';
            app.Options = uitab(app.Mode);
            app.Options.Title = 'Options';
            app.CustomtrainingplotPanel = uipanel(app.Options);
            app.CustomtrainingplotPanel.Title = 'Custom training plot';
            app.O_CustomTrainingProgressWindow = uicheckbox(app.CustomtrainingplotPanel);
            app.O_CustomTrainingProgressWindow.ValueChangedFcn = createCallbackFcn(app, @O_CustomTrainingProgressWindowValueChanged, true);
            app.O_CustomTrainingProgressWindow.Text = 'Custom training progress window';
            app.RefreshrateiterLabel = uilabel(app.CustomtrainingplotPanel);
            app.RefreshrateiterLabel.Text = 'Refresh rate (iter)';
            app.O_RefreshRateIter = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_RefreshRateIter.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_RefreshRateIter.Value = 5;
            app.NumberofpointsLabel = uilabel(app.CustomtrainingplotPanel);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.O_NumberOfPoints = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_NumberOfPoints.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_NumberOfPoints.Value = 1000;
            app.O_PreviewImagePatches = uicheckbox(app.CustomtrainingplotPanel);
            app.O_PreviewImagePatches.ValueChangedFcn = createCallbackFcn(app, @O_PreviewImagePatchesValueChanged, true);
            app.O_PreviewImagePatches.Text = 'Preview image patches';
            app.FractionofimagesforpreviewLabel = uilabel(app.CustomtrainingplotPanel);
            app.FractionofimagesforpreviewLabel.Text = 'Fraction of images for preview';
            app.O_FractionOfPreviewPatches = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_FractionOfPreviewPatches.ValueDisplayFormat = '%11.5g';
            app.O_FractionOfPreviewPatches.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_FractionOfPreviewPatches.Value = 0.05;
            app.O_CalculateAccuracyInstances = uicheckbox(app.CustomtrainingplotPanel);
            app.O_CalculateAccuracyInstances.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_CalculateAccuracyInstances.Text = 'Calculate accuracy for instance 2D';
            app.ConfigfilesPanel = uipanel(app.Options);
            app.ConfigfilesPanel.Title = 'Config files';
            app.LoadButton = uibutton(app.ConfigfilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'open_16px.png';
            app.LoadButton.Text = 'Load';
            app.ConfigFilesLabel = uilabel(app.ConfigfilesPanel);
            app.SaveButton = uibutton(app.ConfigfilesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save_16px.png';
            app.SaveButton.Text = 'Save';
            app.duplicateConfigButton = uibutton(app.ConfigfilesPanel, 'push');
            app.duplicateConfigButton.ButtonPushedFcn = createCallbackFcn(app, @duplicateConfigButtonPressed, true);
            app.duplicateConfigButton.Icon = 'duplicate_16px.png';
            app.duplicateConfigButton.Tooltip = {'Copy the selected network file and its config to a new name'};
            app.duplicateConfigButton.Text = 'Duplicate';
            app.OptionsToolsPanel = uipanel(app.Options);
            app.OptionsToolsPanel.Title = 'Tools';
            app.O_importNetworkButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_importNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @O_importNetworkButtonPushed, true);
            app.O_importNetworkButton.Icon = 'import_16px.png';
            app.O_importNetworkButton.Tooltip = {'import external network to be used with DeepMIB'};
            app.O_importNetworkButton.Text = 'Import network';
            app.O_exportNetworkButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_exportNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @O_exportNetworkButtonPushed, true);
            app.O_exportNetworkButton.Icon = 'export_16px.png';
            app.O_exportNetworkButton.Tooltip = {'Export network to the ONNX or TensorFlow Formats'};
            app.O_exportNetworkButton.Text = 'Export network';
            app.O_countLabelsButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_countLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @O_countLabelsButtonPushed, true);
            app.O_countLabelsButton.Tooltip = {'Count labels in model files'};
            app.O_countLabelsButton.Text = 'Count labels';
            app.O_balanceClassesButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_balanceClassesButton.ButtonPushedFcn = createCallbackFcn(app, @O_balanceClassesButtonPushed, true);
            app.O_balanceClassesButton.Tooltip = {'Count labels in model files'};
            app.O_balanceClassesButton.Text = 'Balance classes';
            app.BottomButtonsPanel = uipanel(app.GridLayout);
            app.Help = uibutton(app.BottomButtonsPanel, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.PreprocessButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.TrainButton = uibutton(app.BottomButtonsPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.PredictButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MibDeepGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end