classdef PreferencesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        ExternalDirectoriesPanel        matlab.ui.container.Panel
        JavaConfigureBtn                matlab.ui.control.Button
        JavaFindBtn                     matlab.ui.control.Button
        PythonExecutionMode             matlab.ui.control.DropDown
        PythonExecutionModeLabel        matlab.ui.control.Label
        PythonInstallationPath          matlab.ui.control.EditField
        PythoninstallationpathLabel     matlab.ui.control.Label
        PythonDirSelectBtn              matlab.ui.control.Button
        OmeroInstallationPath           matlab.ui.control.EditField
        OMEROinstallationdirectoryLabel  matlab.ui.control.Label
        OmeroDirSelectBtn               matlab.ui.control.Button
        ImarisInstallationPath          matlab.ui.control.EditField
        ImarisinstallationdirectoryLabel  matlab.ui.control.Label
        ImarisDirSelectBtn              matlab.ui.control.Button
        JavaInstallationPath            matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        JavaDirSelectBtn                matlab.ui.control.Button
        FijiInstallationPath            matlab.ui.control.EditField
        FijiinstallationdirectoryLabel  matlab.ui.control.Label
        FijiDirSelectBtn                matlab.ui.control.Button
        DeepMIBDir                      matlab.ui.control.EditField
        DirectorytostorenetworkarchitecturesforDeepMIBLabel  matlab.ui.control.Label
        DeepMIBDirSelectBtn             matlab.ui.control.Button
        bm4dInstallationPath            matlab.ui.control.EditField
        BM4DinstallationdirectoryLabel  matlab.ui.control.Label
        BM4DDirSelectBtn                matlab.ui.control.Button
        bm3dInstallationPath            matlab.ui.control.EditField
        BM3DinstallationdirectoryLabel  matlab.ui.control.Label
        BM3DDirSelectBtn                matlab.ui.control.Button
        BioFormatsMemoizerMemoDir       matlab.ui.control.EditField
        BioformatsMemoizertemporarydirectoryLabel  matlab.ui.control.Label
        MemoizerDirSelectBtn            matlab.ui.control.Button
        LocationsofexternalpackagesthatcanbeusedwithMIBLabel_2  matlab.ui.control.Label
        LocationsofexternalpackagesthatcanbeusedwithMIBLabel  matlab.ui.control.Label
        InputOutputPanel                matlab.ui.container.Panel
        BioFormatsPanel                 matlab.ui.container.Panel
        BioFormatsLabel                 matlab.ui.control.Label
        BioFormatsLibrary               matlab.ui.control.DropDown
        ZarrlibraryLabel_2              matlab.ui.control.Label
        ZarrLibraryPanel                matlab.ui.container.Panel
        ChunkCacheMB                    matlab.ui.control.NumericEditField
        ChunkCacheMBLabel               matlab.ui.control.Label
        ZarrSmoothing                   matlab.ui.control.CheckBox
        ZarrLibraryLabel                matlab.ui.control.Label
        ZarrLibrary                     matlab.ui.control.DropDown
        ZarrlibraryLabel                matlab.ui.control.Label
        KeyboardShortcutsPanel          matlab.ui.container.Panel
        ResetKeyShortcutsButton         matlab.ui.control.Button
        PressedKeyValue                 matlab.ui.control.Label
        PressedkeyLabel                 matlab.ui.control.Label
        shortcutsTable                  matlab.ui.control.Table
        DefaultButton                   matlab.ui.control.Button
        UserInterfacePanel              matlab.ui.container.Panel
        ParallelprocessingPanel         matlab.ui.container.Panel
        CpuParallelLimit                matlab.ui.control.NumericEditField
        cpuParallelLimitLabel           matlab.ui.control.Label
        UpdateCheckPanel                matlab.ui.container.Panel
        RecheckPeriod                   matlab.ui.control.NumericEditField
        PeriodforupdaterecheckdaysLabel  matlab.ui.control.Label
        FontPanel                       matlab.ui.container.Panel
        FontSizeEditField               matlab.ui.control.NumericEditField
        FontSizeDireContentsEditField   matlab.ui.control.NumericEditField
        SelectFontButton                matlab.ui.control.Button
        CurrentFontLabel                matlab.ui.control.Label
        FontsizefordirectorycontentsEditFieldLabel  matlab.ui.control.Label
        FontsizeLabel                   matlab.ui.control.Label
        GUItweaksPanel                  matlab.ui.container.Panel
        AltWithScrollWheel              matlab.ui.control.DropDown
        AltWithScrollWheelLabel         matlab.ui.control.Label
        RenderingEngine                 matlab.ui.control.DropDown
        LeftmouseactionLabel_2          matlab.ui.control.Label
        RecentDirsNumber                matlab.ui.control.NumericEditField
        NumberofrecentdirsEditFieldLabel  matlab.ui.control.Label
        MouseWheelActionDropDown        matlab.ui.control.DropDown
        LeftMouseActionDropDown         matlab.ui.control.DropDown
        ImageResizeMethodDropDown       matlab.ui.control.DropDown
        EnableSelectionDropDown         matlab.ui.control.DropDown
        EnableselectionDropDownLabel    matlab.ui.control.Label
        ImageresizemethodDropDownLabel  matlab.ui.control.Label
        LeftmouseactionLabel            matlab.ui.control.Label
        MousewheelactionDropDownLabel   matlab.ui.control.Label
        GUIscalingPanel                 matlab.ui.container.Panel
        SystemScalingEditField          matlab.ui.control.NumericEditField
        mibScalingFactorEditField       matlab.ui.control.NumericEditField
        uipanel                         matlab.ui.control.CheckBox
        uitab                           matlab.ui.control.CheckBox
        uitable                         matlab.ui.control.CheckBox
        uibuttongroup                   matlab.ui.control.CheckBox
        uitabgroup                      matlab.ui.control.CheckBox
        axes                            matlab.ui.control.CheckBox
        uicontrol                       matlab.ui.control.CheckBox
        ScalethefollowingelementsofGUILabel  matlab.ui.control.Label
        OSscalingfactorEditFieldLabel   matlab.ui.control.Label
        MIBscalingfactorEditFieldLabel  matlab.ui.control.Label
        RescaleGUIButton                matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        CategoriesTree                  matlab.ui.container.Tree
        UserInterfaceNode               matlab.ui.container.TreeNode
        ColorsNode                      matlab.ui.container.TreeNode
        BackupAndUndoNode               matlab.ui.container.TreeNode
        ExternalDirectoriesNode         matlab.ui.container.TreeNode
        KeyboardShortcutsNode           matlab.ui.container.TreeNode
        SegmentationToolsNode           matlab.ui.container.TreeNode
        InputOutputNode                 matlab.ui.container.TreeNode
        SegmentationToolsPanel          matlab.ui.container.Panel
        FavoriteToolsPanel              matlab.ui.container.Panel
        FavoriteToolB                   matlab.ui.control.DropDown
        FavoritetoolBDropDownLabel      matlab.ui.control.Label
        FavoriteToolA                   matlab.ui.control.DropDown
        FavoritetoolADropDownLabel      matlab.ui.control.Label
        AnntationsPanel                 matlab.ui.container.Panel
        AnnotationsColorButton2         matlab.ui.control.Button
        annotationShownExtraDepth       matlab.ui.control.Spinner
        ExtradepthtoshowSpinnerLabel    matlab.ui.control.Label
        annotationFontSize              matlab.ui.control.DropDown
        FontsizeDropDownLabel           matlab.ui.control.Label
        InterpolationPanel              matlab.ui.container.Panel
        InterpolationType               matlab.ui.control.DropDown
        InterpolationNumberOfPoints     matlab.ui.control.NumericEditField
        InterpolationLineWidth          matlab.ui.control.NumericEditField
        LinewidthpxEditFieldLabel       matlab.ui.control.Label
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        TypeDropDownLabel               matlab.ui.control.Label
        BackupAndUndoPanel              matlab.ui.container.Panel
        max3dUndoHistoryLabel           matlab.ui.control.Label
        EnableUndo                      matlab.ui.control.CheckBox
        maxUndoHistoryLabel             matlab.ui.control.Label
        maxUndoHistory                  matlab.ui.control.NumericEditField
        Numberof3DhistorystepstostoreEditFieldLabel  matlab.ui.control.Label
        NumberofhistorystepstostoreEditFieldLabel  matlab.ui.control.Label
        max3dUndoHistory                matlab.ui.control.NumericEditField
        ColorsPanel                     matlab.ui.container.Panel
        cursorMaterialColor             matlab.ui.control.CheckBox
        LabelsPanel                     matlab.ui.container.Panel
        LabelsShowAsContours            matlab.ui.control.CheckBox
        MasksPanel                      matlab.ui.container.Panel
        MaskShowAsContours              matlab.ui.control.CheckBox
        ContoursPanel                   matlab.ui.container.Panel
        ContourThicknessMasksMethod     matlab.ui.control.DropDown
        ModeLabel                       matlab.ui.control.Label
        ContourThicknessRendering       matlab.ui.control.DropDown
        RenderingLabel                  matlab.ui.control.Label
        ContourThicknessMasks           matlab.ui.control.NumericEditField
        MaskthicknessLabel              matlab.ui.control.Label
        ContourThicknessModels          matlab.ui.control.NumericEditField
        ModelthicknessLabel             matlab.ui.control.Label
        ScaleToOneCheckBox              matlab.ui.control.CheckBox
        AnnotationsColorButton          matlab.ui.control.Button
        MaskColorButton                 matlab.ui.control.Button
        SelectionColorButton            matlab.ui.control.Button
        LUTColorsTable                  matlab.ui.control.Table
        LUTcolorsforcolorchannelsLabel  matlab.ui.control.Label
        ColorsofmaterialsLabel          matlab.ui.control.Label
        ModelsColorsTable               matlab.ui.control.Table
        NumberOfColorsDropDown          matlab.ui.control.DropDown
        NumberofcolorsDropDownLabel     matlab.ui.control.Label
        PaletteGeneratorDropDown        matlab.ui.control.DropDown
        PalettegeneratorDropDownLabel   matlab.ui.control.Label
        ModelsColorsTableContextMenu    matlab.ui.container.ContextMenu
        ReverseColormapMenu             matlab.ui.container.Menu
        InsertColorMenu                 matlab.ui.container.Menu
        ReplaceWithRandomColorMenu      matlab.ui.container.Menu
        SwapTwoColorsMenu               matlab.ui.container.Menu
        DeleteColorsMenu                matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ImportFromMatlabMenu            matlab.ui.container.Menu
        LoadFromFileMenu                matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportToMatlabMenu              matlab.ui.container.Menu
        SaveToFileMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        winController % controller of the view 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            
            app.BackupAndUndoPanel.Visible = 'off';
            app.ColorsPanel.Visible = 'off';
            app.ExternalDirectoriesPanel.Visible = 'off';
            app.SegmentationToolsPanel.Visible = 'off';
            app.KeyboardShortcutsPanel.Visible = 'off';
            app.InputOutputPanel.Visible = 'off';
            
            app.BackupAndUndoPanel.Parent = app.UserInterfacePanel.Parent;
            app.ColorsPanel.Parent = app.UserInterfacePanel.Parent;
            app.ExternalDirectoriesPanel.Parent = app.UserInterfacePanel.Parent;
            app.SegmentationToolsPanel.Parent = app.UserInterfacePanel.Parent;
            app.KeyboardShortcutsPanel.Parent = app.UserInterfacePanel.Parent;
            app.InputOutputPanel.Parent = app.UserInterfacePanel.Parent;
            
            app.BackupAndUndoPanel.Position = app.UserInterfacePanel.Position;
            app.ColorsPanel.Position = app.UserInterfacePanel.Position;
            app.ExternalDirectoriesPanel.Position = app.UserInterfacePanel.Position;
            app.SegmentationToolsPanel.Position = app.UserInterfacePanel.Position;
            app.KeyboardShortcutsPanel.Position = app.UserInterfacePanel.Position;
            app.InputOutputPanel.Position = app.UserInterfacePanel.Position;
            
            % add icons to the list
            mibPath = app.winController.mibModel.mibPath;
            app.UserInterfaceNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_interface_16px.png');
            app.ColorsNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_colors_16px.png');
            app.BackupAndUndoNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_backup_16px.png');
            app.ExternalDirectoriesNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_external_16px.png');
            app.KeyboardShortcutsNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_keyboard_16px.png');
            app.SegmentationToolsNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_segmentation_16px.png');
            app.InputOutputNode.Icon = fullfile(mibPath, 'assets', 'icons', 'preferences_io_16px.png');

            app.maxUndoHistoryLabel.Text = sprintf('total number of operations (2D and 3D) stored for backup\nthe larger the value more steps are stored\nbut more memory consumed');
            app.max3dUndoHistoryLabel.Text = sprintf('total number of 3D operations stored for backup\nthe value should be smaller than the number of history steps to store');
        end

        % Selection changed function: CategoriesTree
        function CategoriesTreeSelectionChanged(app, event)
            selectedNodes = app.CategoriesTree.SelectedNodes;
            app.winController.CategoriesTreeSelectionChanged(selectedNodes);
        end

        % Callback function: CancelButton, Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();            
        end

        % Callback function: AltWithScrollWheel, CpuParallelLimit, 
        % ...and 19 other components
        function UserInterfacePanelCallbacks(app, event)
            app.winController.UserInterfacePanelCallbacks(event);            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.winController.ApplyButtonPushedCallback();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.winController.OKButtonPushedCallback();
        end

        % Button pushed function: RescaleGUIButton
        function RescaleGUIButtonPushed(app, event)
            app.winController.RescaleGUIButtonPushed();
        end

        % Callback function: AnnotationsColorButton, 
        % ...and 11 other components
        function ColorsPanelCallbacks(app, event)
            app.winController.ColorPanelCallbacks(event);
        end

        % Cell selection callback: LUTColorsTable, ModelsColorsTable
        function TableCellSelectionCallback(app, event)
            app.winController.TableCellSelectionCallback(event);
        end

        % Cell edit callback: LUTColorsTable, ModelsColorsTable
        function TableCellEditCallback(app, event)
            app.winController.TableCellEditCallback(event);
        end

        % Value changed function: ScaleToOneCheckBox
        function ScaleToOneCheckBoxValueChanged(app, event)
            options.updateDataOnly = 1;     % update only data in the table without redo the styles
            app.winController.updateColorsTables('ModelsColorsTable', options);    % update color table due to press of the checkbox
            app.winController.updateColorsTables('LUTColorsTable', options);    % update color table due to press of the checkbox
        end

        % Menu selected function: DeleteColorsMenu, ExportMenu, 
        % ...and 8 other components
        function ModelsColorsTableContextMenuCallbacks(app, event)
            app.winController.ModelsColorsTableContextMenuCallbacks(event);
        end

        % Value changed function: EnableUndo, max3dUndoHistory, 
        % ...and 1 other component
        function BackupAndUndoPanelCallbacks(app, event)
            app.winController.BackupAndUndoPanelCallbacks(event);
        end

        % Button pushed function: BM3DDirSelectBtn, BM4DDirSelectBtn, 
        % ...and 7 other components
        function ExternalDirSelectBtnButtonPushed(app, event)
            app.winController.ExternalDirSelect(event);  
        end

        % Value changed function: BioFormatsMemoizerMemoDir, DeepMIBDir, 
        % ...and 7 other components
        function ExternalDirPathChanged(app, event)
            app.winController.ExternalDirPathChange(event);  
        end

        % Cell edit callback: shortcutsTable
        function shortcutsTableCellEdit(app, event)
            app.winController.updateKeyShortcut(event);  
        end

        % Key release function: Figure
        function FigureKeyRelease(app, event)
            key = event.Key;
            
            if strcmp(app.KeyboardShortcutsPanel.Visible, 'off'); return; end % do not display key press for other panels
            app.PressedKeyValue.Text = sprintf('%s %s', strjoin(event.Modifier, ' + '), key);
        end

        % Value changed function: FavoriteToolA, FavoriteToolB, 
        % ...and 5 other components
        function SegmentationPanelCallbacks(app, event)
            app.winController.SegmentationPanelCallbacks(event);
        end

        % Button pushed function: ResetKeyShortcutsButton
        function KeyboardShortcutsPanelCallbacks(app, event)
            app.winController.KeyboardShortcutsPanelCallbacks(event);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.winController.helpBtnCallback();
        end

        % Value changed function: BioFormatsLibrary, ZarrLibrary, 
        % ...and 1 other component
        function InputOutputPanelCallbacks(app, event)
            app.winController.InputOutputPanelCallbacks(event);
        end

        % Value changed function: PythonExecutionMode
        function ExternalDirectoriesPanelCallbacks(app, event)
            app.winController.ExternalDirectoriesPanelCallbacks(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Preferences';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Figure.KeyReleaseFcn = createCallbackFcn(app, @FigureKeyRelease, true);
            app.ColorsPanel = uipanel(app.Figure);
            app.PalettegeneratorDropDownLabel = uilabel(app.ColorsPanel);
            app.PalettegeneratorDropDownLabel.Text = 'Palette generator:';
            app.PaletteGeneratorDropDown = uidropdown(app.ColorsPanel);
            app.PaletteGeneratorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.PaletteGeneratorDropDown.Tooltip = {'Select one of the predefined palettes'};
            app.NumberofcolorsDropDownLabel = uilabel(app.ColorsPanel);
            app.NumberofcolorsDropDownLabel.Text = 'Number of colors:';
            app.NumberOfColorsDropDown = uidropdown(app.ColorsPanel);
            app.NumberOfColorsDropDown.Items = {'6', '7', '8', '9'};
            app.NumberOfColorsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.NumberOfColorsDropDown.Tooltip = {'Number of colors in the palette'; ''};
            app.NumberOfColorsDropDown.Value = '6';
            app.ModelsColorsTable = uitable(app.ColorsPanel);
            app.ModelsColorsTable.ColumnName = {'Red'; 'Green'; 'Blue'; 'Preview'};
            app.ModelsColorsTable.ColumnEditable = [true true true false];
            app.ModelsColorsTable.CellEditCallback = createCallbackFcn(app, @TableCellEditCallback, true);
            app.ModelsColorsTable.CellSelectionCallback = createCallbackFcn(app, @TableCellSelectionCallback, true);
            app.ColorsofmaterialsLabel = uilabel(app.ColorsPanel);
            app.ColorsofmaterialsLabel.Text = 'Colors of materials';
            app.LUTcolorsforcolorchannelsLabel = uilabel(app.ColorsPanel);
            app.LUTcolorsforcolorchannelsLabel.Text = 'LUT colors for color channels';
            app.LUTColorsTable = uitable(app.ColorsPanel);
            app.LUTColorsTable.ColumnName = {'Red'; 'Green'; 'Blue'; 'Preview'};
            app.LUTColorsTable.ColumnEditable = [true true true false];
            app.LUTColorsTable.CellEditCallback = createCallbackFcn(app, @TableCellEditCallback, true);
            app.LUTColorsTable.CellSelectionCallback = createCallbackFcn(app, @TableCellSelectionCallback, true);
            app.SelectionColorButton = uibutton(app.ColorsPanel, 'push');
            app.SelectionColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.SelectionColorButton.Tooltip = {'press to choose color for the Selection layer'};
            app.SelectionColorButton.Text = 'Selection';
            app.MaskColorButton = uibutton(app.ColorsPanel, 'push');
            app.MaskColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.MaskColorButton.Tooltip = {'press to choose color for the Mask layer'};
            app.MaskColorButton.Text = 'Mask';
            app.AnnotationsColorButton = uibutton(app.ColorsPanel, 'push');
            app.AnnotationsColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.AnnotationsColorButton.Tooltip = {'press to choose color for the Annotations'};
            app.AnnotationsColorButton.Text = 'Annotations';
            app.ScaleToOneCheckBox = uicheckbox(app.ColorsPanel);
            app.ScaleToOneCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleToOneCheckBoxValueChanged, true);
            app.ScaleToOneCheckBox.Tooltip = {'scale colors between 0 and 1'; ''};
            app.ScaleToOneCheckBox.Text = 'scale to 0-1';
            app.ContoursPanel = uipanel(app.ColorsPanel);
            app.ContoursPanel.Title = 'Contours';
            app.ModelthicknessLabel = uilabel(app.ContoursPanel);
            app.ModelthicknessLabel.Tooltip = {'Thickness of lines for visualization of models using the Contour mode'};
            app.ModelthicknessLabel.Text = 'Model thickness';
            app.ContourThicknessModels = uieditfield(app.ContoursPanel, 'numeric');
            app.ContourThicknessModels.Limits = [1 Inf];
            app.ContourThicknessModels.RoundFractionalValues = 'on';
            app.ContourThicknessModels.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.ContourThicknessModels.Tooltip = {'Thickness of lines for visualization of models using the Contour mode'};
            app.ContourThicknessModels.Value = 2;
            app.MaskthicknessLabel = uilabel(app.ContoursPanel);
            app.MaskthicknessLabel.Tooltip = {'Thickness of lines for visualization of masks using the Contour mode'};
            app.MaskthicknessLabel.Text = 'Mask thickness';
            app.ContourThicknessMasks = uieditfield(app.ContoursPanel, 'numeric');
            app.ContourThicknessMasks.Limits = [1 Inf];
            app.ContourThicknessMasks.RoundFractionalValues = 'on';
            app.ContourThicknessMasks.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.ContourThicknessMasks.Tooltip = {'Thickness of lines for visualization of masks using the Contour mode'};
            app.ContourThicknessMasks.Value = 2;
            app.RenderingLabel = uilabel(app.ContoursPanel);
            app.RenderingLabel.Tooltip = {'rendering method for showing contours, in the "performance" mode when two objects are touching the contour is rendered using color of an earlier material'};
            app.RenderingLabel.Text = 'Rendering';
            app.ContourThicknessRendering = uidropdown(app.ContoursPanel);
            app.ContourThicknessRendering.Items = {'quality', 'performance'};
            app.ContourThicknessRendering.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.ContourThicknessRendering.Tooltip = {'rendering method for showing contours, in the "performance" mode when two objects are touching the contour is rendered using color of an earlier material'};
            app.ContourThicknessRendering.Value = 'quality';
            app.ModeLabel = uilabel(app.ContoursPanel);
            app.ModeLabel.Tooltip = {'rendering method for showing contours, in the "performance" mode when two objects are touching the contour is rendered using color of an earlier material'};
            app.ModeLabel.Text = 'Mask method';
            app.ContourThicknessMasksMethod = uidropdown(app.ContoursPanel);
            app.ContourThicknessMasksMethod.Items = {'inwards', 'outwards'};
            app.ContourThicknessMasksMethod.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.ContourThicknessMasksMethod.Tooltip = {'method for showing contours of the Mask layer: "inward" means by shrinking the mask object, while "outward" by growing '};
            app.ContourThicknessMasksMethod.Value = 'inwards';
            app.MasksPanel = uipanel(app.ColorsPanel);
            app.MasksPanel.Title = 'Masks';
            app.MaskShowAsContours = uicheckbox(app.MasksPanel);
            app.MaskShowAsContours.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.MaskShowAsContours.Tooltip = {'when checked, the Mask layer is rendered as contours, otherwise as filled shapes'};
            app.MaskShowAsContours.Text = 'show as contours';
            app.MaskShowAsContours.Value = true;
            app.LabelsPanel = uipanel(app.ColorsPanel);
            app.LabelsPanel.Title = 'Labels';
            app.LabelsShowAsContours = uicheckbox(app.LabelsPanel);
            app.LabelsShowAsContours.ValueChangedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.LabelsShowAsContours.Tooltip = {'when checked, the Mask layer is rendered as contours, otherwise as filled shapes'};
            app.LabelsShowAsContours.Text = 'show as contours';
            app.cursorMaterialColor = uicheckbox(app.ColorsPanel);
            app.cursorMaterialColor.Text = 'cursor color follows selected material ';
            app.BackupAndUndoPanel = uipanel(app.Figure);
            app.max3dUndoHistory = uieditfield(app.BackupAndUndoPanel, 'numeric');
            app.max3dUndoHistory.Limits = [0 Inf];
            app.max3dUndoHistory.RoundFractionalValues = 'on';
            app.max3dUndoHistory.ValueChangedFcn = createCallbackFcn(app, @BackupAndUndoPanelCallbacks, true);
            app.NumberofhistorystepstostoreEditFieldLabel = uilabel(app.BackupAndUndoPanel);
            app.NumberofhistorystepstostoreEditFieldLabel.Text = 'Number of history steps to store';
            app.Numberof3DhistorystepstostoreEditFieldLabel = uilabel(app.BackupAndUndoPanel);
            app.Numberof3DhistorystepstostoreEditFieldLabel.Text = 'Number of 3D history steps to store';
            app.maxUndoHistory = uieditfield(app.BackupAndUndoPanel, 'numeric');
            app.maxUndoHistory.Limits = [0 Inf];
            app.maxUndoHistory.RoundFractionalValues = 'on';
            app.maxUndoHistory.ValueChangedFcn = createCallbackFcn(app, @BackupAndUndoPanelCallbacks, true);
            app.maxUndoHistoryLabel = uilabel(app.BackupAndUndoPanel);
            app.maxUndoHistoryLabel.Text = 'total number of operations (2D and 3D) stored for backup';
            app.EnableUndo = uicheckbox(app.BackupAndUndoPanel);
            app.EnableUndo.ValueChangedFcn = createCallbackFcn(app, @BackupAndUndoPanelCallbacks, true);
            app.EnableUndo.Text = 'enable undo';
            app.max3dUndoHistoryLabel = uilabel(app.BackupAndUndoPanel);
            app.max3dUndoHistoryLabel.Text = 'total number of operations (2D and 3D) stored for backup';
            app.SegmentationToolsPanel = uipanel(app.Figure);
            app.InterpolationPanel = uipanel(app.SegmentationToolsPanel);
            app.InterpolationPanel.Title = 'Interpolation';
            app.TypeDropDownLabel = uilabel(app.InterpolationPanel);
            app.TypeDropDownLabel.Tooltip = {'type of the interpolant to use: "shape" is best for blobs, while "line" for non closed linear objects'};
            app.TypeDropDownLabel.Text = 'Type:';
            app.NumberofpointsEditFieldLabel = uilabel(app.InterpolationPanel);
            app.NumberofpointsEditFieldLabel.Tooltip = {'Number of points for generation of shapes during interpolation. Larger the value improves results, but slows performance'};
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.LinewidthpxEditFieldLabel = uilabel(app.InterpolationPanel);
            app.LinewidthpxEditFieldLabel.Tooltip = {'thinkness of the generated lines for the "line" interpolation'};
            app.LinewidthpxEditFieldLabel.Text = 'Line width, px';
            app.InterpolationLineWidth = uieditfield(app.InterpolationPanel, 'numeric');
            app.InterpolationLineWidth.Limits = [1 Inf];
            app.InterpolationLineWidth.RoundFractionalValues = 'on';
            app.InterpolationLineWidth.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.InterpolationLineWidth.Tooltip = {'thinkness of the generated lines for the "line" interpolation'};
            app.InterpolationLineWidth.Value = 2;
            app.InterpolationNumberOfPoints = uieditfield(app.InterpolationPanel, 'numeric');
            app.InterpolationNumberOfPoints.Limits = [8 Inf];
            app.InterpolationNumberOfPoints.RoundFractionalValues = 'on';
            app.InterpolationNumberOfPoints.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.InterpolationNumberOfPoints.Tooltip = {'Number of points for generation of shapes during interpolation. Larger the value improves results, but slows performance'};
            app.InterpolationNumberOfPoints.Value = 8;
            app.InterpolationType = uidropdown(app.InterpolationPanel);
            app.InterpolationType.Items = {'shape', 'line'};
            app.InterpolationType.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.InterpolationType.Tooltip = {'type of the interpolant to use: "shape" is best for blobs, while "line" for non closed linear objects'};
            app.InterpolationType.Value = 'shape';
            app.AnntationsPanel = uipanel(app.SegmentationToolsPanel);
            app.AnntationsPanel.Title = 'Annotations';
            app.FontsizeDropDownLabel = uilabel(app.AnntationsPanel);
            app.FontsizeDropDownLabel.Tooltip = {'font size for showing annotations'};
            app.FontsizeDropDownLabel.Text = 'Font size:';
            app.annotationFontSize = uidropdown(app.AnntationsPanel);
            app.annotationFontSize.Items = {'1 (pt 8)', '2 (pt 10)', '3 (pt 12)', '4 (pt 14)', '5 (pt 16)', '6 (pt 18)', '7 (pt 20)'};
            app.annotationFontSize.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.annotationFontSize.Tooltip = {'font size for showing annotations'};
            app.annotationFontSize.Value = '1 (pt 8)';
            app.ExtradepthtoshowSpinnerLabel = uilabel(app.AnntationsPanel);
            app.ExtradepthtoshowSpinnerLabel.Tooltip = {'extend visualization of the annotations for additional slices, i.e. when 3, the annotation will be shown 3 slices before and after its location'};
            app.ExtradepthtoshowSpinnerLabel.Text = 'Extra depth to show:';
            app.annotationShownExtraDepth = uispinner(app.AnntationsPanel);
            app.annotationShownExtraDepth.Limits = [0 Inf];
            app.annotationShownExtraDepth.RoundFractionalValues = 'on';
            app.annotationShownExtraDepth.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.annotationShownExtraDepth.Tooltip = {'extend visualization of the annotations for additional slices, i.e. when 3, the annotation will be shown 3 slices before and after its location'};
            app.AnnotationsColorButton2 = uibutton(app.AnntationsPanel, 'push');
            app.AnnotationsColorButton2.ButtonPushedFcn = createCallbackFcn(app, @ColorsPanelCallbacks, true);
            app.AnnotationsColorButton2.Tooltip = {'press to choose color for the Annotations'};
            app.AnnotationsColorButton2.Text = 'Color';
            app.FavoriteToolsPanel = uipanel(app.SegmentationToolsPanel);
            app.FavoriteToolsPanel.Title = 'Favorite tools';
            app.FavoritetoolADropDownLabel = uilabel(app.FavoriteToolsPanel);
            app.FavoritetoolADropDownLabel.Tooltip = {'Favorite tool A available by default via "Shift+D"'};
            app.FavoritetoolADropDownLabel.Text = 'Favorite tool A';
            app.FavoriteToolA = uidropdown(app.FavoriteToolsPanel);
            app.FavoriteToolA.Items = {'3D ball', '3D lines', 'Annotations', 'Brush', 'BW Thresholding', 'Drag & Drop materials', 'Lasso', 'MagicWand-RegionGrowing', 'Membrane ClickTracker', 'Object Picker', 'Segment-anything model', 'Spot'};
            app.FavoriteToolA.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.FavoriteToolA.Tooltip = {'Favorite tool A available by default via "Shift+D"'};
            app.FavoriteToolA.Value = '3D ball';
            app.FavoritetoolBDropDownLabel = uilabel(app.FavoriteToolsPanel);
            app.FavoritetoolBDropDownLabel.Tooltip = {'Favorite tool B available by default via "Ctrl+D"'};
            app.FavoritetoolBDropDownLabel.Text = 'Favorite tool B';
            app.FavoriteToolB = uidropdown(app.FavoriteToolsPanel);
            app.FavoriteToolB.Items = {'3D ball', '3D lines', 'Annotations', 'Brush', 'BW Thresholding', 'Drag & Drop materials', 'Lasso', 'MagicWand-RegionGrowing', 'Membrane ClickTracker', 'Object Picker', 'Segment-anything model', 'Spot'};
            app.FavoriteToolB.ValueChangedFcn = createCallbackFcn(app, @SegmentationPanelCallbacks, true);
            app.FavoriteToolB.Tooltip = {'Favorite tool B available by default via "Ctrl+D"'};
            app.FavoriteToolB.Value = '3D ball';
            app.CategoriesTree = uitree(app.Figure);
            app.CategoriesTree.SelectionChangedFcn = createCallbackFcn(app, @CategoriesTreeSelectionChanged, true);
            app.UserInterfaceNode = uitreenode(app.CategoriesTree);
            app.UserInterfaceNode.Text = 'User interface';
            app.ColorsNode = uitreenode(app.CategoriesTree);
            app.ColorsNode.Text = 'Colors and styles';
            app.BackupAndUndoNode = uitreenode(app.CategoriesTree);
            app.BackupAndUndoNode.Text = 'Backup and undo';
            app.ExternalDirectoriesNode = uitreenode(app.CategoriesTree);
            app.ExternalDirectoriesNode.Text = 'External directories';
            app.KeyboardShortcutsNode = uitreenode(app.CategoriesTree);
            app.KeyboardShortcutsNode.Text = 'Keyboard shortcuts';
            app.SegmentationToolsNode = uitreenode(app.CategoriesTree);
            app.SegmentationToolsNode.Text = 'Segmentation tools';
            app.InputOutputNode = uitreenode(app.CategoriesTree);
            app.InputOutputNode.Text = 'Input / Output';
            app.HelpButton = uibutton(app.Figure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.OKButton = uibutton(app.Figure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ApplyButton = uibutton(app.Figure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.Figure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.UserInterfacePanel = uipanel(app.Figure);
            app.GUIscalingPanel = uipanel(app.UserInterfacePanel);
            app.GUIscalingPanel.Title = 'GUI scaling';
            app.RescaleGUIButton = uibutton(app.GUIscalingPanel, 'push');
            app.RescaleGUIButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleGUIButtonPushed, true);
            app.RescaleGUIButton.Enable = 'off';
            app.RescaleGUIButton.Tooltip = {'Rescale main window of MIB'};
            app.RescaleGUIButton.Text = 'Rescale GUI';
            app.MIBscalingfactorEditFieldLabel = uilabel(app.GUIscalingPanel);
            app.MIBscalingfactorEditFieldLabel.Tooltip = {'Scaling factor for MIB, the elements specified on the right will be rescaled using this factor'; ''};
            app.MIBscalingfactorEditFieldLabel.Text = 'MIB scaling factor';
            app.OSscalingfactorEditFieldLabel = uilabel(app.GUIscalingPanel);
            app.OSscalingfactorEditFieldLabel.Tooltip = {'Specify the scaling factor of the operation system; it is used to calculate correct coordinates of the mouse'};
            app.OSscalingfactorEditFieldLabel.Text = 'OS scaling factor';
            app.ScalethefollowingelementsofGUILabel = uilabel(app.GUIscalingPanel);
            app.ScalethefollowingelementsofGUILabel.Enable = 'off';
            app.ScalethefollowingelementsofGUILabel.Text = 'Scale the following elements of GUI:';
            app.uicontrol = uicheckbox(app.GUIscalingPanel);
            app.uicontrol.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uicontrol.Tooltip = {'Check to rescale other control elements'};
            app.uicontrol.Enable = 'off';
            app.uicontrol.Text = 'uicontrol';
            app.axes = uicheckbox(app.GUIscalingPanel);
            app.axes.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.axes.Tooltip = {'Check to rescale axes'};
            app.axes.Enable = 'off';
            app.axes.Text = 'axes';
            app.uitabgroup = uicheckbox(app.GUIscalingPanel);
            app.uitabgroup.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uitabgroup.Tooltip = {'Check to rescale tab groups'};
            app.uitabgroup.Enable = 'off';
            app.uitabgroup.Text = 'uitabgroup';
            app.uibuttongroup = uicheckbox(app.GUIscalingPanel);
            app.uibuttongroup.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uibuttongroup.Tooltip = {'Check to rescale button groups'};
            app.uibuttongroup.Enable = 'off';
            app.uibuttongroup.Text = 'uibuttongroup';
            app.uitable = uicheckbox(app.GUIscalingPanel);
            app.uitable.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uitable.Tooltip = {'Check to rescale tables'};
            app.uitable.Enable = 'off';
            app.uitable.Text = 'uitable';
            app.uitab = uicheckbox(app.GUIscalingPanel);
            app.uitab.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uitab.Tooltip = {'Check to rescale tabs'};
            app.uitab.Enable = 'off';
            app.uitab.Text = 'uitab';
            app.uipanel = uicheckbox(app.GUIscalingPanel);
            app.uipanel.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.uipanel.Tooltip = {'Check to rescale panels'};
            app.uipanel.Enable = 'off';
            app.uipanel.Text = 'uipanel';
            app.mibScalingFactorEditField = uieditfield(app.GUIscalingPanel, 'numeric');
            app.mibScalingFactorEditField.Limits = [0.01 Inf];
            app.mibScalingFactorEditField.ValueDisplayFormat = '%.2f';
            app.mibScalingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.mibScalingFactorEditField.Enable = 'off';
            app.mibScalingFactorEditField.Tooltip = {'Scaling factor for MIB, the elements specified on the right will be rescaled using this factor'; ''};
            app.mibScalingFactorEditField.Value = 0.01;
            app.SystemScalingEditField = uieditfield(app.GUIscalingPanel, 'numeric');
            app.SystemScalingEditField.Limits = [0.01 Inf];
            app.SystemScalingEditField.ValueDisplayFormat = '%.2f';
            app.SystemScalingEditField.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.SystemScalingEditField.Tooltip = {'Specify the scaling factor of the operation system; it is used to calculate correct coordinates of the mouse'};
            app.SystemScalingEditField.Value = 0.01;
            app.GUItweaksPanel = uipanel(app.UserInterfacePanel);
            app.GUItweaksPanel.Title = 'GUI tweaks';
            app.MousewheelactionDropDownLabel = uilabel(app.GUItweaksPanel);
            app.MousewheelactionDropDownLabel.Tooltip = {'define mouse wheel action: zoom in/out or change of slices/time frames'};
            app.MousewheelactionDropDownLabel.Text = 'Mouse wheel action';
            app.LeftmouseactionLabel = uilabel(app.GUItweaksPanel);
            app.LeftmouseactionLabel.Tooltip = {'assign action: ''pan'' - move image or ''select'' - draw and segment to the left mouse button, the right mouse button is assigned to the opposite '; 'action'};
            app.LeftmouseactionLabel.Text = 'Left mouse action';
            app.ImageresizemethodDropDownLabel = uilabel(app.GUItweaksPanel);
            app.ImageresizemethodDropDownLabel.Tooltip = {'Specify the interpolation method to be used to display images in the View Image panel. '; 'The nearest is the fastest method, the bicubic - the slowest. In the automatic mode the nearest is used for magnifications higher than 100% and bicubic for less than 100%'};
            app.ImageresizemethodDropDownLabel.Text = 'Image resize method';
            app.EnableselectionDropDownLabel = uilabel(app.GUItweaksPanel);
            app.EnableselectionDropDownLabel.Tooltip = {'when Selection is disabled, MIB is not reserving space for it to minimize memory requirements but it is not possible to  use segmentation tools'; ''};
            app.EnableselectionDropDownLabel.Text = 'Enable selection';
            app.EnableSelectionDropDown = uidropdown(app.GUItweaksPanel);
            app.EnableSelectionDropDown.Items = {'yes', 'no'};
            app.EnableSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.EnableSelectionDropDown.Tooltip = {'when Selection is disabled, MIB is not reserving space for it to minimize memory requirements but it is not possible to  use segmentation tools'; ''};
            app.EnableSelectionDropDown.Value = 'yes';
            app.ImageResizeMethodDropDown = uidropdown(app.GUItweaksPanel);
            app.ImageResizeMethodDropDown.Items = {'auto', 'nearest', 'bicubic'};
            app.ImageResizeMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.ImageResizeMethodDropDown.Tooltip = {'Specify the interpolation method to be used to display images in the View Image panel. '; 'The nearest is the fastest method, the bicubic - the slowest. In the automatic mode the nearest is used for magnifications higher than 100% and bicubic for less than 100%'};
            app.ImageResizeMethodDropDown.Value = 'auto';
            app.LeftMouseActionDropDown = uidropdown(app.GUItweaksPanel);
            app.LeftMouseActionDropDown.Items = {'Pan image', 'Selection/drawing'};
            app.LeftMouseActionDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.LeftMouseActionDropDown.Tooltip = {'assign action: ''pan'' - move image or ''select'' - draw and segment to the left mouse button, the right mouse button is assigned to the opposite '; 'action'};
            app.LeftMouseActionDropDown.Value = 'Pan image';
            app.MouseWheelActionDropDown = uidropdown(app.GUItweaksPanel);
            app.MouseWheelActionDropDown.Items = {'Zoom In/Out', 'Change slices/frames'};
            app.MouseWheelActionDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.MouseWheelActionDropDown.Tooltip = {'define mouse wheel action: zoom in/out or change of slices/time frames'};
            app.MouseWheelActionDropDown.Value = 'Zoom In/Out';
            app.NumberofrecentdirsEditFieldLabel = uilabel(app.GUItweaksPanel);
            app.NumberofrecentdirsEditFieldLabel.Tooltip = {'How many recent directories from where the data was loaded to keep'};
            app.NumberofrecentdirsEditFieldLabel.Text = 'Number of recent dirs';
            app.RecentDirsNumber = uieditfield(app.GUItweaksPanel, 'numeric');
            app.RecentDirsNumber.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.RecentDirsNumber.Tooltip = {'How many recent directories from where the data was loaded to keep'};
            app.LeftmouseactionLabel_2 = uilabel(app.GUItweaksPanel);
            app.LeftmouseactionLabel_2.Tooltip = {'Define rendering engine for 3D visualization'};
            app.LeftmouseactionLabel_2.Text = '3D rendering engine';
            app.RenderingEngine = uidropdown(app.GUItweaksPanel);
            app.RenderingEngine.Items = {'Viewer3d, R2022b'};
            app.RenderingEngine.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.RenderingEngine.Tooltip = {'Define rendering engine for 3D visualization'};
            app.RenderingEngine.Value = 'Viewer3d, R2022b';
            app.AltWithScrollWheelLabel = uilabel(app.GUItweaksPanel);
            app.AltWithScrollWheelLabel.Tooltip = {'Define rendering engine for 3D visualization'};
            app.AltWithScrollWheelLabel.Text = 'Hold Alt with Scroll Wheel';
            app.AltWithScrollWheel = uidropdown(app.GUItweaksPanel);
            app.AltWithScrollWheel.Items = {'Return to the slice', 'Scroll time points'};
            app.AltWithScrollWheel.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.AltWithScrollWheel.Tooltip = {'Define rendering engine for 3D visualization'};
            app.AltWithScrollWheel.Value = 'Scroll time points';
            app.FontPanel = uipanel(app.UserInterfacePanel);
            app.FontPanel.Title = 'Font';
            app.FontsizeLabel = uilabel(app.FontPanel);
            app.FontsizeLabel.Tooltip = {'Font size for widgets of GUI'};
            app.FontsizeLabel.Text = 'Font size';
            app.FontsizefordirectorycontentsEditFieldLabel = uilabel(app.FontPanel);
            app.FontsizefordirectorycontentsEditFieldLabel.Tooltip = {'Font size to show file list in the Directory contents panel'};
            app.FontsizefordirectorycontentsEditFieldLabel.Text = 'Font size for directory contents';
            app.CurrentFontLabel = uilabel(app.FontPanel);
            app.CurrentFontLabel.Tooltip = {'Currennt MIB font'};
            app.CurrentFontLabel.Text = 'Current font: ';
            app.SelectFontButton = uibutton(app.FontPanel, 'push');
            app.SelectFontButton.ButtonPushedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.SelectFontButton.Text = 'Select font...';
            app.FontSizeDireContentsEditField = uieditfield(app.FontPanel, 'numeric');
            app.FontSizeDireContentsEditField.Limits = [1 Inf];
            app.FontSizeDireContentsEditField.RoundFractionalValues = 'on';
            app.FontSizeDireContentsEditField.ValueDisplayFormat = '%11d';
            app.FontSizeDireContentsEditField.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.FontSizeDireContentsEditField.Tooltip = {'Font size to show file list in the Directory contents panel'};
            app.FontSizeDireContentsEditField.Value = 1;
            app.FontSizeEditField = uieditfield(app.FontPanel, 'numeric');
            app.FontSizeEditField.Limits = [1 Inf];
            app.FontSizeEditField.RoundFractionalValues = 'on';
            app.FontSizeEditField.ValueDisplayFormat = '%11d';
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.FontSizeEditField.Tooltip = {'Font size for widgets of GUI'};
            app.FontSizeEditField.Value = 1;
            app.UpdateCheckPanel = uipanel(app.UserInterfacePanel);
            app.UpdateCheckPanel.Title = 'Update check';
            app.PeriodforupdaterecheckdaysLabel = uilabel(app.UpdateCheckPanel);
            app.PeriodforupdaterecheckdaysLabel.Tooltip = {'define when the next check for MIB update should be performed'};
            app.PeriodforupdaterecheckdaysLabel.Text = 'Recheck for update, days';
            app.RecheckPeriod = uieditfield(app.UpdateCheckPanel, 'numeric');
            app.RecheckPeriod.Limits = [1 Inf];
            app.RecheckPeriod.RoundFractionalValues = 'on';
            app.RecheckPeriod.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.RecheckPeriod.Value = 30;
            app.ParallelprocessingPanel = uipanel(app.UserInterfacePanel);
            app.ParallelprocessingPanel.Title = 'Parallel processing';
            app.cpuParallelLimitLabel = uilabel(app.ParallelprocessingPanel);
            app.cpuParallelLimitLabel.Text = 'Number of CPUs';
            app.CpuParallelLimit = uieditfield(app.ParallelprocessingPanel, 'numeric');
            app.CpuParallelLimit.ValueChangedFcn = createCallbackFcn(app, @UserInterfacePanelCallbacks, true);
            app.DefaultButton = uibutton(app.Figure, 'push');
            app.DefaultButton.Text = 'Default';
            app.KeyboardShortcutsPanel = uipanel(app.Figure);
            app.shortcutsTable = uitable(app.KeyboardShortcutsPanel);
            app.shortcutsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.shortcutsTable.RowName = {};
            app.shortcutsTable.CellEditCallback = createCallbackFcn(app, @shortcutsTableCellEdit, true);
            app.PressedkeyLabel = uilabel(app.KeyboardShortcutsPanel);
            app.PressedkeyLabel.Text = 'Pressed key:';
            app.PressedKeyValue = uilabel(app.KeyboardShortcutsPanel);
            app.PressedKeyValue.Text = '<make this panel in focus with a mouse click and press any key to see it here>';
            app.ResetKeyShortcutsButton = uibutton(app.KeyboardShortcutsPanel, 'push');
            app.ResetKeyShortcutsButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardShortcutsPanelCallbacks, true);
            app.ResetKeyShortcutsButton.Tooltip = {'Reset key shortcuts to default'};
            app.ResetKeyShortcutsButton.Text = 'Reset';
            app.InputOutputPanel = uipanel(app.Figure);
            app.ZarrLibraryPanel = uipanel(app.InputOutputPanel);
            app.ZarrLibraryPanel.Title = 'Zarr library';
            app.ZarrlibraryLabel = uilabel(app.ZarrLibraryPanel);
            app.ZarrlibraryLabel.Tooltip = {'font size for showing annotations'};
            app.ZarrlibraryLabel.Text = 'Zarr library';
            app.ZarrLibrary = uidropdown(app.ZarrLibraryPanel);
            app.ZarrLibrary.Items = {'native', 'python'};
            app.ZarrLibrary.ValueChangedFcn = createCallbackFcn(app, @InputOutputPanelCallbacks, true);
            app.ZarrLibrary.Tooltip = {'font size for showing annotations'};
            app.ZarrLibrary.Value = 'native';
            app.ZarrLibraryLabel = uilabel(app.ZarrLibraryPanel);
            app.ZarrSmoothing = uicheckbox(app.ZarrLibraryPanel);
            app.ZarrSmoothing.ValueChangedFcn = createCallbackFcn(app, @InputOutputPanelCallbacks, true);
            app.ZarrSmoothing.Text = 'smoothing';
            app.ZarrSmoothing.Value = true;
            app.ChunkCacheMBLabel = uilabel(app.ZarrLibraryPanel);
            app.ChunkCacheMBLabel.Text = 'Chunk Cache';
            app.ChunkCacheMB = uieditfield(app.ZarrLibraryPanel, 'numeric');
            app.ChunkCacheMB.Limits = [1 Inf];
            app.ChunkCacheMB.RoundFractionalValues = 'on';
            app.ChunkCacheMB.ValueDisplayFormat = '%d MB';
            app.ChunkCacheMB.Value = 500;
            app.BioFormatsPanel = uipanel(app.InputOutputPanel);
            app.BioFormatsPanel.Title = 'Bio-Formats library';
            app.ZarrlibraryLabel_2 = uilabel(app.BioFormatsPanel);
            app.ZarrlibraryLabel_2.Tooltip = {'font size for showing annotations'};
            app.ZarrlibraryLabel_2.Text = 'BioFormats library';
            app.BioFormatsLibrary = uidropdown(app.BioFormatsPanel);
            app.BioFormatsLibrary.Items = {'MIB', 'MATLAB'};
            app.BioFormatsLibrary.ValueChangedFcn = createCallbackFcn(app, @InputOutputPanelCallbacks, true);
            app.BioFormatsLibrary.Tooltip = {'font size for showing annotations'};
            app.BioFormatsLibrary.Value = 'MIB';
            app.BioFormatsLabel = uilabel(app.BioFormatsPanel);
            app.ExternalDirectoriesPanel = uipanel(app.Figure);
            app.LocationsofexternalpackagesthatcanbeusedwithMIBLabel = uilabel(app.ExternalDirectoriesPanel);
            app.LocationsofexternalpackagesthatcanbeusedwithMIBLabel.Text = 'Locations of external packages that can be used with MIB';
            app.LocationsofexternalpackagesthatcanbeusedwithMIBLabel_2 = uilabel(app.ExternalDirectoriesPanel);
            app.LocationsofexternalpackagesthatcanbeusedwithMIBLabel_2.Text = 'Make the edit box below empty, if you are not planning to use particular packages';
            app.MemoizerDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.MemoizerDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.MemoizerDirSelectBtn.Text = '...';
            app.BioformatsMemoizertemporarydirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.BioformatsMemoizertemporarydirectoryLabel.Text = 'Bioformats Memoizer temporary directory';
            app.BioFormatsMemoizerMemoDir = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.BioFormatsMemoizerMemoDir.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.BM3DDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.BM3DDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.BM3DDirSelectBtn.Text = '...';
            app.BM3DinstallationdirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.BM3DinstallationdirectoryLabel.Text = 'BM3D installation directory';
            app.bm3dInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.bm3dInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.BM4DDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.BM4DDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.BM4DDirSelectBtn.Text = '...';
            app.BM4DinstallationdirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.BM4DinstallationdirectoryLabel.Text = 'BM4D installation directory (not yet in use)';
            app.bm4dInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.bm4dInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.DeepMIBDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.DeepMIBDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.DeepMIBDirSelectBtn.Text = '...';
            app.DirectorytostorenetworkarchitecturesforDeepMIBLabel = uilabel(app.ExternalDirectoriesPanel);
            app.DirectorytostorenetworkarchitecturesforDeepMIBLabel.Text = 'Directory to store network architectures for DeepMIB';
            app.DeepMIBDir = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.DeepMIBDir.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.FijiDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.FijiDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.FijiDirSelectBtn.Text = '...';
            app.FijiinstallationdirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.FijiinstallationdirectoryLabel.Text = 'Fiji installation directory';
            app.FijiInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.FijiInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.JavaDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.JavaDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.JavaDirSelectBtn.Text = '...';
            app.Label = uilabel(app.ExternalDirectoriesPanel);
            app.Label.Text = 'Java path (R2026b or newer)';
            app.JavaInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.JavaInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.ImarisDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.ImarisDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.ImarisDirSelectBtn.Text = '...';
            app.ImarisinstallationdirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.ImarisinstallationdirectoryLabel.Text = 'Imaris installation directory';
            app.ImarisInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.ImarisInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.OmeroDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.OmeroDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.OmeroDirSelectBtn.Text = '...';
            app.OMEROinstallationdirectoryLabel = uilabel(app.ExternalDirectoriesPanel);
            app.OMEROinstallationdirectoryLabel.Text = 'OMERO installation directory';
            app.OmeroInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.OmeroInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.PythonDirSelectBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.PythonDirSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @ExternalDirSelectBtnButtonPushed, true);
            app.PythonDirSelectBtn.Text = '...';
            app.PythoninstallationpathLabel = uilabel(app.ExternalDirectoriesPanel);
            app.PythoninstallationpathLabel.Text = 'Python installation path';
            app.PythonInstallationPath = uieditfield(app.ExternalDirectoriesPanel, 'text');
            app.PythonInstallationPath.ValueChangedFcn = createCallbackFcn(app, @ExternalDirPathChanged, true);
            app.PythonExecutionModeLabel = uilabel(app.ExternalDirectoriesPanel);
            app.PythonExecutionModeLabel.Text = 'Python execution mode';
            app.PythonExecutionMode = uidropdown(app.ExternalDirectoriesPanel);
            app.PythonExecutionMode.Items = {'OutOfProcess', 'InProcess'};
            app.PythonExecutionMode.ValueChangedFcn = createCallbackFcn(app, @ExternalDirectoriesPanelCallbacks, true);
            app.PythonExecutionMode.Value = 'OutOfProcess';
            app.JavaFindBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.JavaFindBtn.Text = 'Find Java...';
            app.JavaConfigureBtn = uibutton(app.ExternalDirectoriesPanel, 'push');
            app.JavaConfigureBtn.Text = 'Configure Java...';
            app.ModelsColorsTableContextMenu = uicontextmenu(app.Figure);
            app.ReverseColormapMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.ReverseColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.ReverseColormapMenu.Text = 'Reverse colormap';
            app.InsertColorMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.InsertColorMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.InsertColorMenu.Separator = 'on';
            app.InsertColorMenu.Text = 'Insert color';
            app.ReplaceWithRandomColorMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.ReplaceWithRandomColorMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.ReplaceWithRandomColorMenu.Text = 'Replace with random color';
            app.SwapTwoColorsMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.SwapTwoColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.SwapTwoColorsMenu.Text = 'Swap two colors';
            app.DeleteColorsMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.DeleteColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.DeleteColorsMenu.Text = 'Delete color(s)';
            app.ImportMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.ImportMenu.Separator = 'on';
            app.ImportMenu.Text = 'Import';
            app.ImportFromMatlabMenu = uimenu(app.ImportMenu);
            app.ImportFromMatlabMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.ImportFromMatlabMenu.Text = 'from Matlab';
            app.LoadFromFileMenu = uimenu(app.ImportMenu);
            app.LoadFromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.LoadFromFileMenu.Text = 'load from a file';
            app.ExportMenu = uimenu(app.ModelsColorsTableContextMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.ExportMenu.Text = 'Export';
            app.ExportToMatlabMenu = uimenu(app.ExportMenu);
            app.ExportToMatlabMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.ExportToMatlabMenu.Text = 'to Matlab';
            app.SaveToFileMenu = uimenu(app.ExportMenu);
            app.SaveToFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelsColorsTableContextMenuCallbacks, true);
            app.SaveToFileMenu.Text = 'save to a file';
            % Assign app.ModelsColorsTableContextMenu
            app.ModelsColorsTable.ContextMenu = app.ModelsColorsTableContextMenu;
            app.ImageResizeMethodDropDown.ContextMenu = app.ModelsColorsTableContextMenu;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreferencesGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end