classdef QuantificationPropertiesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                    matlab.ui.Figure
        shapes3dPanel             matlab.ui.container.Panel
        shapes3dGridLayout        matlab.ui.container.GridLayout
        Volume3d                  matlab.ui.control.CheckBox
        FilledArea3d              matlab.ui.control.CheckBox
        EquatorialEccentricity3d  matlab.ui.control.CheckBox
        MeridionalEccentricity3d  matlab.ui.control.CheckBox
        MajorAxisLength3d         matlab.ui.control.CheckBox
        SecondAxisLength3d        matlab.ui.control.CheckBox
        ThirdAxisLength3d         matlab.ui.control.CheckBox
        EndpointsLength3d         matlab.ui.control.CheckBox
        ConvexVolume3d            matlab.ui.control.CheckBox
        EquivDiameter3d           matlab.ui.control.CheckBox
        Extent3d                  matlab.ui.control.CheckBox
        Solidity3d                matlab.ui.control.CheckBox
        SurfaceArea3d             matlab.ui.control.CheckBox
        mainGridLayout            matlab.ui.container.GridLayout
        bottomGridLayout          matlab.ui.container.GridLayout
        CheckallButton            matlab.ui.control.Button
        UncheckallButton          matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        shapes2dPanel             matlab.ui.container.Panel
        shapes2dGridLayout        matlab.ui.container.GridLayout
        Area                      matlab.ui.control.CheckBox
        ConvexArea                matlab.ui.control.CheckBox
        Eccentricity              matlab.ui.control.CheckBox
        EquivDiameter             matlab.ui.control.CheckBox
        EulerNumber               matlab.ui.control.CheckBox
        Extent                    matlab.ui.control.CheckBox
        FilledArea                matlab.ui.control.CheckBox
        MajorAxisLength           matlab.ui.control.CheckBox
        MinorAxisLength           matlab.ui.control.CheckBox
        Orientation               matlab.ui.control.CheckBox
        Perimeter                 matlab.ui.control.CheckBox
        Solidity                  matlab.ui.control.CheckBox
        CurveLength               matlab.ui.control.CheckBox
        EndpointsLength           matlab.ui.control.CheckBox
        FirstAxisLength           matlab.ui.control.CheckBox
        SecondAxisLength          matlab.ui.control.CheckBox
        intensityPanel            matlab.ui.container.Panel
        intensityGridLayout       matlab.ui.container.GridLayout
        MinIntensity              matlab.ui.control.CheckBox
        MaxIntensity              matlab.ui.control.CheckBox
        MeanIntensity             matlab.ui.control.CheckBox
        StdIntensity              matlab.ui.control.CheckBox
        SumIntensity              matlab.ui.control.CheckBox
        Correlation               matlab.ui.control.CheckBox
        separatorPanel            matlab.ui.container.Panel
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Name = 'Define object properties';
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            
            %app.shapes2dGridLayout = uigridlayout(app.shapes2dPanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.separatorPanel = uipanel(app.mainGridLayout);
            app.intensityPanel = uipanel(app.mainGridLayout);
            app.intensityPanel.Title = 'Intensity properties';
            app.intensityPanel.Tag = 'intensityPanel';
            app.intensityGridLayout = uigridlayout(app.intensityPanel);
            app.Correlation = uicheckbox(app.intensityGridLayout);
            app.Correlation.Tag = 'Correlation';
            app.Correlation.Text = 'Correlation';
            app.SumIntensity = uicheckbox(app.intensityGridLayout);
            app.SumIntensity.Tag = 'SumIntensity';
            app.SumIntensity.Text = 'Sum Intensity';
            app.StdIntensity = uicheckbox(app.intensityGridLayout);
            app.StdIntensity.Tag = 'StdIntensity';
            app.StdIntensity.Text = 'Std Intensity';
            app.MeanIntensity = uicheckbox(app.intensityGridLayout);
            app.MeanIntensity.Tag = 'MeanIntensity';
            app.MeanIntensity.Text = 'Mean Intensity';
            app.MaxIntensity = uicheckbox(app.intensityGridLayout);
            app.MaxIntensity.Tag = 'MaxIntensity';
            app.MaxIntensity.Text = 'Max Intensity';
            app.MinIntensity = uicheckbox(app.intensityGridLayout);
            app.MinIntensity.Tag = 'MinIntensity';
            app.MinIntensity.Text = 'Min Intensity';
            app.shapes2dPanel = uipanel(app.mainGridLayout);
            app.shapes2dPanel.Title = 'Shape properties for 2D objects';
            app.shapes2dPanel.Tag = 'shapes2dPanel';
            app.shapes2dGridLayout = uigridlayout(app.shapes2dPanel);
            app.SecondAxisLength = uicheckbox(app.shapes2dGridLayout);
            app.SecondAxisLength.Tag = 'SecondAxisLength';
            app.SecondAxisLength.Text = 'Second Axis Length';
            app.FirstAxisLength = uicheckbox(app.shapes2dGridLayout);
            app.FirstAxisLength.Tag = 'FirstAxisLength';
            app.FirstAxisLength.Text = 'First Axis Length';
            app.EndpointsLength = uicheckbox(app.shapes2dGridLayout);
            app.EndpointsLength.Tag = 'EndpointsLength';
            app.EndpointsLength.Text = 'Endpoints Length';
            app.CurveLength = uicheckbox(app.shapes2dGridLayout);
            app.CurveLength.Tag = 'CurveLength';
            app.CurveLength.Text = 'Curve Length';
            app.Solidity = uicheckbox(app.shapes2dGridLayout);
            app.Solidity.Tag = 'Solidity';
            app.Solidity.Tooltip = {'Proportion of the pixels in the convex hull that are also in the region, returned as a scalar. Computed as Area/ConvexArea.'};
            app.Solidity.Text = 'Solidity';
            app.Perimeter = uicheckbox(app.shapes2dGridLayout);
            app.Perimeter.Tag = 'Perimeter';
            app.Perimeter.Tooltip = {'Distance around the boundary of the region. returned as a scalar'};
            app.Perimeter.Text = 'Perimeter';
            app.Orientation = uicheckbox(app.shapes2dGridLayout);
            app.Orientation.Tag = 'Orientation';
            app.Orientation.Tooltip = {'Angle between the x-axis and the major axis of the ellipse that has the same second-moments as the region, returned as a scalar'};
            app.Orientation.Text = 'Orientation';
            app.MinorAxisLength = uicheckbox(app.shapes2dGridLayout);
            app.MinorAxisLength.Tag = 'MinorAxisLength';
            app.MinorAxisLength.Tooltip = {'length of the minor axis '};
            app.MinorAxisLength.Text = 'Minor Axis Length';
            app.MajorAxisLength = uicheckbox(app.shapes2dGridLayout);
            app.MajorAxisLength.Tag = 'MajorAxisLength';
            app.MajorAxisLength.Tooltip = {'length of the major axis '};
            app.MajorAxisLength.Text = 'Major Axis Length';
            app.FilledArea = uicheckbox(app.shapes2dGridLayout);
            app.FilledArea.Tag = 'FilledArea';
            app.FilledArea.Tooltip = {'Number of on pixels in FilledImage, returned as a scalar.'};
            app.FilledArea.Text = 'Filled Area';
            app.Extent = uicheckbox(app.shapes2dGridLayout);
            app.Extent.Tag = 'Extent';
            app.Extent.Tooltip = {'Ratio of pixels in the region to pixels in the total bounding box, returned as a scalar. Computed as the Area divided by the area of the bounding box.'};
            app.Extent.Text = 'Extent';
            app.EulerNumber = uicheckbox(app.shapes2dGridLayout);
            app.EulerNumber.Tag = 'EulerNumber';
            app.EulerNumber.Tooltip = {'Number of objects in the region minus the number of holes in those objects, returned as a scalar'};
            app.EulerNumber.Text = 'Euler Number';
            app.EquivDiameter = uicheckbox(app.shapes2dGridLayout);
            app.EquivDiameter.Tag = 'EquivDiameter';
            app.EquivDiameter.Tooltip = {'Diameter of a circle with the same area as the region, returned as a scalar. Computed as sqrt(4*Area/pi)'};
            app.EquivDiameter.Text = 'Equiv Diameter';
            app.Eccentricity = uicheckbox(app.shapes2dGridLayout);
            app.Eccentricity.Tag = 'Eccentricity';
            app.Eccentricity.Tooltip = {'Eccentricity of the ellipse that has the same second-moments as the region, returned as a scalar (0 is a circle; 1 is a line)'};
            app.Eccentricity.Text = 'Eccentricity';
            app.ConvexArea = uicheckbox(app.shapes2dGridLayout);
            app.ConvexArea.Tag = 'ConvexArea';
            app.ConvexArea.Tooltip = {'Number of pixels in ''ConvexImage'', returned as a scalar.'};
            app.ConvexArea.Text = 'Convex Area';
            app.Area = uicheckbox(app.shapes2dGridLayout);
            app.Area.Tag = 'Area';
            app.Area.Tooltip = {'Actual number of pixels in the region, returned as a scalar.'};
            app.Area.Text = 'Area';
            app.bottomGridLayout = uigridlayout(app.mainGridLayout);
            app.CancelButton = uibutton(app.bottomGridLayout, 'push');
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.bottomGridLayout, 'push');
            app.OKButton.Text = 'OK';
            app.UncheckallButton = uibutton(app.bottomGridLayout, 'push');
            app.UncheckallButton.Text = 'Uncheck all';
            app.CheckallButton = uibutton(app.bottomGridLayout, 'push');
            app.CheckallButton.Text = 'Check all';
            app.shapes3dPanel = uipanel(app.Figure);
            app.shapes3dPanel.Title = 'Shape properties for 3D objects';
            app.shapes3dPanel.Tag = 'shapes2dPanel';
            app.shapes3dGridLayout = uigridlayout(app.shapes3dPanel);
            app.SurfaceArea3d = uicheckbox(app.shapes3dGridLayout);
            app.SurfaceArea3d.Tag = 'SurfaceArea3d';
            app.SurfaceArea3d.Tooltip = {'Area around the boundary of the region'};
            app.SurfaceArea3d.Enable = 'off';
            app.SurfaceArea3d.Text = 'Surface Area';
            app.Solidity3d = uicheckbox(app.shapes3dGridLayout);
            app.Solidity3d.Tag = 'Solidity3d';
            app.Solidity3d.Tooltip = {'Proportion of the voxels in the convex hull that are also in the region, returned as a scalar. Computed as Volume/ConvexVolume'};
            app.Solidity3d.Enable = 'off';
            app.Solidity3d.Text = 'Solidity';
            app.Extent3d = uicheckbox(app.shapes3dGridLayout);
            app.Extent3d.Tag = 'Extent3d';
            app.Extent3d.Tooltip = {'Ratio of voxels in the region to voxels in the total bounding box, returned as a scalar. Computed as the value of Volume divided by the volume of the bounding box. [Volume/(bounding box width * bounding box height * bounding box depth)]'};
            app.Extent3d.Enable = 'off';
            app.Extent3d.Text = 'Extent';
            app.EquivDiameter3d = uicheckbox(app.shapes3dGridLayout);
            app.EquivDiameter3d.Tag = 'EquivDiameter3d';
            app.EquivDiameter3d.Tooltip = {'Diameter of a sphere with the same volume as the region, returned as a scalar. Computed as (6*Volume/pi)^(1/3).'};
            app.EquivDiameter3d.Enable = 'off';
            app.EquivDiameter3d.Text = 'Equiv Diameter';
            app.ConvexVolume3d = uicheckbox(app.shapes3dGridLayout);
            app.ConvexVolume3d.Tag = 'ConvexVolume3d';
            app.ConvexVolume3d.Tooltip = {'Number of voxels in ''ConvexImage'', returned as a scalar.'};
            app.ConvexVolume3d.Enable = 'off';
            app.ConvexVolume3d.Text = 'Convex Volume';
            app.EndpointsLength3d = uicheckbox(app.shapes3dGridLayout);
            app.EndpointsLength3d.Tag = 'EndpointsLength3d';
            app.EndpointsLength3d.Text = 'Endpoints Length';
            app.ThirdAxisLength3d = uicheckbox(app.shapes3dGridLayout);
            app.ThirdAxisLength3d.Tag = 'ThirdAxisLength3d';
            app.ThirdAxisLength3d.Tooltip = {'the length of the third axis '};
            app.ThirdAxisLength3d.Text = 'Third Axis Length';
            app.SecondAxisLength3d = uicheckbox(app.shapes3dGridLayout);
            app.SecondAxisLength3d.Tag = 'SecondAxisLength3d';
            app.SecondAxisLength3d.Tooltip = {'the length of the second axis '};
            app.SecondAxisLength3d.Text = 'Second Axis Length';
            app.MajorAxisLength3d = uicheckbox(app.shapes3dGridLayout);
            app.MajorAxisLength3d.Tag = 'MajorAxisLength3d';
            app.MajorAxisLength3d.Tooltip = {'length of the major axis '};
            app.MajorAxisLength3d.Text = 'Major Axis Length';
            app.MeridionalEccentricity3d = uicheckbox(app.shapes3dGridLayout);
            app.MeridionalEccentricity3d.Tag = 'MeridionalEccentricity3d';
            app.MeridionalEccentricity3d.Tooltip = {'Meriodional Eccentricity, defineds as the eccentricity of the section through the longest and the shortest axes'};
            app.MeridionalEccentricity3d.Text = 'Meridional Eccentricity';
            app.EquatorialEccentricity3d = uicheckbox(app.shapes3dGridLayout);
            app.EquatorialEccentricity3d.Tag = 'EquatorialEccentricity3d';
            app.EquatorialEccentricity3d.Tooltip = {'Equatorial Eccentricity, defined as the eccentricity of the section through the second longest and the shortest axes'};
            app.EquatorialEccentricity3d.Text = 'Equatorial Eccentricity';
            app.FilledArea3d = uicheckbox(app.shapes3dGridLayout);
            app.FilledArea3d.Tag = 'FilledArea3d';
            app.FilledArea3d.Tooltip = {''};
            app.FilledArea3d.Text = 'Filled Area';
            app.Volume3d = uicheckbox(app.shapes3dGridLayout);
            app.Volume3d.Tag = 'Volume3d';
            app.Volume3d.Tooltip = {'Count of the actual number of ''on'' voxels in the region, returned as a scalar. Volume represents the metric or measure of the number of voxels in the regions within the volumetric binary image, BW.'};
            app.Volume3d.Text = 'Volume';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantificationPropertiesGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end