classdef SelectFromUrlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                  matlab.ui.Figure
        mainGridLayout          matlab.ui.container.GridLayout
        chunkCacheMB            matlab.ui.control.NumericEditField
        ChunkCacheMBLabel       matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        closeButton             matlab.ui.control.Button
        openButton              matlab.ui.control.Button
        helpButton              matlab.ui.control.Button
        DatasetMode             matlab.ui.control.DropDown
        DatasetModeLabel        matlab.ui.control.Label
        LoadAs                  matlab.ui.control.DropDown
        LoadAsLabel             matlab.ui.control.Label
        GroupPath               matlab.ui.control.EditField
        GroupPathLabel          matlab.ui.control.Label
        infoTextArea            matlab.ui.control.TextArea
        infoTextAreaLabel       matlab.ui.control.Label
        groupTree               matlab.ui.container.Tree
        Node                    matlab.ui.container.TreeNode
        Node2                   matlab.ui.container.TreeNode
        Node3                   matlab.ui.container.TreeNode
        Node4                   matlab.ui.container.TreeNode
        statusLabel             matlab.ui.control.Label
        Url                     matlab.ui.control.EditField
        connectButton           matlab.ui.control.Button
        StoreURLEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        winController % controller for the view
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Select image or Zarr from URL';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.StoreURLEditFieldLabel = uilabel(app.mainGridLayout);
            app.StoreURLEditFieldLabel.Text = 'Store URL:';
            app.connectButton = uibutton(app.mainGridLayout, 'push');
            app.connectButton.Text = 'Connect';
            app.Url = uieditfield(app.mainGridLayout, 'text');
            app.statusLabel = uilabel(app.mainGridLayout);
            app.groupTree = uitree(app.mainGridLayout);
            app.Node = uitreenode(app.groupTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.infoTextAreaLabel = uilabel(app.mainGridLayout);
            app.infoTextAreaLabel.Text = 'Dataset info';
            app.infoTextArea = uitextarea(app.mainGridLayout);
            app.GroupPathLabel = uilabel(app.mainGridLayout);
            app.GroupPathLabel.Text = 'Group path';
            app.GroupPath = uieditfield(app.mainGridLayout, 'text');
            app.LoadAsLabel = uilabel(app.mainGridLayout);
            app.LoadAsLabel.Text = 'Load as';
            app.LoadAs = uidropdown(app.mainGridLayout);
            app.DatasetModeLabel = uilabel(app.mainGridLayout);
            app.DatasetModeLabel.Text = 'Dataset mode';
            app.DatasetMode = uidropdown(app.mainGridLayout);
            app.helpButton = uibutton(app.mainGridLayout, 'push');
            app.helpButton.Icon = 'help_16px.png';
            app.helpButton.Text = '';
            app.openButton = uibutton(app.mainGridLayout, 'push');
            app.openButton.Text = 'Open';
            app.closeButton = uibutton(app.mainGridLayout, 'push');
            app.closeButton.Text = 'Close';
            app.Image = uiimage(app.mainGridLayout);
            app.Image.ImageSource = 'puffin_import_96px.png';
            app.ChunkCacheMBLabel = uilabel(app.mainGridLayout);
            app.ChunkCacheMBLabel.Text = 'Chunk Cache';
            app.chunkCacheMB = uieditfield(app.mainGridLayout, 'numeric');
            app.chunkCacheMB.Limits = [1 Inf];
            app.chunkCacheMB.RoundFractionalValues = 'on';
            app.chunkCacheMB.ValueDisplayFormat = '%d MB';
            app.chunkCacheMB.Value = 500;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectFromUrlGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end