classdef SelectHDFSeriesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gui                       matlab.ui.Figure
        mainGridLayout            matlab.ui.container.GridLayout
        seriesTable               matlab.ui.control.Table
        selectdatasettoloadLabel  matlab.ui.control.Label
        cancelButton              matlab.ui.control.Button
        continueButton            matlab.ui.control.Button
        readMetadata              matlab.ui.control.CheckBox
        selectedSeries            matlab.ui.control.Label
        reorderDims               matlab.ui.control.CheckBox
        newDimOrder               matlab.ui.control.EditField
        selectedseriesLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        handles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.handles.selectdatasettoloadLabel = app.selectdatasettoloadLabel;
            app.handles.seriesTable = app.seriesTable;
            app.handles.selectedSeries = app.selectedSeries;
            app.handles.reorderDims = app.reorderDims;
            app.handles.newDimOrder = app.newDimOrder;
            app.handles.readMetadata = app.readMetadata;
            app.handles.continueButton = app.continueButton;
            app.handles.cancelButton = app.cancelButton;
            app.gui.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gui = uifigure('Visible', 'off');
            app.gui.Name = 'Select HDF5 series';
            app.mainGridLayout = uigridlayout(app.gui);
            app.selectedseriesLabel = uilabel(app.mainGridLayout);
            app.selectedseriesLabel.Text = 'Selected series:';
            app.newDimOrder = uieditfield(app.mainGridLayout, 'text');
            app.newDimOrder.Tag = 'newDimOrder';
            app.newDimOrder.Enable = 'off';
            app.newDimOrder.Value = 'YXCZT';
            app.reorderDims = uicheckbox(app.mainGridLayout);
            app.reorderDims.Tag = 'reorderDims';
            app.reorderDims.Tooltip = {'To rearrange the order of dimensions to match MIB3 order of YXZCT, provide the new order sequence'};
            app.reorderDims.Text = 'Reorder dimensions to match MIB order [Y,X,Z,C,T]';
            app.selectedSeries = uilabel(app.mainGridLayout);
            app.selectedSeries.Tag = 'selectedSeries';
            app.selectedSeries.Text = 'Selected series';
            app.readMetadata = uicheckbox(app.mainGridLayout);
            app.readMetadata.Tag = 'readMetadata';
            app.readMetadata.Tooltip = {'Read embedded metadata'};
            app.readMetadata.Text = 'read all parameters';
            app.continueButton = uibutton(app.mainGridLayout, 'push');
            app.continueButton.Tag = 'continueButton';
            app.continueButton.Text = 'Continue';
            app.cancelButton = uibutton(app.mainGridLayout, 'push');
            app.cancelButton.Tag = 'cancelButton';
            app.cancelButton.Text = 'Cancel';
            app.selectdatasettoloadLabel = uilabel(app.mainGridLayout);
            app.selectdatasettoloadLabel.Text = 'Select dataset to load';
            app.seriesTable = uitable(app.mainGridLayout);
            app.seriesTable.ColumnName = {'Name'; 'Y'; 'X'; 'Z'; 'C'; 'T'; 'Data type'};
            app.seriesTable.RowName = {};
            app.seriesTable.SelectionType = 'row';
            app.seriesTable.Tag = 'seriesTable';
            % Show the figure after all components are created
            app.gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectHDFSeriesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gui)
        end
    end
end