classdef SelectLociSeriesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gui                     matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        selectedSeriesText2     matlab.ui.control.Label
        parametersCheckbox      matlab.ui.control.CheckBox
        PreviewPanel            matlab.ui.container.Panel
        stretchContrast         matlab.ui.control.CheckBox
        sliceNumberEdit         matlab.ui.control.Spinner
        sliceNumberSlider       matlab.ui.control.Slider
        sliceNumberSliderLabel  matlab.ui.control.Label
        previewCheck            matlab.ui.control.CheckBox
        infoLabel               matlab.ui.control.Label
        selectedSeriesText      matlab.ui.control.Label
        selectedSerieLabel      matlab.ui.control.Label
        seriesTable             matlab.ui.control.Table
        cancelBtn               matlab.ui.control.Button
        continueBtn             matlab.ui.control.Button
        imagePreview            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        handles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui.Visible = false;

            app.handles.seriesTable = app.seriesTable;
            app.handles.imagePreview = app.imagePreview;
            app.handles.infoLabel = app.infoLabel;
            app.handles.previewCheck = app.previewCheck;
            app.handles.stretchContrast = app.stretchContrast;
            app.handles.sliceNumberSlider = app.sliceNumberSlider;
            app.handles.sliceNumberEdit = app.sliceNumberEdit;
            app.handles.selectedSeriesText = app.selectedSeriesText;
            app.handles.selectedSeriesText2 = app.selectedSeriesText2;
            app.handles.continueBtn = app.continueBtn;
            app.handles.cancelBtn = app.cancelBtn;
            % make tight, otherwise the image does not fill the whole frame
            app.imagePreview.XLimitMethod = 'tight';
            app.imagePreview.YLimitMethod = 'tight';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gui = uifigure('Visible', 'off');
            app.gui.Name = 'Select BioFormats series';
            app.GridLayout = uigridlayout(app.gui);
            app.imagePreview = uiaxes(app.GridLayout);
            app.imagePreview.XTick = [];
            app.imagePreview.XTickLabel = '';
            app.imagePreview.YTick = [];
            app.imagePreview.ZTick = [];
            app.continueBtn = uibutton(app.GridLayout, 'push');
            app.continueBtn.Text = 'Continue';
            app.cancelBtn = uibutton(app.GridLayout, 'push');
            app.cancelBtn.Text = 'Cancel';
            app.seriesTable = uitable(app.GridLayout);
            app.seriesTable.ColumnName = {'Name'; 'Width'; 'Height'; 'Colors'; 'Planes'; 'Time'};
            app.seriesTable.RowName = {};
            app.seriesTable.SelectionType = 'row';
            app.selectedSerieLabel = uilabel(app.GridLayout);
            app.selectedSerieLabel.Text = 'Selected serie:';
            app.selectedSeriesText = uilabel(app.GridLayout);
            app.selectedSeriesText.Text = 'selectedSeriesText';
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewPanel.Title = 'Preview';
            app.infoLabel = uilabel(app.PreviewPanel);
            app.infoLabel.Text = {'Please select desired series from the table above.'; 'It is possible to select multiple series,'; 'but the series should have the same dimensions!'};
            app.previewCheck = uicheckbox(app.PreviewPanel);
            app.previewCheck.Tag = 'previewCheck';
            app.previewCheck.Text = 'image preview';
            app.sliceNumberSliderLabel = uilabel(app.PreviewPanel);
            app.sliceNumberSliderLabel.Text = 'Slice numner to preview';
            app.sliceNumberSlider = uislider(app.PreviewPanel);
            app.sliceNumberSlider.Limits = [1 100];
            app.sliceNumberSlider.Step = 1;
            app.sliceNumberSlider.Value = 1;
            app.sliceNumberEdit = uispinner(app.PreviewPanel);
            app.sliceNumberEdit.Limits = [1 Inf];
            app.sliceNumberEdit.RoundFractionalValues = 'on';
            app.sliceNumberEdit.ValueDisplayFormat = '%d';
            app.sliceNumberEdit.Value = 1;
            app.stretchContrast = uicheckbox(app.PreviewPanel);
            app.stretchContrast.Tag = 'stretchContrast';
            app.stretchContrast.Text = 'stretch contrast';
            app.stretchContrast.Value = true;
            app.parametersCheckbox = uicheckbox(app.GridLayout);
            app.parametersCheckbox.Text = 'Read all parameters';
            app.parametersCheckbox.Value = true;
            app.selectedSeriesText2 = uilabel(app.GridLayout);
            app.selectedSeriesText2.Text = 'selectedSeriesText';
            % Show the figure after all components are created
            app.gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectLociSeriesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gui)
        end
    end
end