classdef SnapshotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                        matlab.ui.Figure
        mainGridLayout                matlab.ui.container.GridLayout
        CropButtonGroup               matlab.ui.container.ButtonGroup
        ROIIndex                      matlab.ui.control.DropDown
        ROI                           matlab.ui.control.RadioButton
        ShownArea                     matlab.ui.control.RadioButton
        FullImage                     matlab.ui.control.RadioButton
        TargetButtonGroup             matlab.ui.container.ButtonGroup
        File                          matlab.ui.control.RadioButton
        Clipboard                     matlab.ui.control.RadioButton
        filePanel                     matlab.ui.container.Panel
        fileGridLayout                matlab.ui.container.GridLayout
        outputDir                     matlab.ui.control.EditField
        OutputfilenameEditFieldLabel  matlab.ui.control.Label
        selectFileBtn                 matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        bmpTab                        matlab.ui.container.Tab
        jpgTab                        matlab.ui.container.Tab
        jpgGridLayout                 matlab.ui.container.GridLayout
        jpgComment                    matlab.ui.control.EditField
        CommentEditFieldLabel         matlab.ui.control.Label
        jpgBitdepth                   matlab.ui.control.DropDown
        BitdepthDropDownLabel         matlab.ui.control.Label
        JPGmode                       matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        JPGquality                    matlab.ui.control.NumericEditField
        QualityEditFieldLabel         matlab.ui.control.Label
        pngTab                        matlab.ui.container.Tab
        tifTab                        matlab.ui.container.Tab
        tifGridLayout                 matlab.ui.container.GridLayout
        tifDescription                matlab.ui.control.EditField
        DescriptionEditFieldLabel     matlab.ui.control.Label
        tifRowsPerStrip               matlab.ui.control.NumericEditField
        tifRowsPerStripLabel          matlab.ui.control.Label
        tifResolution                 matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        tifColor                      matlab.ui.control.DropDown
        ColorspaceDropDownLabel       matlab.ui.control.Label
        TIFcompression                matlab.ui.control.DropDown
        CompressionDropDownLabel      matlab.ui.control.Label
        FileFormat                    matlab.ui.control.DropDown
        FileformatDropDownLabel       matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        measurementsOptions           matlab.ui.control.Button
        WhiteBackground               matlab.ui.control.CheckBox
        Measurements                  matlab.ui.control.CheckBox
        Scalebar                      matlab.ui.control.CheckBox
        Margin                        matlab.ui.control.Spinner
        MarginSpinnerLabel            matlab.ui.control.Label
        RowsNumber                    matlab.ui.control.Spinner
        RowsSpinnerLabel              matlab.ui.control.Label
        ColsNumber                    matlab.ui.control.Spinner
        ColsSpinnerLabel              matlab.ui.control.Label
        Grayscale                     matlab.ui.control.CheckBox
        SplitChannels                 matlab.ui.control.CheckBox
        ResizePanel                   matlab.ui.container.Panel
        resizeGridLayout              matlab.ui.container.GridLayout
        bin8Btn                       matlab.ui.control.Button
        bin4Btn                       matlab.ui.control.Button
        bin2Btn                       matlab.ui.control.Button
        binCheck                      matlab.ui.control.CheckBox
        ResizeMethod                  matlab.ui.control.DropDown
        ResizingmethodDropDownLabel   matlab.ui.control.Label
        Height                        matlab.ui.control.EditField
        HeightEditFieldLabel          matlab.ui.control.Label
        Width                         matlab.ui.control.EditField
        WidthEditFieldLabel           matlab.ui.control.Label
        helpButton                    matlab.ui.control.Button
        snapshotBtn                   matlab.ui.control.Button
        closelBtn                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Button pushed function: closelBtn
        function closelBtnButtonPushed(app, event)
            app.winController.closeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Snapshot';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.closelBtn = uibutton(app.mainGridLayout, 'push');
            app.closelBtn.ButtonPushedFcn = createCallbackFcn(app, @closelBtnButtonPushed, true);
            app.closelBtn.Text = 'Close';
            app.snapshotBtn = uibutton(app.mainGridLayout, 'push');
            app.snapshotBtn.Tooltip = {'make a snapshot'};
            app.snapshotBtn.Text = 'Make snapshot';
            app.helpButton = uibutton(app.mainGridLayout, 'push');
            app.helpButton.Tooltip = {''};
            app.helpButton.Text = 'Help';
            app.ResizePanel = uipanel(app.mainGridLayout);
            app.ResizePanel.Title = 'Resize';
            app.resizeGridLayout = uigridlayout(app.ResizePanel);
            app.WidthEditFieldLabel = uilabel(app.resizeGridLayout);
            app.WidthEditFieldLabel.Text = 'Width';
            app.Width = uieditfield(app.resizeGridLayout, 'text');
            app.Width.CharacterLimits = [1 Inf];
            app.Width.InputType = 'digits';
            app.Width.Tooltip = {'enter width of the snapshot'};
            app.Width.Value = '1024';
            app.HeightEditFieldLabel = uilabel(app.resizeGridLayout);
            app.HeightEditFieldLabel.Text = 'Height';
            app.Height = uieditfield(app.resizeGridLayout, 'text');
            app.Height.CharacterLimits = [1 Inf];
            app.Height.InputType = 'digits';
            app.Height.Tooltip = {'enter height of the snapshot'};
            app.Height.Value = '1024';
            app.ResizingmethodDropDownLabel = uilabel(app.resizeGridLayout);
            app.ResizingmethodDropDownLabel.Text = 'Resizing method';
            app.ResizeMethod = uidropdown(app.resizeGridLayout);
            app.ResizeMethod.Items = {'nearest', 'bilinear', 'bicubic'};
            app.ResizeMethod.Value = 'nearest';
            app.binCheck = uicheckbox(app.resizeGridLayout);
            app.binCheck.Tooltip = {'select to enable the bin2, bin4, bin8 buttons; unselect to enable mag2, mag4, mag8 buttons'};
            app.binCheck.Text = 'bin';
            app.binCheck.Value = true;
            app.bin2Btn = uibutton(app.resizeGridLayout, 'push');
            app.bin2Btn.Tooltip = {'Reduce dimensions of the snapshot in 2 times'};
            app.bin2Btn.Text = 'bin x2';
            app.bin4Btn = uibutton(app.resizeGridLayout, 'push');
            app.bin4Btn.Tooltip = {'Reduce dimensions of the snapshot in 4 times'};
            app.bin4Btn.Text = 'bin x4';
            app.bin8Btn = uibutton(app.resizeGridLayout, 'push');
            app.bin8Btn.Tooltip = {'Reduce dimensions of the snapshot in 8 times'};
            app.bin8Btn.Text = 'bin x8';
            app.OptionsPanel = uipanel(app.mainGridLayout);
            app.OptionsPanel.Title = 'Options';
            app.SplitChannels = uicheckbox(app.OptionsPanel);
            app.SplitChannels.Tooltip = {'generate a montage image where each color channel is shown in a separate panel'};
            app.SplitChannels.Text = 'Split channels';
            app.Grayscale = uicheckbox(app.OptionsPanel);
            app.Grayscale.Tooltip = {'render each color channel in grayscale'};
            app.Grayscale.Enable = 'off';
            app.Grayscale.Text = 'Grayscale';
            app.ColsSpinnerLabel = uilabel(app.OptionsPanel);
            app.ColsSpinnerLabel.Text = 'Cols:';
            app.ColsNumber = uispinner(app.OptionsPanel);
            app.ColsNumber.Limits = [1 Inf];
            app.ColsNumber.RoundFractionalValues = 'on';
            app.ColsNumber.ValueDisplayFormat = '%d';
            app.ColsNumber.Tooltip = {'define number of horizontal panels in the montage image'};
            app.ColsNumber.Value = 2;
            app.RowsSpinnerLabel = uilabel(app.OptionsPanel);
            app.RowsSpinnerLabel.Text = 'Rows:';
            app.RowsNumber = uispinner(app.OptionsPanel);
            app.RowsNumber.Limits = [1 Inf];
            app.RowsNumber.RoundFractionalValues = 'on';
            app.RowsNumber.ValueDisplayFormat = '%d';
            app.RowsNumber.Tooltip = {'define number of vertical panels in the montage image'};
            app.RowsNumber.Value = 2;
            app.MarginSpinnerLabel = uilabel(app.OptionsPanel);
            app.MarginSpinnerLabel.Text = 'Margin:';
            app.Margin = uispinner(app.OptionsPanel);
            app.Margin.Limits = [0 Inf];
            app.Margin.RoundFractionalValues = 'on';
            app.Margin.ValueDisplayFormat = '%d';
            app.Margin.Tooltip = {'margins for the panels'};
            app.Margin.Value = 10;
            app.Scalebar = uicheckbox(app.OptionsPanel);
            app.Scalebar.Tooltip = {'add scale bar to the snapshot'};
            app.Scalebar.Text = 'Scale bar';
            app.Measurements = uicheckbox(app.OptionsPanel);
            app.Measurements.Text = 'Measurements';
            app.WhiteBackground = uicheckbox(app.OptionsPanel);
            app.WhiteBackground.Tooltip = {'enable to render white background'};
            app.WhiteBackground.Text = 'whiteBg';
            app.WhiteBackground.Value = true;
            app.measurementsOptions = uibutton(app.OptionsPanel, 'push');
            app.measurementsOptions.Icon = 'settings_16px.png';
            app.measurementsOptions.Enable = 'off';
            app.measurementsOptions.Tooltip = {'update visualization options for the measurements'};
            app.measurementsOptions.Text = '';
            app.filePanel = uipanel(app.mainGridLayout);
            app.filePanel.Title = 'File format';
            app.fileGridLayout = uigridlayout(app.filePanel);
            app.FileformatDropDownLabel = uilabel(app.fileGridLayout);
            app.FileformatDropDownLabel.Text = 'File format';
            app.FileFormat = uidropdown(app.fileGridLayout);
            app.FileFormat.Items = {'BMP', 'JPG', 'PNG', 'TIF'};
            app.FileFormat.Value = 'TIF';
            app.TabGroup = uitabgroup(app.fileGridLayout);
            app.bmpTab = uitab(app.TabGroup);
            app.bmpTab.Title = 'BMP';
            app.jpgTab = uitab(app.TabGroup);
            app.jpgTab.Tooltip = {'A scalar value indicating desired bitdepth; for grayscale images this can be 8, 12, or 16; for color images this can be 8 or 12. '};
            app.jpgTab.Title = 'JPG';
            app.jpgGridLayout = uigridlayout(app.jpgTab);
            app.jpgGridLayout.Tooltip = {'A number between 0 and 100; higher numbers mean higher quality (less image degradation due to compression), but the resulting file size is larger. '};
            app.QualityEditFieldLabel = uilabel(app.jpgGridLayout);
            app.QualityEditFieldLabel.Text = 'Quality';
            app.JPGquality = uieditfield(app.jpgGridLayout, 'numeric');
            app.JPGquality.Limits = [0 100];
            app.JPGquality.RoundFractionalValues = 'on';
            app.JPGquality.ValueDisplayFormat = '%d';
            app.JPGquality.Value = 95;
            app.ModeDropDownLabel = uilabel(app.jpgGridLayout);
            app.ModeDropDownLabel.Text = 'Mode';
            app.JPGmode = uidropdown(app.jpgGridLayout);
            app.JPGmode.Items = {'lossy', 'lossless'};
            app.JPGmode.Value = 'lossy';
            app.BitdepthDropDownLabel = uilabel(app.jpgGridLayout);
            app.BitdepthDropDownLabel.Text = 'Bitdepth';
            app.jpgBitdepth = uidropdown(app.jpgGridLayout);
            app.jpgBitdepth.Items = {'8', '12', '16'};
            app.jpgBitdepth.Value = '8';
            app.CommentEditFieldLabel = uilabel(app.jpgGridLayout);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.jpgComment = uieditfield(app.jpgGridLayout, 'text');
            app.jpgComment.Value = 'Microscopy Image Browser snapshot';
            app.pngTab = uitab(app.TabGroup);
            app.pngTab.Title = 'PNG';
            app.tifTab = uitab(app.TabGroup);
            app.tifTab.Title = 'TIF';
            app.tifGridLayout = uigridlayout(app.tifTab);
            app.CompressionDropDownLabel = uilabel(app.tifGridLayout);
            app.CompressionDropDownLabel.Text = 'Compression';
            app.TIFcompression = uidropdown(app.tifGridLayout);
            app.TIFcompression.Items = {'none', 'packbits', 'lzw', 'deflate', 'jpeg', 'ccitt', 'fax3', 'fax4'};
            app.TIFcompression.Value = 'lzw';
            app.ColorspaceDropDownLabel = uilabel(app.tifGridLayout);
            app.ColorspaceDropDownLabel.Text = 'Color space';
            app.tifColor = uidropdown(app.tifGridLayout);
            app.tifColor.Items = {'rgb', 'cielab', 'icclab'};
            app.tifColor.Value = 'rgb';
            app.ResolutionEditFieldLabel = uilabel(app.tifGridLayout);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.tifResolution = uieditfield(app.tifGridLayout, 'numeric');
            app.tifResolution.Limits = [1 Inf];
            app.tifResolution.RoundFractionalValues = 'on';
            app.tifResolution.ValueDisplayFormat = '%d';
            app.tifResolution.Tooltip = {'A scalar indicating both the XResolution and YResolution. The default value is 72.'};
            app.tifResolution.Value = 72;
            app.tifRowsPerStripLabel = uilabel(app.tifGridLayout);
            app.tifRowsPerStripLabel.Text = 'tifRowsPerStrip';
            app.tifRowsPerStrip = uieditfield(app.tifGridLayout, 'numeric');
            app.tifRowsPerStrip.Limits = [1 Inf];
            app.tifRowsPerStrip.RoundFractionalValues = 'on';
            app.tifRowsPerStrip.ValueDisplayFormat = '%d';
            app.tifRowsPerStrip.Tooltip = {'You must specify the ''RowsPerStrip'' parameter if you specify ''jpeg'' compression. The value must be a multiple of 8'};
            app.tifRowsPerStrip.Value = 8000;
            app.DescriptionEditFieldLabel = uilabel(app.tifGridLayout);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.tifDescription = uieditfield(app.tifGridLayout, 'text');
            app.tifDescription.Value = 'Microscopy Image Browser snapshot';
            app.selectFileBtn = uibutton(app.fileGridLayout, 'push');
            app.selectFileBtn.Text = '...';
            app.OutputfilenameEditFieldLabel = uilabel(app.fileGridLayout);
            app.OutputfilenameEditFieldLabel.Text = 'Output filename:';
            app.outputDir = uieditfield(app.fileGridLayout, 'text');
            app.outputDir.Value = 'c:\snapshot.tif';
            app.TargetButtonGroup = uibuttongroup(app.mainGridLayout);
            app.TargetButtonGroup.Title = 'Target';
            app.Clipboard = uiradiobutton(app.TargetButtonGroup);
            app.Clipboard.Tooltip = {'copy snapshot to clipboard'};
            app.Clipboard.Text = 'Clipboard';
            app.Clipboard.Value = true;
            app.File = uiradiobutton(app.TargetButtonGroup);
            app.File.Tooltip = {'save snapshot into a file'};
            app.File.Text = 'File';
            app.CropButtonGroup = uibuttongroup(app.mainGridLayout);
            app.CropButtonGroup.Title = 'Crop';
            app.FullImage = uiradiobutton(app.CropButtonGroup);
            app.FullImage.Text = 'Full image';
            app.FullImage.Value = true;
            app.ShownArea = uiradiobutton(app.CropButtonGroup);
            app.ShownArea.Text = 'Shown area';
            app.ROI = uiradiobutton(app.CropButtonGroup);
            app.ROI.Tooltip = {'make snapshot using the selected ROI'};
            app.ROI.Text = 'ROI';
            app.ROIIndex = uidropdown(app.CropButtonGroup);
            app.ROIIndex.Items = {'1', '2', '3'};
            app.ROIIndex.Enable = 'off';
            app.ROIIndex.Value = '1';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnapshotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end