classdef TipsAppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure            matlab.ui.Figure
        figureGridLayout  matlab.ui.container.GridLayout
        webBrowser        matlab.ui.control.HTML
        bottomGridLayout  matlab.ui.container.GridLayout
        showTipsCheck     matlab.ui.control.CheckBox
        previousTipBtn    matlab.ui.control.Button
        nextTipBtn        matlab.ui.control.Button
        closeBtn          matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: closeBtn
        function closeBtnButtonPushed(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: previousTipBtn
        function previousTipBtnButtonPushed(app, event)
            app.winController.previousTipBtn_Callback();
        end

        % Button pushed function: nextTipBtn
        function nextTipBtnButtonPushed(app, event)
            app.winController.nextTipBtn_Callback();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Tip of the Day';
            app.Figure.Icon = 'bulb_16px.png';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.figureGridLayout = uigridlayout(app.Figure);
            app.bottomGridLayout = uigridlayout(app.figureGridLayout);
            app.closeBtn = uibutton(app.bottomGridLayout, 'push');
            app.closeBtn.ButtonPushedFcn = createCallbackFcn(app, @closeBtnButtonPushed, true);
            app.closeBtn.Text = 'Close';
            app.nextTipBtn = uibutton(app.bottomGridLayout, 'push');
            app.nextTipBtn.ButtonPushedFcn = createCallbackFcn(app, @nextTipBtnButtonPushed, true);
            app.nextTipBtn.Text = 'Next tip';
            app.previousTipBtn = uibutton(app.bottomGridLayout, 'push');
            app.previousTipBtn.ButtonPushedFcn = createCallbackFcn(app, @previousTipBtnButtonPushed, true);
            app.previousTipBtn.Text = 'Previous tip';
            app.showTipsCheck = uicheckbox(app.bottomGridLayout);
            app.showTipsCheck.Text = 'show tips on startup';
            app.webBrowser = uihtml(app.figureGridLayout);
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TipsAppGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end