classdef VolRenAppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                         matlab.ui.Figure
        menuFile                       matlab.ui.container.Menu
        menuFileLoadAnimationPath      matlab.ui.container.Menu
        menuFileSaveAnimationPath      matlab.ui.container.Menu
        menuFileMakeSnapshot           matlab.ui.container.Menu
        menuFileMakeSpinMovie          matlab.ui.container.Menu
        menuFileMakeAnimationMovie     matlab.ui.container.Menu
        menuView                       matlab.ui.container.Menu
        menuDefaultView                matlab.ui.container.Menu
        menuXYview                     matlab.ui.container.Menu
        menuXZview                     matlab.ui.container.Menu
        menuYZview                     matlab.ui.container.Menu
        menuTools                      matlab.ui.container.Menu
        menuSettings                   matlab.ui.container.Menu
        menuBackgroundColor            matlab.ui.container.Menu
        menuBackgroundGradientColor    matlab.ui.container.Menu
        menuBackgroundGradient         matlab.ui.container.Menu
        bottomTabGroup                 matlab.ui.container.TabGroup
        animationTab                   matlab.ui.container.Tab
        animationGridLayout            matlab.ui.container.GridLayout
        animationBottomPanel           matlab.ui.container.Panel
        deleteAllKeyFramesButton       matlab.ui.control.Button
        noFramesEditField              matlab.ui.control.NumericEditField
        NoframesEditFieldLabel         matlab.ui.control.Label
        autoJumpCheckBox               matlab.ui.control.CheckBox
        addKeyFrameButton              matlab.ui.control.Button
        spinAxis                       matlab.ui.control.DropDown
        spinTestButton                 matlab.ui.control.Button
        previewAnimationButton         matlab.ui.control.Button
        keyFrameTable                  matlab.ui.control.Table
        mainGridLayout                 matlab.ui.container.GridLayout
        topTabGroup                    matlab.ui.container.TabGroup
        viewerTab                      matlab.ui.container.Tab
        viewerGridLayout               matlab.ui.container.GridLayout
        viewerTopPanel                 matlab.ui.container.Panel
        ControlsPanel                  matlab.ui.container.Panel
        liveUpdateCheckBox             matlab.ui.control.CheckBox
        rotationMode                   matlab.ui.control.DropDown
        RotationmodeLabel              matlab.ui.control.Label
        LightsPanel                    matlab.ui.container.Panel
        DiffuseLight                   matlab.ui.control.Spinner
        DiffuselightSpinnerLabel       matlab.ui.control.Label
        AmbientLight                   matlab.ui.control.Spinner
        AmbientlightSpinnerLabel       matlab.ui.control.Label
        Help                           matlab.ui.control.StateButton
        showBox                        matlab.ui.control.CheckBox
        showOrientationAxes            matlab.ui.control.CheckBox
        showScaleBar                   matlab.ui.control.CheckBox
        cameraPanel                    matlab.ui.container.Panel
        cameraGridLayout               matlab.ui.container.GridLayout
        cameraUpVectorZ                matlab.ui.control.NumericEditField
        cameraUpVectorY                matlab.ui.control.NumericEditField
        cameraUpVectorX                matlab.ui.control.NumericEditField
        UpvectorEditFieldLabel         matlab.ui.control.Label
        cameraTargetZ                  matlab.ui.control.NumericEditField
        cameraTargetY                  matlab.ui.control.NumericEditField
        cameraTargetX                  matlab.ui.control.NumericEditField
        TargetEditFieldLabel           matlab.ui.control.Label
        cameraPositionZ                matlab.ui.control.NumericEditField
        cameraPositionY                matlab.ui.control.NumericEditField
        cameraPositionX                matlab.ui.control.NumericEditField
        PositionEditFieldLabel         matlab.ui.control.Label
        cameraZoomEdit                 matlab.ui.control.NumericEditField
        ZoomEditFieldLabel             matlab.ui.control.Label
        cameraDistanceEdit             matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        volumeTab                      matlab.ui.container.Tab
        volumeGridLayout               matlab.ui.container.GridLayout
        slicesGridLayout               matlab.ui.container.GridLayout
        zNextSlice                     matlab.ui.control.Button
        yNextSlice                     matlab.ui.control.Button
        xNextSlice                     matlab.ui.control.Button
        zPrevSlice                     matlab.ui.control.Button
        yPrevSlice                     matlab.ui.control.Button
        xPrevSlice                     matlab.ui.control.Button
        zSliderEdit                    matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ySliderEdit                    matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        xSliderEdit                    matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        zSlider                        matlab.ui.control.Slider
        ySlider                        matlab.ui.control.Slider
        xSlider                        matlab.ui.control.Slider
        slicesLabel                    matlab.ui.control.Label
        volumeTopPanel                 matlab.ui.container.Panel
        colormapWhitePoint             matlab.ui.control.NumericEditField
        WhitepointEditFieldLabel       matlab.ui.control.Label
        colormapBlackPoint             matlab.ui.control.NumericEditField
        BlackpointEditFieldLabel       matlab.ui.control.Label
        colormapInvert                 matlab.ui.control.CheckBox
        colormapName                   matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        transparentVolumeCheckBox      matlab.ui.control.CheckBox
        isovalueLabel                  matlab.ui.control.Label
        isovalueEdit                   matlab.ui.control.NumericEditField
        isovalueSlider                 matlab.ui.control.Slider
        showVolumeCheckBox             matlab.ui.control.CheckBox
        rendererDropDown               matlab.ui.control.DropDown
        RendererDropDownLabel          matlab.ui.control.Label
        colormapAxes                   matlab.ui.control.UIAxes
        aphaCurveBottomGridLayout      matlab.ui.container.GridLayout
        AlphacurveLabel                matlab.ui.control.Label
        resetAlphaCurve                matlab.ui.control.Button
        invertAlphaCurve               matlab.ui.control.Button
        alphaAxes                      matlab.ui.control.UIAxes
        modelTab                       matlab.ui.container.Tab
        modelGridLayout                matlab.ui.container.GridLayout
        modelTopPanel                  matlab.ui.container.Panel
        modelRightGridLayout           matlab.ui.container.GridLayout
        refreshOverlayButton           matlab.ui.control.Button
        overlayRenderingStyle          matlab.ui.control.DropDown
        RenderingstyleDropDownLabel    matlab.ui.control.Label
        modelHideAllCheckBox           matlab.ui.control.CheckBox
        addCurrentMaterialButton       matlab.ui.control.Button
        overlayMaterialsList           matlab.ui.control.EditField
        overlayMaterialsMode           matlab.ui.control.DropDown
        MaterialstoshowDropDownLabel   matlab.ui.control.Label
        modelUpdateOverlay             matlab.ui.control.StateButton
        overlaySourceDropDown          matlab.ui.control.DropDown
        OverlaysourcetypeLabel         matlab.ui.control.Label
        modelTable                     matlab.ui.control.Table
        SurfacesTab                    matlab.ui.container.Tab
        surfacesGridLayout             matlab.ui.container.GridLayout
        surfaceTable                   matlab.ui.control.Table
        surfacesHideAll                matlab.ui.control.CheckBox
        surfaceTopPanel                matlab.ui.container.Panel
        keyFrameTableContextMenu       matlab.ui.container.ContextMenu
        keyFrameTable_cm_jumpToKeyFrame  matlab.ui.container.Menu
        keyFrameTable_cm_insertKeyFrame  matlab.ui.container.Menu
        keyFrameTable_cm_replaceKeyFrame  matlab.ui.container.Menu
        keyFrameTable_cm_removeKeyFrame  matlab.ui.container.Menu
        modelTableContextMenu          matlab.ui.container.ContextMenu
        modelTable_cm_generateSurface  matlab.ui.container.Menu
        modelTable_cm_generateSurfaceByIndex  matlab.ui.container.Menu
        modelTable_cm_removeMaterials  matlab.ui.container.Menu
        surfaceTableContextMenu        matlab.ui.container.ContextMenu
        surfaceTable_cm_saveSurface    matlab.ui.container.Menu
        surfaceTable_cm_removeSurface  matlab.ui.container.Menu
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');

            app.bottomTabGroup.Parent = app.mainGridLayout;

            % remove lines in app.colormapAxes
            app.colormapAxes.XAxis.Color = 'none';
            app.colormapAxes.YAxis.Color = 'none';
            % remove lines in app.alphaAxes
            app.alphaAxes.XAxis.Color = 'none';
            app.alphaAxes.YAxis.Color = 'none';

        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.showHelp();
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Value changed function: rendererDropDown
        function rendererDropDownValueChanged(app, event)
            app.winController.updateVolumeRenderingStyle();
        end

        % Value changing function: isovalueSlider
        function isovalueSliderValueChanging(app, event)
            app.isovalueEdit.Value = event.Value;
            app.isovalueEditValueChanged(event)
        end

        % Value changed function: isovalueEdit
        function isovalueEditValueChanged(app, event)
            app.isovalueSlider.Value = event.Value;
            app.winController.updateIsovalue(app.isovalueEdit.Value);
        end

        % Button down function: alphaAxes
        function alphaAxesButtonDown(app, event)
            app.winController.alphaAxesButtonDown(event);
        end

        % Value changed function: AmbientLight, DiffuseLight, 
        % ...and 4 other components
        function toggleViewerSettings(app, event)
            app.winController.toggleViewerSettings(event)
        end

        % Menu selected function: menuBackgroundColor, 
        % ...and 2 other components
        function updateBackgroundColor(app, event)
            app.winController.updateBackgroundColor(event);
        end

        % Button pushed function: invertAlphaCurve, resetAlphaCurve
        function alphaCurveOperations(app, event)
            app.winController.alphaCurveOperations(event);
        end

        % Value changed function: colormapBlackPoint, colormapInvert, 
        % ...and 2 other components
        function updateColormap(app, event)
            app.winController.updateColormap(event);
        end

        % Menu selected function: menuDefaultView, menuXYview, menuXZview, 
        % ...and 1 other component
        function changeView(app, event)
            app.winController.menuChangeView(event);
        end

        % Button pushed function: spinTestButton
        function spinTestButtonPushed(app, event)
            app.winController.spinDataset();
        end

        % Button pushed function: addKeyFrameButton
        function addKeyFrameButtonPushed(app, event)
            app.winController.addAnimationKeyFrame();
        end

        % Cell selection callback: keyFrameTable
        function keyFrameTableCellSelection(app, event)
            indices = event.Indices; % indices(1) -> row, indices(2) -> column
            app.winController.keyFrameTable_CellSelection(indices(2));
        end

        % Button pushed function: deleteAllKeyFramesButton
        function deleteAllKeyFramesButtonPushed(app, event)
            app.winController.deleteAllAnimationKeyFrames();
        end

        % Button pushed function: previewAnimationButton
        function previewAnimationButtonPushed(app, event)
            app.winController.previewAnimation();
        end

        % Menu selected function: keyFrameTable_cm_insertKeyFrame, 
        % ...and 3 other components
        function keyFrameTable_cm_Callback(app, event)
            app.winController.keyFrameTable_cm_Callback(event);
        end

        % Value changed function: noFramesEditField
        function noFramesEditFieldValueChanged(app, event)
            app.winController.updateAnimationNumberOfFrames(app.noFramesEditField.Value);
        end

        % Menu selected function: menuFileLoadAnimationPath
        function menuFileLoadAnimationPathMenuSelected(app, event)
            app.winController.loadAnimationPath();
        end

        % Menu selected function: menuFileSaveAnimationPath
        function menuFileSaveAnimationPathMenuSelected(app, event)
            app.winController.saveAnimationPath();
        end

        % Menu selected function: menuFileMakeSnapshot
        function menuFileMakeSnapshotMenuSelected(app, event)
            app.winController.makeSnapshop();
        end

        % Menu selected function: menuFileMakeSpinMovie
        function menuFileMakeSpinMovieMenuSelected(app, event)
            app.winController.makeAnimation('spin');
        end

        % Menu selected function: menuFileMakeAnimationMovie
        function menuFileMakeAnimationMovieMenuSelected(app, event)
            app.winController.makeAnimation('animation');
        end

        % Value changed function: modelUpdateOverlay
        function modelUpdateOverlayValueChanged(app, event)
            app.winController.modelUpdateOverlay();
        end

        % Value changed function: showVolumeCheckBox
        function showVolumeCheckBoxValueChanged(app, event)
            app.winController.showVolume();
        end

        % Value changed function: cameraDistanceEdit, cameraPositionX, 
        % ...and 9 other components
        function updateCameraPosition(app, event)
            app.winController.updateCameraPosition(event);
        end

        % Cell edit callback: modelTable
        function modelTableCellEdit(app, event)
            app.winController.modelTableCellEdit(event);
        end

        % Menu selected function: modelTable_cm_generateSurface, 
        % ...and 2 other components
        function modelTable_cm_Callback(app, event)
            app.winController.modelTable_cm_Callback(event);
        end

        % Cell selection callback: modelTable
        function modelTableCellSelection(app, event)
            indices = event.Indices; % indices(1) -> row, indices(2) -> column
            app.winController.modelTable_CellSelection(unique(indices(:,1)));
        end

        % Cell edit callback: surfaceTable
        function surfaceTableCellEdit(app, event)
            app.winController.surfaceTableCellEdit(event);
        end

        % Cell selection callback: surfaceTable
        function surfaceTableCellSelection(app, event)
            app.winController.surfaceTable_CellSelection(event.Indices);
        end

        % Button down function: SurfacesTab, animationTab, bottomTabGroup, 
        % ...and 4 other components
        function tabGroupButtonDown(app, event)
            switch event.Source.Parent.Tag
                case 'topTabGroup'
                    app.mainGridLayout.RowHeight = {'1x', 28};
                    if strcmp(event.Source.Tag, 'viewerTab')
                        % update widgets that describe camera
                        app.winController.updateCameraWidgets();
                    end
                case 'bottomTabGroup'
                    app.mainGridLayout.RowHeight = {28, '1x'};
            end
        end

        % Value changing function: xSlider, ySlider, zSlider
        function sliceSliderChanging(app, event)
            switch event.Source.Tag
                case 'xSlider'
                    targetWidget = 'xSliderEdit';
                case 'ySlider'
                    targetWidget = 'ySliderEdit';
                case 'zSlider'
                    targetWidget = 'zSliderEdit';
            end
            app.(targetWidget).Value = event.Value;
            app.winController.changeSlice(targetWidget);
        end

        % Value changed function: xSliderEdit, ySliderEdit, zSliderEdit
        function changeSlice(app, event)
            value = app.xSliderEdit.Value;
            app.winController.changeSlice(event.Source.Tag);
        end

        % Button pushed function: xNextSlice, xPrevSlice, yNextSlice, 
        % ...and 3 other components
        function prevSlice(app, event)
            modifier = app.Figure.CurrentModifier;
            switch event.Source.Tag
                case 'xPrevSlice'
                    if app.xSliderEdit.Value == 1; return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.xSliderEdit.Value = max([app.xSliderEdit.Value-10 1]);
                            case 'control'
                                app.xSliderEdit.Value = 1;
                        end
                    else
                        app.xSliderEdit.Value = app.xSliderEdit.Value-1;
                    end
                    targetWidget = 'xSliderEdit';
                case 'yPrevSlice'
                    if app.ySliderEdit.Value == 1; return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.ySliderEdit.Value = max([app.ySliderEdit.Value-10 1]);
                            case 'control'
                                app.ySliderEdit.Value = 1;
                        end
                    else
                        app.ySliderEdit.Value = app.ySliderEdit.Value-1;
                    end
                    targetWidget = 'ySliderEdit';
                case 'zPrevSlice'
                    if app.zSliderEdit.Value == 1; return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.zSliderEdit.Value = max([app.zSliderEdit.Value-10 1]);
                            case 'control'
                                app.zSliderEdit.Value = 1;
                        end
                    else
                        app.zSliderEdit.Value = app.zSliderEdit.Value-1;
                    end
                    targetWidget = 'zSliderEdit';
                case 'xNextSlice'
                    if app.xSliderEdit.Value == app.xSliderEdit.Limits(2); return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.xSliderEdit.Value = min([app.xSliderEdit.Value+10 app.xSliderEdit.Limits(2)]);
                            case 'control'
                                app.xSliderEdit.Value = app.xSliderEdit.Limits(2);
                        end
                    else
                        app.xSliderEdit.Value = app.xSliderEdit.Value+1;
                    end
                    targetWidget = 'xSliderEdit';
                case 'yNextSlice'
                    if app.ySliderEdit.Value == app.ySliderEdit.Limits(2); return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.ySliderEdit.Value = min([app.ySliderEdit.Value+10 app.ySliderEdit.Limits(2)]);
                            case 'control'
                                app.ySliderEdit.Value = app.ySliderEdit.Limits(2);
                        end
                    else
                        app.ySliderEdit.Value = app.ySliderEdit.Value+1;
                    end
                    targetWidget = 'ySliderEdit';
                case 'zNextSlice'
                    if app.zSliderEdit.Value == app.zSliderEdit.Limits(2); return; end
                    if ~isempty(modifier)
                        switch modifier{1}
                            case 'shift'
                                app.zSliderEdit.Value = min([app.zSliderEdit.Value+10 app.zSliderEdit.Limits(2)]);
                            case 'control'
                                app.zSliderEdit.Value = app.zSliderEdit.Limits(2);
                        end
                    else
                        app.zSliderEdit.Value = app.zSliderEdit.Value+1;
                    end
                    targetWidget = 'zSliderEdit';
            end
            app.winController.changeSlice(targetWidget);
        end

        % Value changed function: transparentVolumeCheckBox
        function transparentVolumeCheckBoxValueChanged(app, event)
            app.winController.transparentVolume();
        end

        % Value changed function: modelHideAllCheckBox
        function modelHideAllCheckBoxValueChanged(app, event)
            value = app.modelHideAllCheckBox.Value;
            app.winController.modelHideAllMaterials(value);
        end

        % Menu selected function: surfaceTable_cm_removeSurface, 
        % ...and 1 other component
        function surfaceTable_cm_Callback(app, event)
            app.winController.surfaceTable_cm_Callback(event);
        end

        % Value changed function: overlayRenderingStyle
        function overlayRenderingStyleValueChanged(app, event)
            app.winController.updateOverlayRenderingStyle();
        end

        % Value changed function: liveUpdateCheckBox
        function liveUpdateCheckBoxValueChanged(app, event)
            app.winController.toggleLiveUpdate();
        end

        % Button pushed function: refreshOverlayButton
        function refreshOverlayButtonPushed(app, event)
            app.winController.refreshOverlay();
        end

        % Value changed function: overlayMaterialsMode
        function overlayMaterialsModeValueChanged(app, event)
            app.winController.overlayMaterialsModeChanged();
        end

        % Value changed function: overlayMaterialsList
        function overlayMaterialsListValueChanged(app, event)
            app.winController.applyOverlayMaterialsList();
        end

        % Button pushed function: addCurrentMaterialButton
        function addCurrentMaterialButtonPushed(app, event)
            app.winController.addCurrentMaterialToList();
        end

        % Value changed function: surfacesHideAll
        function surfacesHideAllValueChanged(app, event)
            app.winController.modelHideAllSurfaces(app.surfacesHideAll.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = '3D Controls';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.menuFile = uimenu(app.Figure);
            app.menuFile.Text = 'File ';
            app.menuFileLoadAnimationPath = uimenu(app.menuFile);
            app.menuFileLoadAnimationPath.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadAnimationPathMenuSelected, true);
            app.menuFileLoadAnimationPath.Text = 'Load animation path';
            app.menuFileSaveAnimationPath = uimenu(app.menuFile);
            app.menuFileSaveAnimationPath.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveAnimationPathMenuSelected, true);
            app.menuFileSaveAnimationPath.Text = 'Save animation path';
            app.menuFileMakeSnapshot = uimenu(app.menuFile);
            app.menuFileMakeSnapshot.MenuSelectedFcn = createCallbackFcn(app, @menuFileMakeSnapshotMenuSelected, true);
            app.menuFileMakeSnapshot.Separator = 'on';
            app.menuFileMakeSnapshot.Text = 'Make snapshot...';
            app.menuFileMakeSpinMovie = uimenu(app.menuFile);
            app.menuFileMakeSpinMovie.MenuSelectedFcn = createCallbackFcn(app, @menuFileMakeSpinMovieMenuSelected, true);
            app.menuFileMakeSpinMovie.Text = 'Make spin movie...';
            app.menuFileMakeAnimationMovie = uimenu(app.menuFile);
            app.menuFileMakeAnimationMovie.MenuSelectedFcn = createCallbackFcn(app, @menuFileMakeAnimationMovieMenuSelected, true);
            app.menuFileMakeAnimationMovie.Text = 'Make animation movie...';
            app.menuView = uimenu(app.Figure);
            app.menuView.Text = 'View ';
            app.menuDefaultView = uimenu(app.menuView);
            app.menuDefaultView.MenuSelectedFcn = createCallbackFcn(app, @changeView, true);
            app.menuDefaultView.Text = 'Default view';
            app.menuXYview = uimenu(app.menuView);
            app.menuXYview.MenuSelectedFcn = createCallbackFcn(app, @changeView, true);
            app.menuXYview.Text = 'XY view';
            app.menuXZview = uimenu(app.menuView);
            app.menuXZview.MenuSelectedFcn = createCallbackFcn(app, @changeView, true);
            app.menuXZview.Text = 'XZ view';
            app.menuYZview = uimenu(app.menuView);
            app.menuYZview.MenuSelectedFcn = createCallbackFcn(app, @changeView, true);
            app.menuYZview.Text = 'YZ view';
            app.menuTools = uimenu(app.Figure);
            app.menuTools.Text = 'Tools ';
            app.menuSettings = uimenu(app.Figure);
            app.menuSettings.Text = 'Settings ';
            app.menuBackgroundColor = uimenu(app.menuSettings);
            app.menuBackgroundColor.MenuSelectedFcn = createCallbackFcn(app, @updateBackgroundColor, true);
            app.menuBackgroundColor.Text = 'Background color';
            app.menuBackgroundGradientColor = uimenu(app.menuSettings);
            app.menuBackgroundGradientColor.MenuSelectedFcn = createCallbackFcn(app, @updateBackgroundColor, true);
            app.menuBackgroundGradientColor.Text = 'Background gradient color';
            app.menuBackgroundGradient = uimenu(app.menuSettings);
            app.menuBackgroundGradient.MenuSelectedFcn = createCallbackFcn(app, @updateBackgroundColor, true);
            app.menuBackgroundGradient.Checked = 'on';
            app.menuBackgroundGradient.Text = 'Background gradient';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.topTabGroup = uitabgroup(app.mainGridLayout);
            app.topTabGroup.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.viewerTab = uitab(app.topTabGroup);
            app.viewerTab.Title = 'Viewer';
            app.viewerTab.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.viewerGridLayout = uigridlayout(app.viewerTab);
            app.cameraPanel = uipanel(app.viewerGridLayout);
            app.cameraPanel.Title = 'Camera';
            app.cameraGridLayout = uigridlayout(app.cameraPanel);
            app.DistanceEditFieldLabel = uilabel(app.cameraGridLayout);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.cameraDistanceEdit = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraDistanceEdit.Limits = [0 Inf];
            app.cameraDistanceEdit.ValueDisplayFormat = '%11.3g';
            app.cameraDistanceEdit.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.ZoomEditFieldLabel = uilabel(app.cameraGridLayout);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.cameraZoomEdit = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraZoomEdit.Limits = [0 Inf];
            app.cameraZoomEdit.ValueDisplayFormat = '%11.3g';
            app.cameraZoomEdit.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraZoomEdit.Value = 1;
            app.PositionEditFieldLabel = uilabel(app.cameraGridLayout);
            app.PositionEditFieldLabel.Text = 'Position';
            app.cameraPositionX = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraPositionX.ValueDisplayFormat = '%11.3g';
            app.cameraPositionX.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraPositionY = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraPositionY.ValueDisplayFormat = '%11.3g';
            app.cameraPositionY.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraPositionZ = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraPositionZ.ValueDisplayFormat = '%11.3g';
            app.cameraPositionZ.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.TargetEditFieldLabel = uilabel(app.cameraGridLayout);
            app.TargetEditFieldLabel.Text = 'Target';
            app.cameraTargetX = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraTargetX.ValueDisplayFormat = '%11.3g';
            app.cameraTargetX.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraTargetY = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraTargetY.ValueDisplayFormat = '%11.3g';
            app.cameraTargetY.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraTargetZ = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraTargetZ.ValueDisplayFormat = '%11.3g';
            app.cameraTargetZ.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.UpvectorEditFieldLabel = uilabel(app.cameraGridLayout);
            app.UpvectorEditFieldLabel.Text = 'Up vector';
            app.cameraUpVectorX = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraUpVectorX.ValueDisplayFormat = '%11.3g';
            app.cameraUpVectorX.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraUpVectorY = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraUpVectorY.ValueDisplayFormat = '%11.3g';
            app.cameraUpVectorY.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.cameraUpVectorZ = uieditfield(app.cameraGridLayout, 'numeric');
            app.cameraUpVectorZ.ValueDisplayFormat = '%11.3g';
            app.cameraUpVectorZ.ValueChangedFcn = createCallbackFcn(app, @updateCameraPosition, true);
            app.viewerTopPanel = uipanel(app.viewerGridLayout);
            app.showScaleBar = uicheckbox(app.viewerTopPanel);
            app.showScaleBar.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.showScaleBar.Text = 'show scale';
            app.showScaleBar.Value = true;
            app.showOrientationAxes = uicheckbox(app.viewerTopPanel);
            app.showOrientationAxes.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.showOrientationAxes.Text = 'show axes';
            app.showOrientationAxes.Value = true;
            app.showBox = uicheckbox(app.viewerTopPanel);
            app.showBox.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.showBox.Text = 'show box';
            app.Help = uibutton(app.viewerTopPanel, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Icon = 'help_16px.png';
            app.Help.Text = 'Help';
            app.LightsPanel = uipanel(app.viewerTopPanel);
            app.LightsPanel.Title = 'Lights';
            app.AmbientlightSpinnerLabel = uilabel(app.LightsPanel);
            app.AmbientlightSpinnerLabel.Text = 'Ambient light';
            app.AmbientLight = uispinner(app.LightsPanel);
            app.AmbientLight.Step = 0.1;
            app.AmbientLight.Limits = [0 1];
            app.AmbientLight.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.AmbientLight.Tooltip = {'the amount of ambient light in the scene'};
            app.AmbientLight.Value = 0.45;
            app.DiffuselightSpinnerLabel = uilabel(app.LightsPanel);
            app.DiffuselightSpinnerLabel.Text = 'Diffuse light';
            app.DiffuseLight = uispinner(app.LightsPanel);
            app.DiffuseLight.Step = 0.1;
            app.DiffuseLight.Limits = [0 1];
            app.DiffuseLight.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.DiffuseLight.Tooltip = {'the amount of diffuse light provided by the light sources'};
            app.DiffuseLight.Value = 0.4;
            app.ControlsPanel = uipanel(app.viewerTopPanel);
            app.ControlsPanel.Title = 'Controls';
            app.RotationmodeLabel = uilabel(app.ControlsPanel);
            app.RotationmodeLabel.Text = 'Rotation:';
            app.rotationMode = uidropdown(app.ControlsPanel);
            app.rotationMode.Items = {'cursor', 'orbit'};
            app.rotationMode.ValueChangedFcn = createCallbackFcn(app, @toggleViewerSettings, true);
            app.rotationMode.Value = 'orbit';
            app.liveUpdateCheckBox = uicheckbox(app.ControlsPanel);
            app.liveUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @liveUpdateCheckBoxValueChanged, true);
            app.liveUpdateCheckBox.Text = 'live update';
            app.volumeTab = uitab(app.topTabGroup);
            app.volumeTab.Title = 'Volume';
            app.volumeTab.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.volumeGridLayout = uigridlayout(app.volumeTab);
            app.alphaAxes = uiaxes(app.volumeGridLayout);
            app.alphaAxes.XTick = [];
            app.alphaAxes.YTick = [];
            app.alphaAxes.ButtonDownFcn = createCallbackFcn(app, @alphaAxesButtonDown, true);
            app.aphaCurveBottomGridLayout = uigridlayout(app.volumeGridLayout);
            app.invertAlphaCurve = uibutton(app.aphaCurveBottomGridLayout, 'push');
            app.invertAlphaCurve.ButtonPushedFcn = createCallbackFcn(app, @alphaCurveOperations, true);
            app.invertAlphaCurve.Tooltip = {'invert the alpha curve'};
            app.invertAlphaCurve.Text = 'Invert';
            app.resetAlphaCurve = uibutton(app.aphaCurveBottomGridLayout, 'push');
            app.resetAlphaCurve.ButtonPushedFcn = createCallbackFcn(app, @alphaCurveOperations, true);
            app.resetAlphaCurve.Tooltip = {'reset the alpha curve to the default state'};
            app.resetAlphaCurve.Text = 'Reset';
            app.AlphacurveLabel = uilabel(app.aphaCurveBottomGridLayout);
            app.AlphacurveLabel.Text = 'Alpha curve';
            app.volumeTopPanel = uipanel(app.volumeGridLayout);
            app.colormapAxes = uiaxes(app.volumeTopPanel);
            app.colormapAxes.XLimitMethod = 'tight';
            app.colormapAxes.YLimitMethod = 'padded';
            app.colormapAxes.XTick = [];
            app.colormapAxes.YTick = [];
            app.RendererDropDownLabel = uilabel(app.volumeTopPanel);
            app.RendererDropDownLabel.Text = 'Renderer';
            app.rendererDropDown = uidropdown(app.volumeTopPanel);
            app.rendererDropDown.Items = {'VolumeRendering', 'GradientOpacity', 'SlicePlanes', 'Isosurface', 'MaximumIntensityProjection', 'MinimumIntensityProjection', 'CinematicRendering', 'LightScattering'};
            app.rendererDropDown.ValueChangedFcn = createCallbackFcn(app, @rendererDropDownValueChanged, true);
            app.rendererDropDown.Value = 'VolumeRendering';
            app.showVolumeCheckBox = uicheckbox(app.volumeTopPanel);
            app.showVolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @showVolumeCheckBoxValueChanged, true);
            app.showVolumeCheckBox.Text = 'show volume';
            app.isovalueSlider = uislider(app.volumeTopPanel);
            app.isovalueSlider.Limits = [0 1];
            app.isovalueSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.isovalueSlider.ValueChangingFcn = createCallbackFcn(app, @isovalueSliderValueChanging, true);
            app.isovalueSlider.Enable = 'off';
            app.isovalueSlider.Value = 0.5;
            app.isovalueEdit = uieditfield(app.volumeTopPanel, 'numeric');
            app.isovalueEdit.Limits = [0 1];
            app.isovalueEdit.ValueDisplayFormat = '%11.3g';
            app.isovalueEdit.ValueChangedFcn = createCallbackFcn(app, @isovalueEditValueChanged, true);
            app.isovalueEdit.Enable = 'off';
            app.isovalueLabel = uilabel(app.volumeTopPanel);
            app.isovalueLabel.Text = 'Iso-value  ';
            app.transparentVolumeCheckBox = uicheckbox(app.volumeTopPanel);
            app.transparentVolumeCheckBox.ValueChangedFcn = createCallbackFcn(app, @transparentVolumeCheckBoxValueChanged, true);
            app.transparentVolumeCheckBox.Text = 'transparent volume';
            app.ColormapDropDownLabel = uilabel(app.volumeTopPanel);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.colormapName = uidropdown(app.volumeTopPanel);
            app.colormapName.Items = {'jet', 'turbo', 'hsv', 'hot', 'cool', 'gray', 'bone', 'copper', 'green', 'red', 'blue'};
            app.colormapName.ValueChangedFcn = createCallbackFcn(app, @updateColormap, true);
            app.colormapName.Value = 'jet';
            app.colormapInvert = uicheckbox(app.volumeTopPanel);
            app.colormapInvert.ValueChangedFcn = createCallbackFcn(app, @updateColormap, true);
            app.colormapInvert.Text = 'invert';
            app.BlackpointEditFieldLabel = uilabel(app.volumeTopPanel);
            app.BlackpointEditFieldLabel.Text = 'Black point';
            app.colormapBlackPoint = uieditfield(app.volumeTopPanel, 'numeric');
            app.colormapBlackPoint.Limits = [0 Inf];
            app.colormapBlackPoint.RoundFractionalValues = 'on';
            app.colormapBlackPoint.ValueChangedFcn = createCallbackFcn(app, @updateColormap, true);
            app.colormapBlackPoint.Tooltip = {'define black point for contrast adjustment. All intensities below this value will be rendered in black'};
            app.WhitepointEditFieldLabel = uilabel(app.volumeTopPanel);
            app.WhitepointEditFieldLabel.Text = 'White point';
            app.colormapWhitePoint = uieditfield(app.volumeTopPanel, 'numeric');
            app.colormapWhitePoint.Limits = [1 Inf];
            app.colormapWhitePoint.RoundFractionalValues = 'on';
            app.colormapWhitePoint.ValueChangedFcn = createCallbackFcn(app, @updateColormap, true);
            app.colormapWhitePoint.Tooltip = {'define white point for contrast adjustment. All intensities above this value will be rendered in white'};
            app.colormapWhitePoint.Value = 255;
            app.slicesGridLayout = uigridlayout(app.volumeGridLayout);
            app.slicesGridLayout.Visible = 'off';
            app.slicesLabel = uilabel(app.slicesGridLayout);
            app.slicesLabel.Text = 'Slices';
            app.xSlider = uislider(app.slicesGridLayout);
            app.xSlider.MajorTicks = [];
            app.xSlider.MajorTickLabels = {''};
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderChanging, true);
            app.ySlider = uislider(app.slicesGridLayout);
            app.ySlider.MajorTicks = [];
            app.ySlider.MajorTickLabels = {''};
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderChanging, true);
            app.ySlider.Value = 40;
            app.zSlider = uislider(app.slicesGridLayout);
            app.zSlider.MajorTicks = [];
            app.zSlider.MajorTickLabels = {''};
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderChanging, true);
            app.XEditFieldLabel = uilabel(app.slicesGridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.xSliderEdit = uieditfield(app.slicesGridLayout, 'numeric');
            app.xSliderEdit.Limits = [1 Inf];
            app.xSliderEdit.RoundFractionalValues = 'on';
            app.xSliderEdit.ValueChangedFcn = createCallbackFcn(app, @changeSlice, true);
            app.xSliderEdit.Value = 1;
            app.YEditFieldLabel = uilabel(app.slicesGridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.ySliderEdit = uieditfield(app.slicesGridLayout, 'numeric');
            app.ySliderEdit.Limits = [1 Inf];
            app.ySliderEdit.RoundFractionalValues = 'on';
            app.ySliderEdit.ValueChangedFcn = createCallbackFcn(app, @changeSlice, true);
            app.ySliderEdit.Value = 1;
            app.ZEditFieldLabel = uilabel(app.slicesGridLayout);
            app.ZEditFieldLabel.Text = 'Z';
            app.zSliderEdit = uieditfield(app.slicesGridLayout, 'numeric');
            app.zSliderEdit.Limits = [1 Inf];
            app.zSliderEdit.RoundFractionalValues = 'on';
            app.zSliderEdit.ValueChangedFcn = createCallbackFcn(app, @changeSlice, true);
            app.zSliderEdit.Value = 1;
            app.xPrevSlice = uibutton(app.slicesGridLayout, 'push');
            app.xPrevSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.xPrevSlice.Tooltip = {'"click" to show previous slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the first slice'};
            app.xPrevSlice.Text = '<';
            app.yPrevSlice = uibutton(app.slicesGridLayout, 'push');
            app.yPrevSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.yPrevSlice.Tooltip = {'"click" to show previous slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the first slice'};
            app.yPrevSlice.Text = '<';
            app.zPrevSlice = uibutton(app.slicesGridLayout, 'push');
            app.zPrevSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.zPrevSlice.Tooltip = {'"click" to show previous slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the first slice'};
            app.zPrevSlice.Text = '<';
            app.xNextSlice = uibutton(app.slicesGridLayout, 'push');
            app.xNextSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.xNextSlice.Tooltip = {'"click" to show next slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the last slice'};
            app.xNextSlice.Text = '>';
            app.yNextSlice = uibutton(app.slicesGridLayout, 'push');
            app.yNextSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.yNextSlice.Tooltip = {'"click" to show next slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the last slice'};
            app.yNextSlice.Text = '>';
            app.zNextSlice = uibutton(app.slicesGridLayout, 'push');
            app.zNextSlice.ButtonPushedFcn = createCallbackFcn(app, @prevSlice, true);
            app.zNextSlice.Tooltip = {'"click" to show next slice,  "Shift+click" to jump 10 slices; or "Ctrl+click" to show the last slice'};
            app.zNextSlice.Text = '>';
            app.modelTab = uitab(app.topTabGroup);
            app.modelTab.Title = 'Model';
            app.modelTab.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.modelGridLayout = uigridlayout(app.modelTab);
            app.modelTable = uitable(app.modelGridLayout);
            app.modelTable.ColumnName = {'C'; 'Name'; 'Alpha'; 'show'};
            app.modelTable.RowName = {};
            app.modelTable.ColumnEditable = [false false true true];
            app.modelTable.CellEditCallback = createCallbackFcn(app, @modelTableCellEdit, true);
            app.modelTable.CellSelectionCallback = createCallbackFcn(app, @modelTableCellSelection, true);
            app.modelTopPanel = uipanel(app.modelGridLayout);
            app.modelRightGridLayout = uigridlayout(app.modelTopPanel);
            app.OverlaysourcetypeLabel = uilabel(app.modelRightGridLayout);
            app.OverlaysourcetypeLabel.Text = 'Overlay source:';
            app.overlaySourceDropDown = uidropdown(app.modelRightGridLayout);
            app.overlaySourceDropDown.Items = {'labels', 'mask', 'selection'};
            app.overlaySourceDropDown.Value = 'labels';
            app.modelUpdateOverlay = uibutton(app.modelRightGridLayout, 'state');
            app.modelUpdateOverlay.ValueChangedFcn = createCallbackFcn(app, @modelUpdateOverlayValueChanged, true);
            app.modelUpdateOverlay.Text = 'Update overlay';
            app.MaterialstoshowDropDownLabel = uilabel(app.modelRightGridLayout);
            app.MaterialstoshowDropDownLabel.Text = 'Materials to show';
            app.overlayMaterialsMode = uidropdown(app.modelRightGridLayout);
            app.overlayMaterialsMode.Items = {'All materials', 'Selected materials'};
            app.overlayMaterialsMode.ValueChangedFcn = createCallbackFcn(app, @overlayMaterialsModeValueChanged, true);
            app.overlayMaterialsMode.Tooltip = {'All materials: 255 cycled colors; Selected materials: exact colors for the listed indices'};
            app.overlayMaterialsMode.Value = 'All materials';
            app.overlayMaterialsList = uieditfield(app.modelRightGridLayout, 'text');
            app.overlayMaterialsList.ValueChangedFcn = createCallbackFcn(app, @overlayMaterialsListValueChanged, true);
            app.overlayMaterialsList.Tooltip = {'Material indices and ranges to render, max 255 of them'};
            app.overlayMaterialsList.Placeholder = '1:10,45';
            app.addCurrentMaterialButton = uibutton(app.modelRightGridLayout, 'push');
            app.addCurrentMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @addCurrentMaterialButtonPushed, true);
            app.addCurrentMaterialButton.Tooltip = {'Add the material selected in the main MIB window'};
            app.addCurrentMaterialButton.Text = 'Add current';
            app.modelHideAllCheckBox = uicheckbox(app.modelRightGridLayout);
            app.modelHideAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @modelHideAllCheckBoxValueChanged, true);
            app.modelHideAllCheckBox.Tooltip = {'hide all materials of the model'};
            app.modelHideAllCheckBox.Text = 'Hide all';
            app.RenderingstyleDropDownLabel = uilabel(app.modelRightGridLayout);
            app.RenderingstyleDropDownLabel.Text = 'Rendering style:';
            app.overlayRenderingStyle = uidropdown(app.modelRightGridLayout);
            app.overlayRenderingStyle.Items = {'LabelOverlay', 'VolumeOverlay', 'GradientOverlay'};
            app.overlayRenderingStyle.ValueChangedFcn = createCallbackFcn(app, @overlayRenderingStyleValueChanged, true);
            app.overlayRenderingStyle.Value = 'LabelOverlay';
            app.refreshOverlayButton = uibutton(app.modelRightGridLayout, 'push');
            app.refreshOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @refreshOverlayButtonPushed, true);
            app.refreshOverlayButton.Icon = 'refresh_16px.png';
            app.refreshOverlayButton.Text = 'Refresh view';
            app.SurfacesTab = uitab(app.topTabGroup);
            app.SurfacesTab.Title = 'Surfaces';
            app.SurfacesTab.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.surfacesGridLayout = uigridlayout(app.SurfacesTab);
            app.surfaceTopPanel = uipanel(app.surfacesGridLayout);
            app.surfacesHideAll = uicheckbox(app.surfacesGridLayout);
            app.surfacesHideAll.ValueChangedFcn = createCallbackFcn(app, @surfacesHideAllValueChanged, true);
            app.surfacesHideAll.Tooltip = {'check to hide all surfaces'};
            app.surfacesHideAll.Text = 'Hide all';
            app.surfaceTable = uitable(app.surfacesGridLayout);
            app.surfaceTable.ColumnName = {'C'; 'Name'; 'Alpha'; 'show'; 'wire'};
            app.surfaceTable.RowName = {};
            app.surfaceTable.ColumnEditable = [false false true true true];
            app.surfaceTable.CellEditCallback = createCallbackFcn(app, @surfaceTableCellEdit, true);
            app.surfaceTable.CellSelectionCallback = createCallbackFcn(app, @surfaceTableCellSelection, true);
            app.bottomTabGroup = uitabgroup(app.Figure);
            app.bottomTabGroup.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.animationTab = uitab(app.bottomTabGroup);
            app.animationTab.Title = 'Animation';
            app.animationTab.ButtonDownFcn = createCallbackFcn(app, @tabGroupButtonDown, true);
            app.animationGridLayout = uigridlayout(app.animationTab);
            app.keyFrameTable = uitable(app.animationGridLayout);
            app.keyFrameTable.ColumnName = '';
            app.keyFrameTable.RowName = {'1'};
            app.keyFrameTable.CellSelectionCallback = createCallbackFcn(app, @keyFrameTableCellSelection, true);
            app.keyFrameTable.Multiselect = 'off';
            app.animationBottomPanel = uipanel(app.animationGridLayout);
            app.previewAnimationButton = uibutton(app.animationBottomPanel, 'push');
            app.previewAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @previewAnimationButtonPushed, true);
            app.previewAnimationButton.Tooltip = {'press to preview animation'};
            app.previewAnimationButton.Text = 'Preview';
            app.spinTestButton = uibutton(app.animationBottomPanel, 'push');
            app.spinTestButton.ButtonPushedFcn = createCallbackFcn(app, @spinTestButtonPushed, true);
            app.spinTestButton.Tooltip = {'press to preview spin rotation path'};
            app.spinTestButton.Text = 'Spin test';
            app.spinAxis = uidropdown(app.animationBottomPanel);
            app.spinAxis.Items = {'Z-axis', 'X-axis', 'Y-axis'};
            app.spinAxis.Value = 'Z-axis';
            app.addKeyFrameButton = uibutton(app.animationBottomPanel, 'push');
            app.addKeyFrameButton.ButtonPushedFcn = createCallbackFcn(app, @addKeyFrameButtonPushed, true);
            app.addKeyFrameButton.Tooltip = {'add current view as a key frame'};
            app.addKeyFrameButton.Text = 'Add keyframe';
            app.autoJumpCheckBox = uicheckbox(app.animationBottomPanel);
            app.autoJumpCheckBox.Tooltip = {'auto update the view upon selecton of key frames'};
            app.autoJumpCheckBox.Text = 'Auto jump';
            app.NoframesEditFieldLabel = uilabel(app.animationBottomPanel);
            app.NoframesEditFieldLabel.Text = 'No. frames';
            app.noFramesEditField = uieditfield(app.animationBottomPanel, 'numeric');
            app.noFramesEditField.Limits = [1 Inf];
            app.noFramesEditField.RoundFractionalValues = 'on';
            app.noFramesEditField.ValueChangedFcn = createCallbackFcn(app, @noFramesEditFieldValueChanged, true);
            app.noFramesEditField.Value = 120;
            app.deleteAllKeyFramesButton = uibutton(app.animationBottomPanel, 'push');
            app.deleteAllKeyFramesButton.ButtonPushedFcn = createCallbackFcn(app, @deleteAllKeyFramesButtonPushed, true);
            app.deleteAllKeyFramesButton.Text = 'Delete all';
            app.keyFrameTableContextMenu = uicontextmenu(app.Figure);
            app.keyFrameTable_cm_jumpToKeyFrame = uimenu(app.keyFrameTableContextMenu);
            app.keyFrameTable_cm_jumpToKeyFrame.MenuSelectedFcn = createCallbackFcn(app, @keyFrameTable_cm_Callback, true);
            app.keyFrameTable_cm_jumpToKeyFrame.Text = 'Jump to the keyframe';
            app.keyFrameTable_cm_insertKeyFrame = uimenu(app.keyFrameTableContextMenu);
            app.keyFrameTable_cm_insertKeyFrame.MenuSelectedFcn = createCallbackFcn(app, @keyFrameTable_cm_Callback, true);
            app.keyFrameTable_cm_insertKeyFrame.Separator = 'on';
            app.keyFrameTable_cm_insertKeyFrame.Text = 'Insert a keyframe';
            app.keyFrameTable_cm_replaceKeyFrame = uimenu(app.keyFrameTableContextMenu);
            app.keyFrameTable_cm_replaceKeyFrame.MenuSelectedFcn = createCallbackFcn(app, @keyFrameTable_cm_Callback, true);
            app.keyFrameTable_cm_replaceKeyFrame.Text = 'Replace keyframe';
            app.keyFrameTable_cm_removeKeyFrame = uimenu(app.keyFrameTableContextMenu);
            app.keyFrameTable_cm_removeKeyFrame.MenuSelectedFcn = createCallbackFcn(app, @keyFrameTable_cm_Callback, true);
            app.keyFrameTable_cm_removeKeyFrame.Text = 'Remove keyframe';
            % Assign app.keyFrameTableContextMenu
            app.keyFrameTable.ContextMenu = app.keyFrameTableContextMenu;
            app.modelTableContextMenu = uicontextmenu(app.Figure);
            app.modelTable_cm_generateSurface = uimenu(app.modelTableContextMenu);
            app.modelTable_cm_generateSurface.MenuSelectedFcn = createCallbackFcn(app, @modelTable_cm_Callback, true);
            app.modelTable_cm_generateSurface.Text = 'Generate surface(s)';
            app.modelTable_cm_generateSurfaceByIndex = uimenu(app.modelTableContextMenu);
            app.modelTable_cm_generateSurfaceByIndex.MenuSelectedFcn = createCallbackFcn(app, @modelTable_cm_Callback, true);
            app.modelTable_cm_generateSurfaceByIndex.Text = 'Generate surface by index...';
            app.modelTable_cm_removeMaterials = uimenu(app.modelTableContextMenu);
            app.modelTable_cm_removeMaterials.MenuSelectedFcn = createCallbackFcn(app, @modelTable_cm_Callback, true);
            app.modelTable_cm_removeMaterials.Tooltip = {''};
            app.modelTable_cm_removeMaterials.Text = 'Remove selected materials';
            % Assign app.modelTableContextMenu
            app.modelTable.ContextMenu = app.modelTableContextMenu;
            app.surfaceTableContextMenu = uicontextmenu(app.Figure);
            app.surfaceTable_cm_saveSurface = uimenu(app.surfaceTableContextMenu);
            app.surfaceTable_cm_saveSurface.MenuSelectedFcn = createCallbackFcn(app, @surfaceTable_cm_Callback, true);
            app.surfaceTable_cm_saveSurface.Text = 'Save surface(s)';
            app.surfaceTable_cm_removeSurface = uimenu(app.surfaceTableContextMenu);
            app.surfaceTable_cm_removeSurface.MenuSelectedFcn = createCallbackFcn(app, @surfaceTable_cm_Callback, true);
            app.surfaceTable_cm_removeSurface.Separator = 'on';
            app.surfaceTable_cm_removeSurface.Text = 'Remove surface(s)';
            % Assign app.surfaceTableContextMenu
            app.surfaceTable.ContextMenu = app.surfaceTableContextMenu;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolRenAppGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end