classdef WhiteBalanceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        mainGridLayout                  matlab.ui.container.GridLayout
        buttonsGridLayout               matlab.ui.container.GridLayout
        correctCurrentButton            matlab.ui.control.Button
        correctAllButton                matlab.ui.control.Button
        closeButton                     matlab.ui.control.Button
        helpButton                      matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        settingsGridLayout              matlab.ui.container.GridLayout
        detectButton                    matlab.ui.control.Button
        ChromaticAdaptationMethod       matlab.ui.control.DropDown
        ChromaticadaptationmethodLabel  matlab.ui.control.Label
        ColorSpace                      matlab.ui.control.DropDown
        ColorspaceLabel                 matlab.ui.control.Label
        ManualWhiteColor                matlab.ui.control.EditField
        ManualwhitevalueLabel           matlab.ui.control.Label
        PickedRegion                    matlab.ui.container.ButtonGroup
        SelectedAreas                   matlab.ui.control.RadioButton
        MaskedAreas                     matlab.ui.control.RadioButton
        ManualValue                     matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'White balance correction';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.mainGridLayout = uigridlayout(app.Figure);
            app.Image = uiimage(app.mainGridLayout);
            app.Image.ImageSource = 'white-balance_96px.png';
            app.Label = uilabel(app.mainGridLayout);
            app.Label.Text = {'Adjust color balance of RGB image with chromatic adaptation.'; 'Use the mask or selection layer to pick regions in the image that should look white or gray. '; 'Do not pick areas with saturated signal.'};
            app.PickedRegion = uibuttongroup(app.mainGridLayout);
            app.PickedRegion.Tooltip = {'Layers containing areas that should be white or gray'};
            app.PickedRegion.Title = 'Picked region';
            app.ManualValue = uiradiobutton(app.PickedRegion);
            app.ManualValue.Text = ' Manual value';
            app.MaskedAreas = uiradiobutton(app.PickedRegion);
            app.MaskedAreas.Text = ' Masked areas';
            app.SelectedAreas = uiradiobutton(app.PickedRegion);
            app.SelectedAreas.Text = ' Selected areas';
            app.SelectedAreas.Value = true;
            app.SettingsPanel = uipanel(app.mainGridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.settingsGridLayout = uigridlayout(app.SettingsPanel);
            app.ManualwhitevalueLabel = uilabel(app.settingsGridLayout);
            app.ManualwhitevalueLabel.Text = 'Manual white value';
            app.ManualWhiteColor = uieditfield(app.settingsGridLayout, 'text');
            app.ManualWhiteColor.Enable = 'off';
            app.ManualWhiteColor.Tooltip = {'Provide 3 values that describe intensity in an area that should be white or gray'};
            app.ManualWhiteColor.Value = '128 128 128';
            app.ColorspaceLabel = uilabel(app.settingsGridLayout);
            app.ColorspaceLabel.Text = 'Color space';
            app.ColorSpace = uidropdown(app.settingsGridLayout);
            app.ColorSpace.Items = {'sRGB', 'Adobe-RGB-1998', 'linear-RGB'};
            app.ColorSpace.Tooltip = {'Color space of the image'};
            app.ColorSpace.Value = 'sRGB';
            app.ChromaticadaptationmethodLabel = uilabel(app.settingsGridLayout);
            app.ChromaticadaptationmethodLabel.Text = 'Chromatic adaptation method';
            app.ChromaticAdaptationMethod = uidropdown(app.settingsGridLayout);
            app.ChromaticAdaptationMethod.Items = {'bradford'};
            app.ChromaticAdaptationMethod.Tooltip = {'Chromatic adaptation method used to scale the RGB values'};
            app.ChromaticAdaptationMethod.Value = 'bradford';
            app.detectButton = uibutton(app.settingsGridLayout, 'push');
            app.detectButton.Tooltip = {'Get calibration point from the selected area on the current slice'};
            app.detectButton.Text = 'Get calibration from selection';
            app.buttonsGridLayout = uigridlayout(app.mainGridLayout);
            app.helpButton = uibutton(app.buttonsGridLayout, 'push');
            app.helpButton.Icon = 'help_16px.png';
            app.helpButton.Text = '';
            app.closeButton = uibutton(app.buttonsGridLayout, 'push');
            app.closeButton.Text = 'Close';
            app.correctAllButton = uibutton(app.buttonsGridLayout, 'push');
            app.correctAllButton.Tooltip = {'correct white balance for all images of the dataset'};
            app.correctAllButton.Text = 'Correct all';
            app.correctCurrentButton = uibutton(app.buttonsGridLayout, 'push');
            app.correctCurrentButton.Tooltip = {'correct white balance for the currently shown image'};
            app.correctCurrentButton.Text = 'Correct current';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WhiteBalanceGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end