classdef WoundHealingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        mainGridLayout                  matlab.ui.container.GridLayout
        WoundHealing                    matlab.ui.control.Button
        OutputButton                    matlab.ui.control.Button
        OutputDir                       matlab.ui.control.EditField
        SelectDirectories               matlab.ui.control.Button
        Extension                       matlab.ui.control.EditField
        ExtensionLabel                  matlab.ui.control.Label
        SelectedDirectories             matlab.ui.control.TextArea
        Stitch                          matlab.ui.control.Button
        Close                           matlab.ui.control.Button
        Help                            matlab.ui.control.StateButton
        WoundhealingsettingsPanel       matlab.ui.container.Panel
        ShowInteractivePlot             matlab.ui.control.CheckBox
        DownsampleImages                matlab.ui.control.NumericEditField
        DownsampleresultingimagesLabel  matlab.ui.control.Label
        TimeStep                        matlab.ui.control.NumericEditField
        TimestepLabel                   matlab.ui.control.Label
        PixelSize                       matlab.ui.control.NumericEditField
        PixelsizeLabel                  matlab.ui.control.Label
        StitchingsettingsPanel          matlab.ui.container.Panel
        ConvertToGrayscale              matlab.ui.control.CheckBox
        NoColumns                       matlab.ui.control.NumericEditField
        NoColumnsEditFieldLabel         matlab.ui.control.Label
        NoRows                          matlab.ui.control.NumericEditField
        NoRowsEditFieldLabel            matlab.ui.control.Label
        AboutTextArea                   matlab.ui.control.TextArea
        AboutTextAreaLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Wound healing assay';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.mainGridLayout = uigridlayout(app.Figure);
            app.Image = uiimage(app.mainGridLayout);
            app.Image.ImageSource = 'puffin_wound_healing_96px.png';
            app.AboutTextAreaLabel = uilabel(app.mainGridLayout);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.mainGridLayout);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'Use this plugin to '; '1) stich grid collection of images that have 0 pixel overlap; '; '2) run a wound healing assay over the stitched images'};
            app.StitchingsettingsPanel = uipanel(app.mainGridLayout);
            app.StitchingsettingsPanel.Title = 'Stitching settings';
            app.NoRowsEditFieldLabel = uilabel(app.StitchingsettingsPanel);
            app.NoRowsEditFieldLabel.Text = 'No. Rows';
            app.NoRows = uieditfield(app.StitchingsettingsPanel, 'numeric');
            app.NoRows.Limits = [1 Inf];
            app.NoRows.RoundFractionalValues = 'on';
            app.NoRows.ValueDisplayFormat = '%d';
            app.NoRows.Value = 3;
            app.NoColumnsEditFieldLabel = uilabel(app.StitchingsettingsPanel);
            app.NoColumnsEditFieldLabel.Text = 'No. Columns';
            app.NoColumns = uieditfield(app.StitchingsettingsPanel, 'numeric');
            app.NoColumns.Limits = [1 Inf];
            app.NoColumns.RoundFractionalValues = 'on';
            app.NoColumns.ValueDisplayFormat = '%d';
            app.NoColumns.Value = 3;
            app.ConvertToGrayscale = uicheckbox(app.StitchingsettingsPanel);
            app.ConvertToGrayscale.Text = 'convert to grayscale';
            app.ConvertToGrayscale.Value = true;
            app.WoundhealingsettingsPanel = uipanel(app.mainGridLayout);
            app.WoundhealingsettingsPanel.Title = 'Wound healing settings';
            app.PixelsizeLabel = uilabel(app.WoundhealingsettingsPanel);
            app.PixelsizeLabel.Text = 'Pixel size';
            app.PixelSize = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.PixelSize.Limits = [0 Inf];
            app.PixelSize.ValueDisplayFormat = '%11.3f';
            app.PixelSize.Value = 1;
            app.TimestepLabel = uilabel(app.WoundhealingsettingsPanel);
            app.TimestepLabel.Text = 'Time step';
            app.TimeStep = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.TimeStep.Limits = [0 Inf];
            app.TimeStep.ValueDisplayFormat = '%11.3f';
            app.TimeStep.Value = 1;
            app.DownsampleresultingimagesLabel = uilabel(app.WoundhealingsettingsPanel);
            app.DownsampleresultingimagesLabel.Text = 'Downsample resulting images';
            app.DownsampleImages = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.DownsampleImages.Limits = [0 100];
            app.DownsampleImages.ValueDisplayFormat = '%d';
            app.DownsampleImages.Tooltip = {'Percentage to downsample resulting images'};
            app.DownsampleImages.Value = 50;
            app.ShowInteractivePlot = uicheckbox(app.WoundhealingsettingsPanel);
            app.ShowInteractivePlot.Text = 'show interactive plot';
            app.ShowInteractivePlot.Value = true;
            app.Help = uibutton(app.mainGridLayout, 'state');
            app.Help.Icon = 'help_16px.png';
            app.Help.Text = '';
            app.Close = uibutton(app.mainGridLayout, 'push');
            app.Close.Text = 'Close';
            app.Stitch = uibutton(app.mainGridLayout, 'push');
            app.Stitch.Tooltip = {'Stitch images in the selected directories'};
            app.Stitch.Text = 'Stitch';
            app.SelectedDirectories = uitextarea(app.mainGridLayout);
            app.SelectedDirectories.Editable = 'off';
            app.SelectedDirectories.Value = {'1. Stitching'; '- define the filename extension'; '- define number of images in the grid (left-up to down-right)'; '- select output directory and press the Stitch button'; ''; '2. Wound healing'; '- define directories with the assays and the output directory for results'; '- define pixel size and time step'; '- press the Wound healing button to start analysis'};
            app.ExtensionLabel = uilabel(app.mainGridLayout);
            app.ExtensionLabel.Text = 'Extension:';
            app.Extension = uieditfield(app.mainGridLayout, 'text');
            app.Extension.Value = 'tif';
            app.SelectDirectories = uibutton(app.mainGridLayout, 'push');
            app.SelectDirectories.Icon = 'open_16px.png';
            app.SelectDirectories.Text = 'Select directories...';
            app.OutputDir = uieditfield(app.mainGridLayout, 'text');
            app.OutputButton = uibutton(app.mainGridLayout, 'push');
            app.OutputButton.Text = 'Output...';
            app.WoundHealing = uibutton(app.mainGridLayout, 'push');
            app.WoundHealing.Text = 'Wound healing';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WoundHealingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end