classdef ImageConverterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                        matlab.ui.Figure
        ZarrSettingsPanel             matlab.ui.container.Panel
        ZarrUseSharding               matlab.ui.control.CheckBox
        ZarrUnits                     matlab.ui.control.DropDown
        UnitsDropDownLabel            matlab.ui.control.Label
        ZarrBBShiftsXYZ               matlab.ui.control.EditField
        VoxelsizexyzLabel_2           matlab.ui.control.Label
        ZarrVoxelSizeXYZ              matlab.ui.control.EditField
        VoxelsizexyzLabel             matlab.ui.control.Label
        ZarrCompressionLevel          matlab.ui.control.Spinner
        CompressionlevelSpinnerLabel  matlab.ui.control.Label
        ZarrCompression               matlab.ui.control.DropDown
        CompressionDropDownLabel      matlab.ui.control.Label
        ZarrShardXFactorsXYZ          matlab.ui.control.EditField
        ZarrDownsampleLimitXYZ        matlab.ui.control.EditField
        ZarrChunkSizes                matlab.ui.control.EditField
        ZarrImageType                 matlab.ui.control.DropDown
        ZarrVersion                   matlab.ui.control.DropDown
        ImagetypeDropDownLabel        matlab.ui.control.Label
        VoxelsizexyzLabel_5           matlab.ui.control.Label
        VoxelsizexyzLabel_3           matlab.ui.control.Label
        ZarrversionDropDownLabel      matlab.ui.control.Label
        VoxelsizexyzLabel_4           matlab.ui.control.Label
        HelpButton                    matlab.ui.control.Button
        OutputPanel                   matlab.ui.container.Panel
        ParallelWorkersNumber         matlab.ui.control.Spinner
        WorkersSpinnerLabel           matlab.ui.control.Label
        ExportSettingsPanel           matlab.ui.container.Panel
        DiscardColormap               matlab.ui.control.CheckBox
        Suffix                        matlab.ui.control.EditField
        SuffixLabel                   matlab.ui.control.Label
        PyramidalTIFcompression       matlab.ui.control.DropDown
        TIFcompressionDropDownLabel   matlab.ui.control.Label
        PyramidalTIFlevels            matlab.ui.control.EditField
        LevelsEditFieldLabel          matlab.ui.control.Label
        PyramidalTIFgenerate          matlab.ui.control.CheckBox
        Prefix                        matlab.ui.control.EditField
        FilenameprefixLabel           matlab.ui.control.Label
        ParallelProcessing            matlab.ui.control.CheckBox
        OutputImageFormatExtension    matlab.ui.control.DropDown
        OutputformatLabel             matlab.ui.control.Label
        SelectOutputDirectory         matlab.ui.control.Button
        OutputDirectory               matlab.ui.control.EditField
        OutputdirectoryLabel          matlab.ui.control.Label
        InputPanel                    matlab.ui.container.Panel
        BioFormatsIndex               matlab.ui.control.NumericEditField
        IndexEditFieldLabel           matlab.ui.control.Label
        BioFormatsInputImageFormatExtension  matlab.ui.control.DropDown
        BioFormatsInputImageFormatExtensionLabel  matlab.ui.control.Label
        BioFormatsReader              matlab.ui.control.CheckBox
        InputImageFormatExtension     matlab.ui.control.DropDown
        ImagefilenameextensionLabel   matlab.ui.control.Label
        IncludeSubfolders             matlab.ui.control.CheckBox
        SelectInputDirectory          matlab.ui.control.Button
        InputDirectory                matlab.ui.control.EditField
        InputdirectoryLabel           matlab.ui.control.Label
        InfoPanel                     matlab.ui.container.Panel
        infoText                      matlab.ui.control.HTML
        Close                         matlab.ui.control.Button
        Convert                       matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.ZarrSettingsPanel.Visible = 'off';
            app.ZarrSettingsPanel.Parent = app.ExportSettingsPanel.Parent;
            app.ZarrSettingsPanel.Position = app.ExportSettingsPanel.Position;
            app.winController = winController;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Button pushed function: Convert
        function Convert_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Convert();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.closeWindow();
        end

        % Value changed function: BioFormatsIndex, 
        % ...and 20 other components
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: SelectInputDirectory, 
        % ...and 1 other component
        function SelectInputDirectoryButtonPushed(app, event)
            app.winController.selectDirectory(event);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.winController.helpButton_Callback();
        end

        % Value changed function: PyramidalTIFgenerate
        function PyramidalTIFgenerateValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            
            if app.PyramidalTIFgenerate.Value
                app.OutputImageFormatExtension.Value = 'tif';
                event2.Source = app.OutputImageFormatExtension;
                app.winController.updateBatchOptFromGUI(event2);
                app.PyramidalTIFlevels.Enable = 'on';
                app.PyramidalTIFcompression.Enable = 'on';
            else
                app.PyramidalTIFlevels.Enable = 'off';
                app.PyramidalTIFcompression.Enable = 'off';
            end
        end

        % Value changed function: BioFormatsReader
        function BioFormatsReaderValueChanged(app, event)
            app.winController.BioFormatsReader_ValueChanged(event);
        end

        % Value changed function: OutputImageFormatExtension
        function OutputImageFormatExtensionValueChanged(app, event)
            app.winController.updateOutputFormat(event);
        end

        % Value changed function: ZarrUseSharding
        function ZarrUseShardingValueChanged(app, event)
            app.winController.useShardingCallback(event)
        end

        % Value changed function: ZarrVersion
        function ZarrVersionValueChanged(app, event)
            app.winController.zarrVersionValueChanged(event)           
        end

        % Value changed function: ParallelProcessing
        function ParallelProcessingValueChanged(app, event)
            app.winController.parallelProcessingCallback(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Image converter';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Convert = uibutton(app.Figure, 'push');
            app.Convert.ButtonPushedFcn = createCallbackFcn(app, @Convert_Callback, true);
            app.Convert.Text = 'Convert';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.InfoPanel = uipanel(app.Figure);
            app.InfoPanel.Title = 'Info';
            app.infoText = uihtml(app.InfoPanel);
            app.InputPanel = uipanel(app.Figure);
            app.InputPanel.Title = 'Input';
            app.InputdirectoryLabel = uilabel(app.InputPanel);
            app.InputdirectoryLabel.Text = 'Input directory:';
            app.InputDirectory = uieditfield(app.InputPanel, 'text');
            app.InputDirectory.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.InputDirectory.Value = 'test';
            app.SelectInputDirectory = uibutton(app.InputPanel, 'push');
            app.SelectInputDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectInputDirectory.Text = '...';
            app.IncludeSubfolders = uicheckbox(app.InputPanel);
            app.IncludeSubfolders.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.IncludeSubfolders.Text = 'Include subfolders';
            app.ImagefilenameextensionLabel = uilabel(app.InputPanel);
            app.ImagefilenameextensionLabel.Text = 'Image filename extension';
            app.InputImageFormatExtension = uidropdown(app.InputPanel);
            app.InputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.InputImageFormatExtension.Value = 'Option 4';
            app.BioFormatsReader = uicheckbox(app.InputPanel);
            app.BioFormatsReader.ValueChangedFcn = createCallbackFcn(app, @BioFormatsReaderValueChanged, true);
            app.BioFormatsReader.Text = 'Bio-Formats reader';
            app.BioFormatsInputImageFormatExtensionLabel = uilabel(app.InputPanel);
            app.BioFormatsInputImageFormatExtensionLabel.Text = 'Image filename extension';
            app.BioFormatsInputImageFormatExtension = uidropdown(app.InputPanel);
            app.BioFormatsInputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioFormatsInputImageFormatExtension.Enable = 'off';
            app.BioFormatsInputImageFormatExtension.Value = 'Option 4';
            app.IndexEditFieldLabel = uilabel(app.InputPanel);
            app.IndexEditFieldLabel.Text = 'Index';
            app.BioFormatsIndex = uieditfield(app.InputPanel, 'numeric');
            app.BioFormatsIndex.Limits = [0 Inf];
            app.BioFormatsIndex.RoundFractionalValues = 'on';
            app.BioFormatsIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioFormatsIndex.Enable = 'off';
            app.BioFormatsIndex.Value = 1;
            app.OutputPanel = uipanel(app.Figure);
            app.OutputPanel.Title = 'Output';
            app.OutputdirectoryLabel = uilabel(app.OutputPanel);
            app.OutputdirectoryLabel.Text = 'Output directory:';
            app.OutputDirectory = uieditfield(app.OutputPanel, 'text');
            app.OutputDirectory.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.OutputDirectory.Value = 'test';
            app.SelectOutputDirectory = uibutton(app.OutputPanel, 'push');
            app.SelectOutputDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectOutputDirectory.Text = '...';
            app.OutputformatLabel = uilabel(app.OutputPanel);
            app.OutputformatLabel.Text = 'Output format:';
            app.OutputImageFormatExtension = uidropdown(app.OutputPanel);
            app.OutputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @OutputImageFormatExtensionValueChanged, true);
            app.OutputImageFormatExtension.Value = 'Option 4';
            app.ParallelProcessing = uicheckbox(app.OutputPanel);
            app.ParallelProcessing.ValueChangedFcn = createCallbackFcn(app, @ParallelProcessingValueChanged, true);
            app.ParallelProcessing.Text = 'Parallel processing';
            app.ExportSettingsPanel = uipanel(app.OutputPanel);
            app.ExportSettingsPanel.Title = 'Settings';
            app.FilenameprefixLabel = uilabel(app.ExportSettingsPanel);
            app.FilenameprefixLabel.Text = 'Filename prefix';
            app.Prefix = uieditfield(app.ExportSettingsPanel, 'text');
            app.Prefix.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PyramidalTIFgenerate = uicheckbox(app.ExportSettingsPanel);
            app.PyramidalTIFgenerate.ValueChangedFcn = createCallbackFcn(app, @PyramidalTIFgenerateValueChanged, true);
            app.PyramidalTIFgenerate.Text = 'Generate pyramidal TIFs';
            app.LevelsEditFieldLabel = uilabel(app.ExportSettingsPanel);
            app.LevelsEditFieldLabel.Text = 'Levels';
            app.PyramidalTIFlevels = uieditfield(app.ExportSettingsPanel, 'text');
            app.PyramidalTIFlevels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PyramidalTIFlevels.Enable = 'off';
            app.PyramidalTIFlevels.Value = '1, 2, 3, 4';
            app.TIFcompressionDropDownLabel = uilabel(app.ExportSettingsPanel);
            app.TIFcompressionDropDownLabel.Text = 'TIF compression';
            app.PyramidalTIFcompression = uidropdown(app.ExportSettingsPanel);
            app.PyramidalTIFcompression.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PyramidalTIFcompression.Enable = 'off';
            app.SuffixLabel = uilabel(app.ExportSettingsPanel);
            app.SuffixLabel.Text = 'Suffix';
            app.Suffix = uieditfield(app.ExportSettingsPanel, 'text');
            app.Suffix.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DiscardColormap = uicheckbox(app.ExportSettingsPanel);
            app.DiscardColormap.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DiscardColormap.Text = 'discard colormap';
            app.WorkersSpinnerLabel = uilabel(app.OutputPanel);
            app.WorkersSpinnerLabel.Text = 'Workers';
            app.ParallelWorkersNumber = uispinner(app.OutputPanel);
            app.ParallelWorkersNumber.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ParallelWorkersNumber.Value = 12;
            app.HelpButton = uibutton(app.Figure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ZarrSettingsPanel = uipanel(app.Figure);
            app.ZarrSettingsPanel.Title = 'Zarr settings';
            app.VoxelsizexyzLabel_4 = uilabel(app.ZarrSettingsPanel);
            app.VoxelsizexyzLabel_4.Text = 'Chunk sizes (x,y,z,c,t), px';
            app.ZarrversionDropDownLabel = uilabel(app.ZarrSettingsPanel);
            app.ZarrversionDropDownLabel.Text = 'Zarr version';
            app.VoxelsizexyzLabel_3 = uilabel(app.ZarrSettingsPanel);
            app.VoxelsizexyzLabel_3.Text = 'Downsample limit (x,y,z), px';
            app.VoxelsizexyzLabel_5 = uilabel(app.ZarrSettingsPanel);
            app.VoxelsizexyzLabel_5.Text = 'Shard x-factors(x,y,z,c,t), px';
            app.ImagetypeDropDownLabel = uilabel(app.ZarrSettingsPanel);
            app.ImagetypeDropDownLabel.Text = 'Image type';
            app.ZarrVersion = uidropdown(app.ZarrSettingsPanel);
            app.ZarrVersion.Items = {'Zarr v2', 'Zarr v3'};
            app.ZarrVersion.ValueChangedFcn = createCallbackFcn(app, @ZarrVersionValueChanged, true);
            app.ZarrVersion.Value = 'Zarr v2';
            app.ZarrImageType = uidropdown(app.ZarrSettingsPanel);
            app.ZarrImageType.Items = {'image', 'model (labels)'};
            app.ZarrImageType.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrImageType.Value = 'image';
            app.ZarrChunkSizes = uieditfield(app.ZarrSettingsPanel, 'text');
            app.ZarrChunkSizes.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrChunkSizes.Value = '128,128,64,1,1';
            app.ZarrDownsampleLimitXYZ = uieditfield(app.ZarrSettingsPanel, 'text');
            app.ZarrDownsampleLimitXYZ.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrDownsampleLimitXYZ.Value = '512,512,256';
            app.ZarrShardXFactorsXYZ = uieditfield(app.ZarrSettingsPanel, 'text');
            app.ZarrShardXFactorsXYZ.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrShardXFactorsXYZ.Enable = 'off';
            app.ZarrShardXFactorsXYZ.Value = '1024, 1024, 1024';
            app.CompressionDropDownLabel = uilabel(app.ZarrSettingsPanel);
            app.CompressionDropDownLabel.Text = 'Compression';
            app.ZarrCompression = uidropdown(app.ZarrSettingsPanel);
            app.ZarrCompression.Items = {'blosc', 'gzip', 'none'};
            app.ZarrCompression.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrCompression.Value = 'blosc';
            app.CompressionlevelSpinnerLabel = uilabel(app.ZarrSettingsPanel);
            app.CompressionlevelSpinnerLabel.Text = 'Compression level';
            app.ZarrCompressionLevel = uispinner(app.ZarrSettingsPanel);
            app.ZarrCompressionLevel.Limits = [0 9];
            app.ZarrCompressionLevel.RoundFractionalValues = 'on';
            app.ZarrCompressionLevel.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrCompressionLevel.Value = 6;
            app.VoxelsizexyzLabel = uilabel(app.ZarrSettingsPanel);
            app.VoxelsizexyzLabel.Text = 'Voxel size (x,y,z)';
            app.ZarrVoxelSizeXYZ = uieditfield(app.ZarrSettingsPanel, 'text');
            app.ZarrVoxelSizeXYZ.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrVoxelSizeXYZ.Value = '13,13,30';
            app.VoxelsizexyzLabel_2 = uilabel(app.ZarrSettingsPanel);
            app.VoxelsizexyzLabel_2.Text = 'Bounding box shifts (x,y,z)';
            app.ZarrBBShiftsXYZ = uieditfield(app.ZarrSettingsPanel, 'text');
            app.ZarrBBShiftsXYZ.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrBBShiftsXYZ.Value = '0,0,0';
            app.UnitsDropDownLabel = uilabel(app.ZarrSettingsPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.ZarrUnits = uidropdown(app.ZarrSettingsPanel);
            app.ZarrUnits.Items = {'nanometers', 'micrometers', 'millimeters', 'pixels'};
            app.ZarrUnits.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ZarrUnits.Value = 'nanometers';
            app.ZarrUseSharding = uicheckbox(app.ZarrSettingsPanel);
            app.ZarrUseSharding.ValueChangedFcn = createCallbackFcn(app, @ZarrUseShardingValueChanged, true);
            app.ZarrUseSharding.Text = 'use sharding';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageConverterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end