classdef DemoPluginGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        ParameterNumeric                matlab.ui.control.NumericEditField
        ParameterNumericEditFieldLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        Close                           matlab.ui.control.Button
        Calculate                       matlab.ui.control.Button
        RadioButtonGroup                matlab.ui.container.ButtonGroup
        DummylabelLabel                 matlab.ui.control.Label
        Radio3                          matlab.ui.control.RadioButton
        Radio2                          matlab.ui.control.RadioButton
        Radio1                          matlab.ui.control.RadioButton
        Dropdown                        matlab.ui.control.DropDown
        DropdowncellLabel               matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Checkbox                        matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        Parameter                       matlab.ui.control.EditField
        ParametereditEditFieldLabel     matlab.ui.control.Label
        Help                            matlab.ui.control.StateButton
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: Calculate
        function Calculate_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Calculate();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            helpFilPath = fullfile(fileparts(app.winController.mibModel.mibPath), 'docs', 'html', 'user-interface', 'plugins', 'tutorials', 'mib-app-design-plugin.html');
            if isfile(helpFilPath)
                web(helpFilPath, '-browser');
            else
                web('http://mib.helsinki.fi/help/main3/user-interface/plugins/tutorials/mib-plugin.html', '-browser');
            end            
        end

        % Callback function: Checkbox, Dropdown, Parameter, 
        % ...and 2 other components
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Child GUI Name';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Help = uibutton(app.Figure, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.ParametereditEditFieldLabel = uilabel(app.Figure);
            app.ParametereditEditFieldLabel.Text = 'Parameter (edit):';
            app.Parameter = uieditfield(app.Figure, 'text');
            app.Parameter.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Parameter.Value = 'test';
            app.text1 = uilabel(app.Figure);
            app.text1.Text = 'Label: tag of this text label will be used to check need for resizing of the widgets';
            app.Label_2 = uilabel(app.Figure);
            app.Label_2.Text = 'List of widget examples, generate their tags from their actions, they all refer to the same callback ''updateBatchOpt''';
            app.Checkbox = uicheckbox(app.Figure);
            app.Checkbox.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Label_3 = uilabel(app.Figure);
            app.Label_3.Text = 'Checkbox (logical):';
            app.DropdowncellLabel = uilabel(app.Figure);
            app.DropdowncellLabel.Text = 'Drop down (cell):';
            app.Dropdown = uidropdown(app.Figure);
            app.Dropdown.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Dropdown.Value = 'Option 4';
            app.RadioButtonGroup = uibuttongroup(app.Figure);
            app.RadioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.RadioButtonGroup.Title = 'Radio';
            app.Radio1 = uiradiobutton(app.RadioButtonGroup);
            app.Radio1.Text = 'Radio1 text';
            app.Radio1.Value = true;
            app.Radio2 = uiradiobutton(app.RadioButtonGroup);
            app.Radio2.Text = 'Radio2 text';
            app.Radio3 = uiradiobutton(app.RadioButtonGroup);
            app.Radio3.Text = 'Radio3 text';
            app.DummylabelLabel = uilabel(app.RadioButtonGroup);
            app.DummylabelLabel.Text = 'Dummy label';
            app.Calculate = uibutton(app.Figure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @Calculate_Callback, true);
            app.Calculate.Text = 'Calculate';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.ResultTextAreaLabel = uilabel(app.Figure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.TextArea = uitextarea(app.Figure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Press Calculate to see updated parameters from GUI'; ''};
            app.ParameterNumericEditFieldLabel = uilabel(app.Figure);
            app.ParameterNumericEditFieldLabel.Text = 'ParameterNumeric';
            app.ParameterNumeric = uieditfield(app.Figure, 'numeric');
            app.ParameterNumeric.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ParameterNumeric.Value = 514.1235;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DemoPluginGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end