classdef GuiTutorialGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                     matlab.ui.Figure
        mainGridLayout             matlab.ui.container.GridLayout
        PluginsettingsPanel        matlab.ui.container.Panel
        settingsGridLayout         matlab.ui.container.GridLayout
        colorDropdown              matlab.ui.control.DropDown
        ColorchannelDropDownLabel  matlab.ui.control.Label
        convertDropdown            matlab.ui.control.DropDown
        ConverttoDropDownLabel     matlab.ui.control.Label
        heightEdit                 matlab.ui.control.Spinner
        HeightSpinnerLabel         matlab.ui.control.Label
        widthEdit                  matlab.ui.control.Spinner
        WidthSpinnerLabel          matlab.ui.control.Label
        yMinEdit                   matlab.ui.control.Spinner
        YminSpinnerLabel           matlab.ui.control.Label
        xMinEdit                   matlab.ui.control.Spinner
        XminSpinnerLabel           matlab.ui.control.Label
        infoText2                  matlab.ui.control.Label
        infoText1                  matlab.ui.control.Label
        buttonGroup                matlab.ui.container.ButtonGroup
        invertRadio                matlab.ui.control.RadioButton
        convertRadio               matlab.ui.control.RadioButton
        resizeRadio                matlab.ui.control.RadioButton
        cropRadio                  matlab.ui.control.RadioButton
        closeBtn                   matlab.ui.control.Button
        continueBtn                matlab.ui.control.Button
        helpBtn                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        winController  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
        end

        % Selection changed function: buttonGroup
        function buttonGroupSelectionChanged(app, event)
            % selectedButton = app.buttonGroup.SelectedObject;
            app.winController.buttonGroup_Callback();
        end

        % Button pushed function: closeBtn
        function closeBtnButtonPushed(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: continueBtn
        function continueBtnButtonPushed(app, event)
            app.winController.continueBtn_Callback();
        end

        % Button pushed function: helpBtn
        function helpBtnButtonPushed(app, event)
            app.winController.helpBtn_Callback();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'GUI tutorial';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.helpBtn = uibutton(app.mainGridLayout, 'push');
            app.helpBtn.ButtonPushedFcn = createCallbackFcn(app, @helpBtnButtonPushed, true);
            app.helpBtn.Text = 'Help';
            app.continueBtn = uibutton(app.mainGridLayout, 'push');
            app.continueBtn.ButtonPushedFcn = createCallbackFcn(app, @continueBtnButtonPushed, true);
            app.continueBtn.Text = 'Continue';
            app.closeBtn = uibutton(app.mainGridLayout, 'push');
            app.closeBtn.ButtonPushedFcn = createCallbackFcn(app, @closeBtnButtonPushed, true);
            app.closeBtn.Text = 'Close';
            app.PluginsettingsPanel = uipanel(app.mainGridLayout);
            app.PluginsettingsPanel.Title = 'Plugin settings';
            app.settingsGridLayout = uigridlayout(app.PluginsettingsPanel);
            app.buttonGroup = uibuttongroup(app.settingsGridLayout);
            app.buttonGroup.SelectionChangedFcn = createCallbackFcn(app, @buttonGroupSelectionChanged, true);
            app.buttonGroup.Title = 'Mode';
            app.cropRadio = uiradiobutton(app.buttonGroup);
            app.cropRadio.Text = 'Crop';
            app.cropRadio.Value = true;
            app.resizeRadio = uiradiobutton(app.buttonGroup);
            app.resizeRadio.Text = 'Resize';
            app.convertRadio = uiradiobutton(app.buttonGroup);
            app.convertRadio.Text = 'Convert';
            app.invertRadio = uiradiobutton(app.buttonGroup);
            app.invertRadio.Text = 'Invert';
            app.infoText1 = uilabel(app.settingsGridLayout);
            app.infoText1.Text = 'Dataset dimensions (yxzct):';
            app.infoText2 = uilabel(app.settingsGridLayout);
            app.infoText2.Text = 'height x width x depth x colors x time';
            app.XminSpinnerLabel = uilabel(app.settingsGridLayout);
            app.XminSpinnerLabel.Text = 'X min';
            app.xMinEdit = uispinner(app.settingsGridLayout);
            app.xMinEdit.Value = 1;
            app.YminSpinnerLabel = uilabel(app.settingsGridLayout);
            app.YminSpinnerLabel.Text = 'Y min';
            app.yMinEdit = uispinner(app.settingsGridLayout);
            app.WidthSpinnerLabel = uilabel(app.settingsGridLayout);
            app.WidthSpinnerLabel.Text = 'Width';
            app.widthEdit = uispinner(app.settingsGridLayout);
            app.HeightSpinnerLabel = uilabel(app.settingsGridLayout);
            app.HeightSpinnerLabel.Text = 'Height';
            app.heightEdit = uispinner(app.settingsGridLayout);
            app.ConverttoDropDownLabel = uilabel(app.settingsGridLayout);
            app.ConverttoDropDownLabel.Text = 'Convert to';
            app.convertDropdown = uidropdown(app.settingsGridLayout);
            app.ColorchannelDropDownLabel = uilabel(app.settingsGridLayout);
            app.ColorchannelDropDownLabel.Text = 'Color channel';
            app.colorDropdown = uidropdown(app.settingsGridLayout);
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTutorialGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end